classdef ConfigDhPara < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DHParametersUIFigure       matlab.ui.Figure
        a1EditFieldLabel           matlab.ui.control.Label
        a1EditField                matlab.ui.control.NumericEditField
        a2EditFieldLabel           matlab.ui.control.Label
        a2EditField                matlab.ui.control.NumericEditField
        a3EditFieldLabel           matlab.ui.control.Label
        a3EditField                matlab.ui.control.NumericEditField
        alpha1EditFieldLabel       matlab.ui.control.Label
        alpha1EditField            matlab.ui.control.NumericEditField
        alpha2EditFieldLabel       matlab.ui.control.Label
        alpha2EditField            matlab.ui.control.NumericEditField
        alpha3EditFieldLabel       matlab.ui.control.Label
        alpha3EditField            matlab.ui.control.NumericEditField
        d1EditFieldLabel           matlab.ui.control.Label
        d1EditField                matlab.ui.control.NumericEditField
        d2EditFieldLabel           matlab.ui.control.Label
        d2EditField                matlab.ui.control.NumericEditField
        d3EditFieldLabel           matlab.ui.control.Label
        d3EditField                matlab.ui.control.NumericEditField
        offset3EditFieldLabel      matlab.ui.control.Label
        offset3EditField           matlab.ui.control.NumericEditField
        offset2EditFieldLabel      matlab.ui.control.Label
        offset2EditField           matlab.ui.control.NumericEditField
        offset1EditFieldLabel      matlab.ui.control.Label
        offset1EditField           matlab.ui.control.NumericEditField
        xEditFieldLabel            matlab.ui.control.Label
        xEditField                 matlab.ui.control.NumericEditField
        yEditFieldLabel            matlab.ui.control.Label
        yEditField                 matlab.ui.control.NumericEditField
        zEditFieldLabel            matlab.ui.control.Label
        zEditField                 matlab.ui.control.NumericEditField
        BaseFrameTranslationLabel  matlab.ui.control.Label
        OKButton                   matlab.ui.control.Button
        CancelButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        mainApp % main window
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, DhPara)
            % Store main app in property for CloseRequestFcn to use
            app.mainApp = mainApp;
            
            app.a1EditField.Value = DhPara(1,1);
            app.a2EditField.Value = DhPara(1,2);
            app.a3EditField.Value = DhPara(1,3);
            app.alpha1EditField.Value = DhPara(2,1);
            app.alpha2EditField.Value = DhPara(2,2);
            app.alpha3EditField.Value = DhPara(2,3);
            app.d1EditField.Value = DhPara(3,1);
            app.d2EditField.Value = DhPara(3,2);
            app.d3EditField.Value = DhPara(3,3);
            app.offset1EditField.Value = DhPara(4,1);
            app.offset2EditField.Value = DhPara(4,2);
            app.offset3EditField.Value = DhPara(4,3);
            app.xEditField.Value = DhPara(5,1);
            app.yEditField.Value = DhPara(5,2);
            app.zEditField.Value = DhPara(5,3);
            
        end

        % Button pushed function: OKButton
        function Confirm(app, event)
            DhPara(1,1) = app.a1EditField.Value;
            DhPara(1,2) = app.a2EditField.Value;
            DhPara(1,3) = app.a3EditField.Value;
            DhPara(2,1) = app.alpha1EditField.Value;
            DhPara(2,2) = app.alpha2EditField.Value;
            DhPara(2,3) = app.alpha3EditField.Value;
            DhPara(3,1) = app.d1EditField.Value;
            DhPara(3,2) = app.d2EditField.Value;
            DhPara(3,3) = app.d3EditField.Value;
            DhPara(4,1) = app.offset1EditField.Value;
            DhPara(4,2) = app.offset2EditField.Value;
            DhPara(4,3) = app.offset3EditField.Value;
            DhPara(5,1) = app.xEditField.Value;
            DhPara(5,2) = app.yEditField.Value;
            DhPara(5,3) = app.zEditField.Value;
            
            % Call main app's public function
            updateDhParaCfg(app.mainApp, DhPara);
            
            % Delete the dialog box
            delete(app)
        end

        % Button pushed function: CancelButton
        function Cancel(app, event)
            % Enable the Plot Opions button in main app
            app.mainApp.DHParaCfgMenu.Enable = 'on';
            
            % Delete the dialog box 
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DHParametersUIFigure = uifigure('Visible', 'off');
            app.DHParametersUIFigure.Name = 'DH Parameters';
            app.a1EditFieldLabel = uilabel(app.DHParametersUIFigure);
            app.a1EditFieldLabel.Text = 'a1';
            app.a1EditField = uieditfield(app.DHParametersUIFigure, 'numeric');
            app.a2EditFieldLabel = uilabel(app.DHParametersUIFigure);
            app.a2EditFieldLabel.Text = 'a2';
            app.a2EditField = uieditfield(app.DHParametersUIFigure, 'numeric');
            app.a3EditFieldLabel = uilabel(app.DHParametersUIFigure);
            app.a3EditFieldLabel.Text = 'a3';
            app.a3EditField = uieditfield(app.DHParametersUIFigure, 'numeric');
            app.alpha1EditFieldLabel = uilabel(app.DHParametersUIFigure);
            app.alpha1EditFieldLabel.Text = 'alpha1';
            app.alpha1EditField = uieditfield(app.DHParametersUIFigure, 'numeric');
            app.alpha2EditFieldLabel = uilabel(app.DHParametersUIFigure);
            app.alpha2EditFieldLabel.Text = 'alpha2';
            app.alpha2EditField = uieditfield(app.DHParametersUIFigure, 'numeric');
            app.alpha3EditFieldLabel = uilabel(app.DHParametersUIFigure);
            app.alpha3EditFieldLabel.Text = 'alpha3';
            app.alpha3EditField = uieditfield(app.DHParametersUIFigure, 'numeric');
            app.d1EditFieldLabel = uilabel(app.DHParametersUIFigure);
            app.d1EditFieldLabel.Text = 'd1';
            app.d1EditField = uieditfield(app.DHParametersUIFigure, 'numeric');
            app.d2EditFieldLabel = uilabel(app.DHParametersUIFigure);
            app.d2EditFieldLabel.Text = 'd2';
            app.d2EditField = uieditfield(app.DHParametersUIFigure, 'numeric');
            app.d3EditFieldLabel = uilabel(app.DHParametersUIFigure);
            app.d3EditFieldLabel.Text = 'd3';
            app.d3EditField = uieditfield(app.DHParametersUIFigure, 'numeric');
            app.offset3EditFieldLabel = uilabel(app.DHParametersUIFigure);
            app.offset3EditFieldLabel.Text = 'offset3';
            app.offset3EditField = uieditfield(app.DHParametersUIFigure, 'numeric');
            app.offset2EditFieldLabel = uilabel(app.DHParametersUIFigure);
            app.offset2EditFieldLabel.Text = 'offset2';
            app.offset2EditField = uieditfield(app.DHParametersUIFigure, 'numeric');
            app.offset1EditFieldLabel = uilabel(app.DHParametersUIFigure);
            app.offset1EditFieldLabel.Text = 'offset1';
            app.offset1EditField = uieditfield(app.DHParametersUIFigure, 'numeric');
            app.xEditFieldLabel = uilabel(app.DHParametersUIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.DHParametersUIFigure, 'numeric');
            app.yEditFieldLabel = uilabel(app.DHParametersUIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.DHParametersUIFigure, 'numeric');
            app.zEditFieldLabel = uilabel(app.DHParametersUIFigure);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.DHParametersUIFigure, 'numeric');
            app.BaseFrameTranslationLabel = uilabel(app.DHParametersUIFigure);
            app.BaseFrameTranslationLabel.Text = 'Base Frame Translation';
            app.OKButton = uibutton(app.DHParametersUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @Confirm, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.DHParametersUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @Cancel, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.DHParametersUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ConfigDhPara(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DHParametersUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DHParametersUIFigure)
        end
    end
end