classdef ConfigHW < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ConfigUIFigure                 matlab.ui.Figure
        ArduinoUSBPortDropDownLabel    matlab.ui.control.Label
        ArduinoUSBPortDropDown         matlab.ui.control.DropDown
        OKButton                       matlab.ui.control.Button
        CancelButton                   matlab.ui.control.Button
        Joint1ServoControlPinDropDownLabel  matlab.ui.control.Label
        Joint1ServoControlPinDropDown  matlab.ui.control.DropDown
        Joint2ServoControlPinDropDownLabel  matlab.ui.control.Label
        Joint2ServoControlPinDropDown  matlab.ui.control.DropDown
        Joint3ServoControlPinDropDownLabel  matlab.ui.control.Label
        Joint3ServoControlPinDropDown  matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        portList = []       % Available port list
        portSelection = ""  % Which port selected
        mainApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, ComPort, ServoPin)
            % Store main app in property for CloseRequestFcn to use
            app.mainApp = mainApp;
            
            app.portList = serialportlist("all");
            app.ArduinoUSBPortDropDown.Items= app.portList;
            app.ArduinoUSBPortDropDown.Value = ComPort;
            app.Joint1ServoControlPinDropDown.Value = ServoPin(1);
            app.Joint2ServoControlPinDropDown.Value = ServoPin(2);
            app.Joint3ServoControlPinDropDown.Value = ServoPin(3);
        end

        % Button pushed function: OKButton
        function Confirm(app, event)
            com_port = app.ArduinoUSBPortDropDown.Value;
            servo_pin ={app.Joint1ServoControlPinDropDown.Value, ...
                        app.Joint2ServoControlPinDropDown.Value, ...
                        app.Joint3ServoControlPinDropDown.Value};
            % Call main app's public function
            updateHWConfig(app.mainApp, com_port,servo_pin);
            
            % Delete the dialog box
            delete(app)
        end

        % Button pushed function: CancelButton
        function Cancel(app, event)
            % Enable the Plot Opions button in main app
            app.mainApp.ArduinoMenu.Enable = 'on';
            
            % Delete the dialog box 
            delete(app)
        end

        % Close request function: ConfigUIFigure
        function ConfigUIFigureCloseRequest(app, event)
            % Enable the Plot Opions button in main app
            app.mainApp.ArduinoMenu.Enable = 'on';
            
            % Delete the dialog box 
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ConfigUIFigure = uifigure('Visible', 'off');
            app.ConfigUIFigure.Name = 'Config';
            app.ConfigUIFigure.CloseRequestFcn = createCallbackFcn(app, @ConfigUIFigureCloseRequest, true);
            app.ArduinoUSBPortDropDownLabel = uilabel(app.ConfigUIFigure);
            app.ArduinoUSBPortDropDownLabel.Text = 'Arduino USB Port';
            app.ArduinoUSBPortDropDown = uidropdown(app.ConfigUIFigure);
            app.OKButton = uibutton(app.ConfigUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @Confirm, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.ConfigUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @Cancel, true);
            app.CancelButton.Text = 'Cancel';
            app.Joint1ServoControlPinDropDownLabel = uilabel(app.ConfigUIFigure);
            app.Joint1ServoControlPinDropDownLabel.Text = 'Joint1 Servo Control Pin';
            app.Joint1ServoControlPinDropDown = uidropdown(app.ConfigUIFigure);
            app.Joint1ServoControlPinDropDown.Items = {'D3', 'D5', 'D6', 'D9', 'D10', 'D11', ''};
            app.Joint1ServoControlPinDropDown.Value = 'D3';
            app.Joint2ServoControlPinDropDownLabel = uilabel(app.ConfigUIFigure);
            app.Joint2ServoControlPinDropDownLabel.Text = 'Joint2 Servo Control Pin';
            app.Joint2ServoControlPinDropDown = uidropdown(app.ConfigUIFigure);
            app.Joint2ServoControlPinDropDown.Items = {'D3', 'D5', 'D6', 'D9', 'D10', 'D11'};
            app.Joint2ServoControlPinDropDown.Value = 'D5';
            app.Joint3ServoControlPinDropDownLabel = uilabel(app.ConfigUIFigure);
            app.Joint3ServoControlPinDropDownLabel.Text = 'Joint3 Servo Control Pin';
            app.Joint3ServoControlPinDropDown = uidropdown(app.ConfigUIFigure);
            app.Joint3ServoControlPinDropDown.Items = {'D3', 'D5', 'D6', 'D9', 'D10', 'D11'};
            app.Joint3ServoControlPinDropDown.Value = 'D6';
            % Show the figure after all components are created
            app.ConfigUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ConfigHW(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ConfigUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ConfigUIFigure)
        end
    end
end