classdef G8RobotArm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        FileMenu                   matlab.ui.container.Menu
        ReadDataMenu               matlab.ui.container.Menu
        DifferentiateMenu          matlab.ui.container.Menu
        CreateRobotMenu            matlab.ui.container.Menu
        HardwareMenu               matlab.ui.container.Menu
        InitArduinoMenu            matlab.ui.container.Menu
        TestArduinoMenu            matlab.ui.container.Menu
        DHparametersPanel          matlab.ui.container.Panel
        a1EditFieldLabel           matlab.ui.control.Label
        a1EditField                matlab.ui.control.NumericEditField
        a2EditFieldLabel           matlab.ui.control.Label
        a2EditField                matlab.ui.control.NumericEditField
        a3EditFieldLabel           matlab.ui.control.Label
        a3EditField                matlab.ui.control.NumericEditField
        alpha1EditFieldLabel       matlab.ui.control.Label
        alpha1EditField            matlab.ui.control.NumericEditField
        alpha2EditFieldLabel       matlab.ui.control.Label
        alpha2EditField            matlab.ui.control.NumericEditField
        alpha3EditFieldLabel       matlab.ui.control.Label
        alpha3EditField            matlab.ui.control.NumericEditField
        d1EditFieldLabel           matlab.ui.control.Label
        d1EditField                matlab.ui.control.NumericEditField
        d2EditFieldLabel           matlab.ui.control.Label
        d2EditField                matlab.ui.control.NumericEditField
        d3EditFieldLabel           matlab.ui.control.Label
        d3EditField                matlab.ui.control.NumericEditField
        BaseFrameTranslationLabel  matlab.ui.control.Label
        xEditFieldLabel            matlab.ui.control.Label
        xBaseTransl                matlab.ui.control.NumericEditField
        yEditFieldLabel            matlab.ui.control.Label
        yBaseTransl                matlab.ui.control.NumericEditField
        zEditFieldLabel            matlab.ui.control.Label
        zBaseTransl                matlab.ui.control.NumericEditField
        offset1EditFieldLabel      matlab.ui.control.Label
        offset1EditField           matlab.ui.control.NumericEditField
        offset2EditFieldLabel      matlab.ui.control.Label
        offset2EditField           matlab.ui.control.NumericEditField
        offset3EditFieldLabel      matlab.ui.control.Label
        offset3EditField           matlab.ui.control.NumericEditField
        EndEffectorLocationPanel   matlab.ui.container.Panel
        XEditFieldLabel            matlab.ui.control.Label
        XEditField                 matlab.ui.control.NumericEditField
        YEditFieldLabel            matlab.ui.control.Label
        YEditField                 matlab.ui.control.NumericEditField
        ZEditFieldLabel            matlab.ui.control.Label
        ZEditField                 matlab.ui.control.NumericEditField
        DistanceEditFieldLabel     matlab.ui.control.Label
        DistanceEditField          matlab.ui.control.NumericEditField
        PoseDropDownLabel          matlab.ui.control.Label
        PoseDropDown               matlab.ui.control.DropDown
        Joint1SliderLabel          matlab.ui.control.Label
        Joint1Slider               matlab.ui.control.Slider
        Joint2SliderLabel          matlab.ui.control.Label
        Joint2Slider               matlab.ui.control.Slider
        Joint3SliderLabel          matlab.ui.control.Label
        Joint3Slider               matlab.ui.control.Slider
        OptimizationPanel          matlab.ui.container.Panel
        OptimizeButton             matlab.ui.control.Button
        errorEditFieldLabel        matlab.ui.control.Label
        errorEditField             matlab.ui.control.NumericEditField
        A1EditFieldLabel           matlab.ui.control.Label
        A1EditField                matlab.ui.control.NumericEditField
        A2EditFieldLabel           matlab.ui.control.Label
        A2EditField                matlab.ui.control.NumericEditField
        D1EditFieldLabel           matlab.ui.control.Label
        D1EditField                matlab.ui.control.NumericEditField
        D2EditFieldLabel           matlab.ui.control.Label
        D2EditField                matlab.ui.control.NumericEditField
        D3EditFieldLabel           matlab.ui.control.Label
        D3EditField                matlab.ui.control.NumericEditField
        OFFSET1EditFieldLabel      matlab.ui.control.Label
        OFFSET1EditField           matlab.ui.control.NumericEditField
        OFFSET2EditFieldLabel      matlab.ui.control.Label
        OFFSET2EditField           matlab.ui.control.NumericEditField
        baseYEditFieldLabel        matlab.ui.control.Label
        baseYEditField             matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        DataRead = false; % Initialize as false
        Data = []; %buffer for reading data
        DiffData = [];
        arduino_hardware = [];
        arduino_initialized = true;
        G8Robot = [];
        IsRobotCreated = false;

        % some useful poses
        Pose = [0 0 0;          %Pose1
                0 90 0;       %Pose2
                0 -90 0;      %Pose3
                90 0 0;       %Pose4
                90 90 0;    %Pose5
                90 -90 0;   %Pose6
                -90 0 0;      %Pose7
                -90 90 0;    %Pose8
                -90 -90 0];  %Pose9
    end
    
    methods (Access = private)
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            poseInx = str2num(app.PoseDropDown.Value);
            app.Joint1Slider.Value = app.Pose(poseInx,1);
            app.Joint2Slider.Value = app.Pose(poseInx,2);
            app.Joint3Slider.Value = app.Pose(poseInx,3);
            
        end

        % Menu selected function: InitArduinoMenu
        function Initialize(app, event)
            try
                app.arduino_hardware = arduino();
            catch
                msgbox('error on hardware initialize');
                return;
            end
            app.arduino_initialized = true;
            msgbox('Arduino initialized');
        end

        % Menu selected function: TestArduinoMenu
        function TestArduino(app, event)
            if(app.arduino_initialized)
                app.arduino_hardware.writeDigitalPin("D13",1);
            end
        end

        % Menu selected function: ReadDataMenu
        function ReadData(app, event)
            [fn pn] = uigetfile('*.txt', 'Where is the data?');
            filename = [pn fn];
            app.Data = textread(filename);
            plot(app.UIAxes, app.Data(:,1),app.Data(:,2));
            app.DataRead = true;
            app.DataReadLamp.Color = [0.0 1.0 0.0];
            app.InfoTextArea.Value = 'Data has been read.';
        end

        % Menu selected function: DifferentiateMenu
        function Differentiate(app, event)
            if(app.DataRead)
                app.DiffData = app.Data;
                app.DiffData(1:999,2) = diff(app.Data(:,2));
                hold(app.UIAxes,'on');
                plot(app.UIAxes, app.DiffData(:,1),app.DiffData(:,2),'g');
                app.InfoTextArea.Value = 'Green is the differential';
            else
                app.InfoTextArea.Value = 'Read the data first';
            end
        end

        % Menu selected function: CreateRobotMenu
        function CreateRobot(app, event)
            clear L
            deg = pi/180;
            
            % all parameters are in SI units: m, radians, kg, kg.m2, N.m, N.m.s etc.
            L(1) = Revolute('d', app.d1EditField.Value, ...   % link length (Dennavit-Hartenberg notation)
                'a', app.a1EditField.Value, ...               % link offset (Dennavit-Hartenberg notation)
                'alpha', app.alpha1EditField.Value*deg, ...        % link twist (Dennavit-Hartenberg notation)
                'qlim', [-120 90]*deg, ...
                'offset',-19*deg ); % minimum and maximum joint angle
            
            L(2) = Revolute('d', app.d2EditField.Value, 'a', app.a2EditField.Value, 'alpha', app.alpha2EditField.Value*deg, ...
                'qlim', [-120 90]*deg, 'offset',16.8*deg );
            
            L(3) = Revolute('d', app.d3EditField.Value, 'a', app.a3EditField.Value, 'alpha', app.alpha3EditField.Value*deg,  ...
                'qlim', [-0 135]*deg, 'offset',0*deg  );

            
            app.G8Robot = SerialLink(L, 'name', 'G8 Robot Arm', ...
                'tool',transl(0,0,0), ...
                'base',transl(app.xBaseTransl.Value,app.yBaseTransl.Value,app.zBaseTransl.Value));

            app.G8Robot.plot([app.Joint1Slider.Value*pi/180 ...
                app.Joint2Slider.Value*pi/180 ...
                app.Joint3Slider.Value*pi/180]);
            app.IsRobotCreated = true;
            
            SE3 = app.G8Robot.fkine([app.Joint1Slider.Value*pi/180 ...
                app.Joint2Slider.Value*pi/180 ...
                app.Joint3Slider.Value*pi/180]);
            
            app.XEditField.Value = SE3.t(1,1);
            app.YEditField.Value = SE3.t(2,1);
            app.ZEditField.Value = SE3.t(3,1);
            
            %Calculate the distance of EE
            X = app.XEditField.Value;
            Y = app.YEditField.Value;
            Z = app.ZEditField.Value;
            app.DistanceEditField.Value = sqrt(X*X + Y*Y + Z*Z);

        end

        % Callback function
        function MoveJoint1(app, event)
            %Re-plot robot after joint1 angle changed
            app.G8Robot.plot([app.Joint1Spinner.Value*pi/180 ...
                app.Joint2Slider.Value*pi/180 ...
                app.Joint3Slider.Value*pi/180]);
            %Recalculate the Transformation matrix after joint angle
            %changed
            SE3 = app.G8Robot.fkine([app.Joint1Spinner.Value*pi/180 ...
                app.Joint2Slider.Value*pi/180 ...
                app.Joint3Slider.Value*pi/180]);
            %Update the EE position
            app.XEditField.Value = SE3.t(1,1);
            app.YEditField.Value = SE3.t(2,1);
            app.ZEditField.Value = SE3.t(3,1);
            
            %Calculate the distance of EE
            X = app.XEditField.Value;
            Y = app.YEditField.Value;
            Z = app.ZEditField.Value;
            app.DistanceEditField.Value = sqrt(X*X + Y*Y + Z*Z);
            
        end

        % Callback function
        function MoveJoint2(app, event)
            app.G8Robot.plot([app.Joint1Spinner.Value*pi/180 ...
                app.Joint2Slider.Value*pi/180 ...
                app.Joint3Slider.Value*pi/180]);
            %Recalculate the Transformation matrix after joint angle
            %changed
            SE3 = app.G8Robot.fkine([app.Joint1Spinner.Value*pi/180 ...
                app.Joint2Slider.Value*pi/180 ...
                app.Joint3Slider.Value*pi/180]);
            %Update the EE position
            app.XEditField.Value = SE3.t(1,1);
            app.YEditField.Value = SE3.t(2,1);
            app.ZEditField.Value = SE3.t(3,1);
            
            %Calculate the distance of EE
            X = app.XEditField.Value;
            Y = app.YEditField.Value;
            Z = app.ZEditField.Value;
            app.DistanceEditField.Value = sqrt(X*X + Y*Y + Z*Z);
        end

        % Callback function
        function MoveJoint3(app, event)
            app.G8Robot.plot([app.Joint1Spinner.Value*pi/180 ...
                app.Joint2Slider.Value*pi/180 ...
                app.Joint3Slider.Value*pi/180]);
            %Recalculate the Transformation matrix after joint angle
            %changed
            SE3 = app.G8Robot.fkine([app.Joint1Spinner.Value*pi/180 ...
                app.Joint2Slider.Value*pi/180 ...
                app.Joint3Slider.Value*pi/180]);
            %Update the EE position
            app.XEditField.Value = SE3.t(1,1);
            app.YEditField.Value = SE3.t(2,1);
            app.ZEditField.Value = SE3.t(3,1);
            
            %Calculate the distance of EE
            X = app.XEditField.Value;
            Y = app.YEditField.Value;
            Z = app.ZEditField.Value;
            app.DistanceEditField.Value = sqrt(X*X + Y*Y + Z*Z);
        end

        % Value changed function: a1EditField
        function Update_a1(app, event)
            value = app.a1EditField.Value;
            if app.IsRobotCreated == true
                clear L
                deg = pi/180;
                
                % all parameters are in SI units: m, radians, kg, kg.m2, N.m, N.m.s etc.
                L(1) = Revolute('d', app.d1EditField.Value, ...   % link length (Dennavit-Hartenberg notation)
                    'a', app.a1EditField.Value, ...               % link offset (Dennavit-Hartenberg notation)
                    'alpha', app.alpha1EditField.Value*deg, ...        % link twist (Dennavit-Hartenberg notation)
                    'qlim', [-120 90]*deg ); % minimum and maximum joint angle
                
                L(2) = Revolute('d', app.d2EditField.Value, 'a', app.a2EditField.Value, 'alpha', app.alpha2EditField.Value*deg, ...
                    'qlim', [-120 90]*deg );
                
                L(3) = Revolute('d', app.d3EditField.Value, 'a', app.a3EditField.Value, 'alpha', app.alpha3EditField.Value*deg,  ...
                    'qlim', [-0 135]*deg );
    
                
                app.G8Robot = SerialLink(L, 'name', 'G8 Robot Arm', ...
                    'configs', {'qz', app.qz, 'qr', app.qr, 'qs', app.qs, 'qn', app.qn}, ...
                    'tool',transl(0,0,0), ...
                    'base',transl(0,0,0));
    
                app.G8Robot.plot([app.Joint1Spinner.Value ...
                    app.Joint2Slider.Value ...
                    app.Joint3Slider.Value]);

            end
        end

        % Key release function: UIFigure
        function UIFigureKeyRelease(app, event)
            key = event.Key;
            
            SE3 = app.G8Robot.fkine([app.Joint1Slider.Value*pi/180 ...
                app.Joint2Slider.Value*pi/180 ...
                app.Joint3Slider.Value*pi/180]);
            
            if key == "leftarrow"
                %SE3.increment([-0.05; 0; 0; 0; 0; 0]);
                SE3.t = SE3.t + [0; -0.001; 0];
            elseif key == "rightarrow"
                %SE3.increment([0.05; 0; 0; 0; 0; 0]);
                SE3.t = SE3.t + [0; 0.001; 0];
            elseif key == "uparrow"
                %SE3.increment([0; 0.05; 0; 0; 0; 0]);
                SE3.t = SE3.t + [0.001; 0; 0];
            elseif key == "downarrow"
                %SE3.increment([0; -0.05; 0; 0; 0; 0]);
                SE3.t = SE3.t + [-0.001; 0; 0];
            end
            
            j = app.G8Robot.ikine(SE3,'mask',[1 1 1 0 0 0]);
            app.Joint1Slider.Value = j(1,1) * 180/pi;
            app.Joint2Slider.Value = j(1,2) * 180/pi;
            app.Joint3Slider.Value = j(1,3) * 180/pi;
            
            app.G8Robot.plot([app.Joint1Slider.Value ...
                    app.Joint2Slider.Value ...
                    app.Joint3Slider.Value])
            %Update the EE position
            app.XEditField.Value = SE3.t(1,1);
            app.YEditField.Value = SE3.t(2,1);
            app.ZEditField.Value = SE3.t(3,1);
            %Calculate the distance of EE
            X = app.XEditField.Value;
            Y = app.YEditField.Value;
            Z = app.ZEditField.Value;
            app.DistanceEditField.Value = sqrt(X*X + Y*Y + Z*Z);
        end

        % Value changed function: PoseDropDown
        function ChangePose(app, event)
            poseinx = str2num(app.PoseDropDown.Value);
            app.Joint1Slider.Value = app.Pose(poseinx,1);
            app.Joint2Slider.Value = app.Pose(poseinx,2);
            app.Joint3Slider.Value = app.Pose(poseinx,3);
            
            app.G8Robot.plot([app.Joint1Slider.Value*pi/180 ...
                app.Joint2Slider.Value*pi/180 ...
                app.Joint3Slider.Value*pi/180]);
            %Recalculate the Transformation matrix after joint angle
            %changed
            SE3 = app.G8Robot.fkine([app.Joint1Slider.Value*pi/180 ...
                app.Joint2Slider.Value*pi/180 ...
                app.Joint3Slider.Value*pi/180]);
            %Update the EE position
            app.XEditField.Value = SE3.t(1,1);
            app.YEditField.Value = SE3.t(2,1);
            app.ZEditField.Value = SE3.t(3,1);
            %Calculate the distance of EE
            X = app.XEditField.Value;
            Y = app.YEditField.Value;
            Z = app.ZEditField.Value;
            app.DistanceEditField.Value = sqrt(X*X + Y*Y + Z*Z);
        end

        % Value changed function: XEditField
        function xPosChange(app, event)
            %Calculate the distance of EE
            X = app.XEditField.Value;
            Y = app.YEditField.Value;
            Z = app.ZEditField.Value;
            app.DistanceEditField.Value = sqrt(X*X + Y*Y + Z*Z);
        end

        % Value changed function: YEditField
        function yPosChange(app, event)
            %Calculate the distance of EE
            X = app.XEditField.Value;
            Y = app.YEditField.Value;
            Z = app.ZEditField.Value;
            app.DistanceEditField.Value = sqrt(X*X + Y*Y + Z*Z);
        end

        % Value changed function: ZEditField
        function zPosChange(app, event)
            %Calculate the distance of EE
            X = app.XEditField.Value;
            Y = app.YEditField.Value;
            Z = app.ZEditField.Value;
            app.DistanceEditField.Value = sqrt(X*X + Y*Y + Z*Z);
            
        end

        % Value changed function: Joint1Slider
        function Joint1SliderValueChanged(app, event)
            %Re-plot robot after joint1 angle changed
            app.G8Robot.plot([app.Joint1Slider.Value*pi/180 ...
                app.Joint2Slider.Value*pi/180 ...
                app.Joint3Slider.Value*pi/180]);
            %Recalculate the Transformation matrix after joint angle
            %changed
            SE3 = app.G8Robot.fkine([app.Joint1Slider.Value*pi/180 ...
                app.Joint2Slider.Value*pi/180 ...
                app.Joint3Slider.Value*pi/180]);
            %Update the EE position
            app.XEditField.Value = SE3.t(1,1);
            app.YEditField.Value = SE3.t(2,1);
            app.ZEditField.Value = SE3.t(3,1);
            
            %Calculate the distance of EE
            X = app.XEditField.Value;
            Y = app.YEditField.Value;
            Z = app.ZEditField.Value;
            app.DistanceEditField.Value = sqrt(X*X + Y*Y + Z*Z);
        end

        % Value changed function: Joint2Slider
        function Joint2SliderValueChanged(app, event)
            %Re-plot robot after joint1 angle changed
            app.G8Robot.plot([app.Joint1Slider.Value*pi/180 ...
                app.Joint2Slider.Value*pi/180 ...
                app.Joint3Slider.Value*pi/180]);
            %Recalculate the Transformation matrix after joint angle
            %changed
            SE3 = app.G8Robot.fkine([app.Joint1Slider.Value*pi/180 ...
                app.Joint2Slider.Value*pi/180 ...
                app.Joint3Slider.Value*pi/180]);
            %Update the EE position
            app.XEditField.Value = SE3.t(1,1);
            app.YEditField.Value = SE3.t(2,1);
            app.ZEditField.Value = SE3.t(3,1);
            
            %Calculate the distance of EE
            X = app.XEditField.Value;
            Y = app.YEditField.Value;
            Z = app.ZEditField.Value;
            app.DistanceEditField.Value = sqrt(X*X + Y*Y + Z*Z);
        end

        % Value changed function: Joint3Slider
        function Joint3SliderValueChanged(app, event)
            %Re-plot robot after joint1 angle changed
            app.G8Robot.plot([app.Joint1Slider.Value*pi/180 ...
                app.Joint2Slider.Value*pi/180 ...
                app.Joint3Slider.Value*pi/180]);
            %Recalculate the Transformation matrix after joint angle
            %changed
            SE3 = app.G8Robot.fkine([app.Joint1Slider.Value*pi/180 ...
                app.Joint2Slider.Value*pi/180 ...
                app.Joint3Slider.Value*pi/180]);
            %Update the EE position
            app.XEditField.Value = SE3.t(1,1);
            app.YEditField.Value = SE3.t(2,1);
            app.ZEditField.Value = SE3.t(3,1);
            
            %Calculate the distance of EE
            X = app.XEditField.Value;
            Y = app.YEditField.Value;
            Z = app.ZEditField.Value;
            app.DistanceEditField.Value = sqrt(X*X + Y*Y + Z*Z);
        end

        % Button pushed function: OptimizeButton
        function Optimize(app, event)
            a1 = app.a1EditField.Value;
            a2 = app.a2EditField.Value;
            d1 = app.d1EditField.Value;
            d2 = app.d2EditField.Value;
            d3 = app.d3EditField.Value;
            offset1 = app.offset1EditField.Value;
            offset2 = app.offset2EditField.Value;
            baseY = app.yBaseTransl.Value;
            MeasDH = [a1 a2 d1 d2 d3 offset1 offset2 baseY];
            options = optimset('Display','final','TolFun',1e-6,'TolX',1e-6, ...
                'PlotFcns',@optimplotfval,'MaxIter',500);
            [x,fval,exitflag,output] = fminsearch(@CalcDistError,MeasDH, options)
            app.errorEditField.Value = fval;
            app.A1EditField.Value = x(1);
            app.A2EditField.Value = x(2);
            app.D1EditField.Value = x(3);
            app.D2EditField.Value = x(4);
            app.D3EditField.Value = x(5);
            app.OFFSET1EditField.Value = x(6);
            app.OFFSET2EditField.Value = x(7);
            app.baseYEditField.Value = x(8);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyRelease, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.ReadDataMenu = uimenu(app.FileMenu);
            app.ReadDataMenu.MenuSelectedFcn = createCallbackFcn(app, @ReadData, true);
            app.ReadDataMenu.Text = 'ReadData';
            app.DifferentiateMenu = uimenu(app.FileMenu);
            app.DifferentiateMenu.MenuSelectedFcn = createCallbackFcn(app, @Differentiate, true);
            app.DifferentiateMenu.Text = 'Differentiate';
            app.CreateRobotMenu = uimenu(app.FileMenu);
            app.CreateRobotMenu.MenuSelectedFcn = createCallbackFcn(app, @CreateRobot, true);
            app.CreateRobotMenu.Text = 'CreateRobot';
            app.HardwareMenu = uimenu(app.UIFigure);
            app.HardwareMenu.Text = 'Hardware';
            app.InitArduinoMenu = uimenu(app.HardwareMenu);
            app.InitArduinoMenu.MenuSelectedFcn = createCallbackFcn(app, @Initialize, true);
            app.InitArduinoMenu.Text = 'InitArduino';
            app.TestArduinoMenu = uimenu(app.HardwareMenu);
            app.TestArduinoMenu.MenuSelectedFcn = createCallbackFcn(app, @TestArduino, true);
            app.TestArduinoMenu.Text = 'TestArduino';
            app.DHparametersPanel = uipanel(app.UIFigure);
            app.DHparametersPanel.Title = 'DH parameters';
            app.a1EditFieldLabel = uilabel(app.DHparametersPanel);
            app.a1EditFieldLabel.Text = 'a1';
            app.a1EditField = uieditfield(app.DHparametersPanel, 'numeric');
            app.a1EditField.ValueChangedFcn = createCallbackFcn(app, @Update_a1, true);
            app.a1EditField.Value = 0.1;
            app.a2EditFieldLabel = uilabel(app.DHparametersPanel);
            app.a2EditFieldLabel.Text = 'a2';
            app.a2EditField = uieditfield(app.DHparametersPanel, 'numeric');
            app.a2EditField.Value = 0.073;
            app.a3EditFieldLabel = uilabel(app.DHparametersPanel);
            app.a3EditFieldLabel.Text = 'a3';
            app.a3EditField = uieditfield(app.DHparametersPanel, 'numeric');
            app.alpha1EditFieldLabel = uilabel(app.DHparametersPanel);
            app.alpha1EditFieldLabel.Text = 'alpha1';
            app.alpha1EditField = uieditfield(app.DHparametersPanel, 'numeric');
            app.alpha2EditFieldLabel = uilabel(app.DHparametersPanel);
            app.alpha2EditFieldLabel.Text = 'alpha2';
            app.alpha2EditField = uieditfield(app.DHparametersPanel, 'numeric');
            app.alpha2EditField.Value = -90;
            app.alpha3EditFieldLabel = uilabel(app.DHparametersPanel);
            app.alpha3EditFieldLabel.Text = 'alpha3';
            app.alpha3EditField = uieditfield(app.DHparametersPanel, 'numeric');
            app.d1EditFieldLabel = uilabel(app.DHparametersPanel);
            app.d1EditFieldLabel.Text = 'd1';
            app.d1EditField = uieditfield(app.DHparametersPanel, 'numeric');
            app.d1EditField.Value = 0.047;
            app.d2EditFieldLabel = uilabel(app.DHparametersPanel);
            app.d2EditFieldLabel.Text = 'd2';
            app.d2EditField = uieditfield(app.DHparametersPanel, 'numeric');
            app.d2EditField.Value = 0.02;
            app.d3EditFieldLabel = uilabel(app.DHparametersPanel);
            app.d3EditFieldLabel.Text = 'd3';
            app.d3EditField = uieditfield(app.DHparametersPanel, 'numeric');
            app.d3EditField.Value = 0.02;
            app.BaseFrameTranslationLabel = uilabel(app.DHparametersPanel);
            app.BaseFrameTranslationLabel.Text = 'Base Frame Translation';
            app.xEditFieldLabel = uilabel(app.DHparametersPanel);
            app.xEditFieldLabel.Text = 'x';
            app.xBaseTransl = uieditfield(app.DHparametersPanel, 'numeric');
            app.yEditFieldLabel = uilabel(app.DHparametersPanel);
            app.yEditFieldLabel.Text = 'y';
            app.yBaseTransl = uieditfield(app.DHparametersPanel, 'numeric');
            app.yBaseTransl.Value = -0.08;
            app.zEditFieldLabel = uilabel(app.DHparametersPanel);
            app.zEditFieldLabel.Text = 'z';
            app.zBaseTransl = uieditfield(app.DHparametersPanel, 'numeric');
            app.offset1EditFieldLabel = uilabel(app.DHparametersPanel);
            app.offset1EditFieldLabel.Text = 'offset1';
            app.offset1EditField = uieditfield(app.DHparametersPanel, 'numeric');
            app.offset1EditField.Value = -19;
            app.offset2EditFieldLabel = uilabel(app.DHparametersPanel);
            app.offset2EditFieldLabel.Text = 'offset2';
            app.offset2EditField = uieditfield(app.DHparametersPanel, 'numeric');
            app.offset2EditField.Value = 16.8;
            app.offset3EditFieldLabel = uilabel(app.DHparametersPanel);
            app.offset3EditFieldLabel.Text = 'offset3';
            app.offset3EditField = uieditfield(app.DHparametersPanel, 'numeric');
            app.EndEffectorLocationPanel = uipanel(app.UIFigure);
            app.EndEffectorLocationPanel.Title = 'End-Effector Location';
            app.XEditFieldLabel = uilabel(app.EndEffectorLocationPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.EndEffectorLocationPanel, 'numeric');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @xPosChange, true);
            app.YEditFieldLabel = uilabel(app.EndEffectorLocationPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.EndEffectorLocationPanel, 'numeric');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @yPosChange, true);
            app.ZEditFieldLabel = uilabel(app.EndEffectorLocationPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.EndEffectorLocationPanel, 'numeric');
            app.ZEditField.ValueChangedFcn = createCallbackFcn(app, @zPosChange, true);
            app.DistanceEditFieldLabel = uilabel(app.UIFigure);
            app.DistanceEditFieldLabel.Text = 'Distance';
            app.DistanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.PoseDropDownLabel = uilabel(app.UIFigure);
            app.PoseDropDownLabel.Text = 'Pose';
            app.PoseDropDown = uidropdown(app.UIFigure);
            app.PoseDropDown.Items = {'Pose1_000', 'Pose2_090', 'Pose3_0-90', 'Pose4_900', 'Pose5_990', 'Pose6_9-90', 'Pose7_-900', 'Pose8_-990', 'Pose9_-9-90'};
            app.PoseDropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.PoseDropDown.ValueChangedFcn = createCallbackFcn(app, @ChangePose, true);
            app.PoseDropDown.Value = '2';
            app.Joint1SliderLabel = uilabel(app.UIFigure);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.UIFigure);
            app.Joint1Slider.Limits = [-120 90];
            app.Joint1Slider.ValueChangedFcn = createCallbackFcn(app, @Joint1SliderValueChanged, true);
            app.Joint2SliderLabel = uilabel(app.UIFigure);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.UIFigure);
            app.Joint2Slider.Limits = [-120 90];
            app.Joint2Slider.ValueChangedFcn = createCallbackFcn(app, @Joint2SliderValueChanged, true);
            app.Joint3SliderLabel = uilabel(app.UIFigure);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.UIFigure);
            app.Joint3Slider.Limits = [0 135];
            app.Joint3Slider.ValueChangedFcn = createCallbackFcn(app, @Joint3SliderValueChanged, true);
            app.OptimizationPanel = uipanel(app.UIFigure);
            app.OptimizationPanel.Title = 'Optimization';
            app.OptimizeButton = uibutton(app.OptimizationPanel, 'push');
            app.OptimizeButton.ButtonPushedFcn = createCallbackFcn(app, @Optimize, true);
            app.OptimizeButton.Text = 'Optimize';
            app.errorEditFieldLabel = uilabel(app.OptimizationPanel);
            app.errorEditFieldLabel.Text = 'error';
            app.errorEditField = uieditfield(app.OptimizationPanel, 'numeric');
            app.A1EditFieldLabel = uilabel(app.OptimizationPanel);
            app.A1EditFieldLabel.Text = 'A1';
            app.A1EditField = uieditfield(app.OptimizationPanel, 'numeric');
            app.A1EditField.Value = 0.1;
            app.A2EditFieldLabel = uilabel(app.OptimizationPanel);
            app.A2EditFieldLabel.Text = 'A2';
            app.A2EditField = uieditfield(app.OptimizationPanel, 'numeric');
            app.A2EditField.Value = 0.1;
            app.D1EditFieldLabel = uilabel(app.OptimizationPanel);
            app.D1EditFieldLabel.Text = 'D1';
            app.D1EditField = uieditfield(app.OptimizationPanel, 'numeric');
            app.D2EditFieldLabel = uilabel(app.OptimizationPanel);
            app.D2EditFieldLabel.Text = 'D2';
            app.D2EditField = uieditfield(app.OptimizationPanel, 'numeric');
            app.D3EditFieldLabel = uilabel(app.OptimizationPanel);
            app.D3EditFieldLabel.Text = 'D3';
            app.D3EditField = uieditfield(app.OptimizationPanel, 'numeric');
            app.OFFSET1EditFieldLabel = uilabel(app.OptimizationPanel);
            app.OFFSET1EditFieldLabel.Text = 'OFFSET1';
            app.OFFSET1EditField = uieditfield(app.OptimizationPanel, 'numeric');
            app.OFFSET2EditFieldLabel = uilabel(app.OptimizationPanel);
            app.OFFSET2EditFieldLabel.Text = 'OFFSET2';
            app.OFFSET2EditField = uieditfield(app.OptimizationPanel, 'numeric');
            app.baseYEditFieldLabel = uilabel(app.OptimizationPanel);
            app.baseYEditFieldLabel.Text = 'baseY';
            app.baseYEditField = uieditfield(app.OptimizationPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = G8RobotArm

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end