classdef G8RobotArm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        G8RobotArmUIFigure         matlab.ui.Figure
        FileMenu                   matlab.ui.container.Menu
        ReadDataMenu               matlab.ui.container.Menu
        DifferentiateMenu          matlab.ui.container.Menu
        CreateRobotMenu            matlab.ui.container.Menu
        HardwareMenu               matlab.ui.container.Menu
        ConnectMenu                matlab.ui.container.Menu
        TestArduinoMenu            matlab.ui.container.Menu
        ConfigMenu                 matlab.ui.container.Menu
        ArduinoMenu                matlab.ui.container.Menu
        DHparametersPanel          matlab.ui.container.Panel
        a1EditFieldLabel           matlab.ui.control.Label
        a1EditField                matlab.ui.control.NumericEditField
        a2EditFieldLabel           matlab.ui.control.Label
        a2EditField                matlab.ui.control.NumericEditField
        a3EditFieldLabel           matlab.ui.control.Label
        a3EditField                matlab.ui.control.NumericEditField
        alpha1EditFieldLabel       matlab.ui.control.Label
        alpha1EditField            matlab.ui.control.NumericEditField
        alpha2EditFieldLabel       matlab.ui.control.Label
        alpha2EditField            matlab.ui.control.NumericEditField
        alpha3EditFieldLabel       matlab.ui.control.Label
        alpha3EditField            matlab.ui.control.NumericEditField
        d1EditFieldLabel           matlab.ui.control.Label
        d1EditField                matlab.ui.control.NumericEditField
        d2EditFieldLabel           matlab.ui.control.Label
        d2EditField                matlab.ui.control.NumericEditField
        d3EditFieldLabel           matlab.ui.control.Label
        d3EditField                matlab.ui.control.NumericEditField
        BaseFrameTranslationLabel  matlab.ui.control.Label
        xEditFieldLabel            matlab.ui.control.Label
        xBaseTransl                matlab.ui.control.NumericEditField
        yEditFieldLabel            matlab.ui.control.Label
        yBaseTransl                matlab.ui.control.NumericEditField
        zEditFieldLabel            matlab.ui.control.Label
        zBaseTransl                matlab.ui.control.NumericEditField
        offset1EditFieldLabel      matlab.ui.control.Label
        offset1EditField           matlab.ui.control.NumericEditField
        offset2EditFieldLabel      matlab.ui.control.Label
        offset2EditField           matlab.ui.control.NumericEditField
        offset3EditFieldLabel      matlab.ui.control.Label
        offset3EditField           matlab.ui.control.NumericEditField
        EndEffectorLocationPanel   matlab.ui.container.Panel
        XEditFieldLabel            matlab.ui.control.Label
        XEditField                 matlab.ui.control.NumericEditField
        YEditFieldLabel            matlab.ui.control.Label
        YEditField                 matlab.ui.control.NumericEditField
        ZEditFieldLabel            matlab.ui.control.Label
        ZEditField                 matlab.ui.control.NumericEditField
        DistanceEditFieldLabel     matlab.ui.control.Label
        DistanceEditField          matlab.ui.control.NumericEditField
        PoseDropDownLabel          matlab.ui.control.Label
        PoseDropDown               matlab.ui.control.DropDown
        Joint1Label                matlab.ui.control.Label
        Joint1Slider               matlab.ui.control.Slider
        Joint2Label                matlab.ui.control.Label
        Joint2Slider               matlab.ui.control.Slider
        Joint3Label                matlab.ui.control.Label
        Joint3Slider               matlab.ui.control.Slider
        OptimizationPanel          matlab.ui.container.Panel
        OptimizeButton             matlab.ui.control.Button
        errorEditFieldLabel        matlab.ui.control.Label
        errorEditField             matlab.ui.control.NumericEditField
        A1EditFieldLabel           matlab.ui.control.Label
        A1EditField                matlab.ui.control.NumericEditField
        A2EditFieldLabel           matlab.ui.control.Label
        A2EditField                matlab.ui.control.NumericEditField
        D1EditFieldLabel           matlab.ui.control.Label
        D1EditField                matlab.ui.control.NumericEditField
        D2EditFieldLabel           matlab.ui.control.Label
        D2EditField                matlab.ui.control.NumericEditField
        D3EditFieldLabel           matlab.ui.control.Label
        D3EditField                matlab.ui.control.NumericEditField
        OFFSET1EditFieldLabel      matlab.ui.control.Label
        OFFSET1EditField           matlab.ui.control.NumericEditField
        OFFSET2EditFieldLabel      matlab.ui.control.Label
        OFFSET2EditField           matlab.ui.control.NumericEditField
        A3EditFieldLabel           matlab.ui.control.Label
        A3EditField                matlab.ui.control.NumericEditField
        OFFSET3EditFieldLabel      matlab.ui.control.Label
        OFFSET3EditField           matlab.ui.control.NumericEditField
        ALPHA1EditFieldLabel       matlab.ui.control.Label
        ALPHA1EditField            matlab.ui.control.NumericEditField
        ALPHA2EditFieldLabel       matlab.ui.control.Label
        ALPHA2EditField            matlab.ui.control.NumericEditField
        ALPHA3EditFieldLabel       matlab.ui.control.Label
        ALPHA3EditField            matlab.ui.control.NumericEditField
        baseXEditFieldLabel        matlab.ui.control.Label
        baseXEditField             matlab.ui.control.NumericEditField
        baseYEditFieldLabel        matlab.ui.control.Label
        baseYEditField             matlab.ui.control.NumericEditField
        baseZEditFieldLabel        matlab.ui.control.Label
        baseZEditField             matlab.ui.control.NumericEditField
        UpdateDHparaButton         matlab.ui.control.Button
    end

    
    properties (Access = private)
        DataRead = false; % Initialize as false
        Data = []; %buffer for reading data
        DiffData = [];
        arduino_hardware = [];
        arduino_initialized = true;
        JointServo1 = [];
        JointServo2 = [];
        JointServo3 = [];
        G8Robot = [];
        IsRobotCreated = false;
        optimized = false;

        % some useful poses
        Pose = [0 0 0;          %Pose1
                0 90 0;       %Pose2
                0 -90 0;      %Pose3
                90 0 0;       %Pose4
                90 90 0;    %Pose5
                90 -90 0;   %Pose6
                -90 0 0;      %Pose7
                -90 90 0;    %Pose8
                -90 -90 0];  %Pose9
        ConfigApp           % Config Dialog box app
        ComPort = "COM5"    % Initial Arduino COM port
        JointServoPin = ["D3" "D5" "D6"]
    end
    
    methods (Access = private)
        
        function results = updateRobot(app)
            if app.IsRobotCreated == true
                %Re-plot robot for new pose
                angle1 = (app.Joint1Slider.Value - app.Joint1Slider.Limits(1))/...
                         (app.Joint1Slider.Limits(2) - app.Joint1Slider.Limits(1));
                %writePosition(app.JointServo1, angle1);
                
                angle2 = (app.Joint2Slider.Value - app.Joint2Slider.Limits(1))/...
                         (app.Joint2Slider.Limits(2) - app.Joint2Slider.Limits(1));
                %writePosition(app.JointServo2, angle2);
                
                app.G8Robot.plot([app.Joint1Slider.Value*pi/180 ...
                    app.Joint2Slider.Value*pi/180 ...
                    app.Joint3Slider.Value*pi/180],"jvec");
                %Recalculate the Transformation matrix after joint angle
                %changed
                T = app.G8Robot.fkine([app.Joint1Slider.Value*pi/180 ...
                    app.Joint2Slider.Value*pi/180 ...
                    app.Joint3Slider.Value*pi/180]);
                %Update the EE position on GUI
                app.XEditField.Value = T.t(1);
                app.YEditField.Value = T.t(2);
                app.ZEditField.Value = T.t(3);
                %Calculate the distance of EE and update on GUI
                app.DistanceEditField.Value = sqrt(T.t(1)^2 + T.t(2)^2 + T.t(3)^2);
            else
                msgbox("Robot not created yet")
            end
        end
    end
    
    methods (Access = public)
        
        
        function updateConfig(app, port, servoPin)
            app.ComPort = port;     %Update port
            app.JointServoPin = servoPin;
            %re-enable config arduino port
            app.ArduinoMenu.Enable = 'on';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Set initial joints angle according to pose selection
            poseInx = str2num(app.PoseDropDown.Value);
            app.Joint1Slider.Value = app.Pose(poseInx,1);
            app.Joint2Slider.Value = app.Pose(poseInx,2);
            app.Joint3Slider.Value = app.Pose(poseInx,3);
            
        end

        % Menu selected function: ConnectMenu
        function Connect(app, event)
            try
                app.arduino_hardware = arduino(app.ComPort, 'Uno', ...
                    'Libraries', 'Servo')
                
            catch ME
                warn_msg = ["Connect failure","Indentifier:",ME.identifier,"Message:",ME.message];
                msgbox(warn_msg);
                return;
            end
            
            try
                app.JointServo1 = servo(app.arduino_hardware, 'D3', ...
                    'MinPulseDuration', 1.5*10^-3, 'MaxPulseDuration', 2*10^-3);
                app.JointServo2 = servo(app.arduino_hardware, 'D5', ...
                    'MinPulseDuration', 1.5*10^-3, 'MaxPulseDuration', 2*10^-3);
            catch ME
                warn_msg = ["Servo failure","Indentifier:",ME.identifier,"Message:",ME.message];
                msgbox(warn_msg);
                return;
            end
            
            app.arduino_initialized = true;
            msgbox('Arduino initialized');
        end

        % Menu selected function: TestArduinoMenu
        function TestArduino(app, event)
            if(app.arduino_initialized)
                app.arduino_hardware.writeDigitalPin("D13",1);
            end
        end

        % Menu selected function: ReadDataMenu
        function ReadData(app, event)
            [fn pn] = uigetfile('*.txt', 'Where is the data?');
            filename = [pn fn];
            app.Data = textread(filename);
            plot(app.UIAxes, app.Data(:,1),app.Data(:,2));
            app.DataRead = true;
            app.DataReadLamp.Color = [0.0 1.0 0.0];
            app.InfoTextArea.Value = 'Data has been read.';
        end

        % Menu selected function: DifferentiateMenu
        function Differentiate(app, event)
            if(app.DataRead)
                app.DiffData = app.Data;
                app.DiffData(1:999,2) = diff(app.Data(:,2));
                hold(app.UIAxes,'on');
                plot(app.UIAxes, app.DiffData(:,1),app.DiffData(:,2),'g');
                app.InfoTextArea.Value = 'Green is the differential';
            else
                app.InfoTextArea.Value = 'Read the data first';
            end
        end

        % Menu selected function: CreateRobotMenu
        function CreateRobot(app, event)
            clear L
            deg = pi/180;
            %get the joint angles limits from slider.Limits           
            qlim1 = deg2rad(app.Joint1Slider.Limits);
            qlim2 = deg2rad(app.Joint2Slider.Limits);
            qlim3 = deg2rad(app.Joint3Slider.Limits);
            
            % Define links of robot
            L(1) = Revolute('d', app.d1EditField.Value, ...   % link length (Dennavit-Hartenberg notation)
                'a', app.a1EditField.Value, ...               % link offset (Dennavit-Hartenberg notation)
                'alpha', app.alpha1EditField.Value*deg, ...   % link twist (Dennavit-Hartenberg notation)
                'qlim', qlim1, ...                            % minimum and maximum joint angle
                'offset',app.offset1EditField.Value*deg ); 
            
            L(2) = Revolute('d', app.d2EditField.Value, ...
                'a', app.a2EditField.Value, ...
                'alpha', app.alpha2EditField.Value*deg, ...
                'qlim', qlim2, ...
                'offset',app.offset2EditField.Value*deg );
            
            L(3) = Revolute('d', app.d3EditField.Value, ...
                'a', app.a3EditField.Value, ...
                'alpha', app.alpha3EditField.Value*deg,  ...
                'qlim', qlim3, ...
                'offset',app.offset3EditField.Value*deg  );

            %Build the robot
            app.G8Robot = SerialLink(L, 'name', 'G8 Robot Arm', ...
                'tool',transl(0,0,0), ...
                'base',transl(app.xBaseTransl.Value, ...
                        app.yBaseTransl.Value, ...
                        app.zBaseTransl.Value));
            %Set the robot created flag
            app.IsRobotCreated = true;
            %plot the robot and update postion and distance info
            app.updateRobot();
        end

        % Value changed function: a1EditField
        function Update_a1(app, event)
            if app.IsRobotCreated == true
                %clear current figure before re-plot
                clf;
                %update robot a1 parameter
                app.G8Robot.links(1).a = app.a1EditField.Value;
                %plot the robot and update postion and distance info
                app.updateRobot();
            end
        end

        % Key release function: G8RobotArmUIFigure
        function G8RobotArmUIFigureKeyRelease(app, event)
            %make sure robot is created already
            if app.IsRobotCreated == true
                %Get current robot EE transformation matrix
                q1 = [app.Joint1Slider.Value*pi/180 ...
                    app.Joint2Slider.Value*pi/180 ...
                    app.Joint3Slider.Value*pi/180];
                T = app.G8Robot.fkine(q1);
                %adjust x or y position according to key press
                if event.Key == "leftarrow"
                    T.t(2) = T.t(2) + 0.005;    %move to Y + 0.005m
                elseif event.Key == "rightarrow"
                    T.t(2) = T.t(2) - 0.005;    %move to Y - 0.005m
                elseif event.Key == "uparrow"
                    T.t(1) = T.t(1) + 0.005;    %move to X + 0.005m
                elseif event.Key == "downarrow"
                    T.t(1) = T.t(1) - 0.005;    %move to X - 0.005m
                else 
                end
                %Do inverse kinematics
                q2 = app.G8Robot.ikine(T,'q0',q1,'mask',[1 1 1 0 0 0]);
                if (isempty(q2) == false)
                    %update joints angle after ikine
                    q2deg = rad2deg(q2);
                    if((q2deg(1) <= app.Joint1Slider.Limits(2)) && ...
                       (q2deg(1) >= app.Joint1Slider.Limits(1)) && ...
                       (q2deg(2) <= app.Joint2Slider.Limits(2)) && ...
                       (q2deg(2) >= app.Joint2Slider.Limits(1)))
                   
                        app.Joint1Slider.Value = q2deg(1);
                        app.Joint2Slider.Value = q2deg(2);
                        app.Joint3Slider.Value = q2deg(3);
                        %plot the robot and update postion and distance info
                        app.updateRobot();
                    else
                        msg = sprintf("Joint angle reaches limit:\n" + ...
                                      "Joint1 angle:%0.1f\n" + ...
                                      "Joint2 angle:%0.1f",q2deg(1),q2deg(2));
                        msgbox(msg);
                    end
                else
                    msgbox("can't reach the target");
                end
            else
                msgbox("Robot not created yet")
            end
        end

        % Value changed function: PoseDropDown
        function ChangePose(app, event)
            %make sure robot is created already
            if app.IsRobotCreated == true
                %update joints angle according to pose selection
                poseinx = str2num(app.PoseDropDown.Value);
                app.Joint1Slider.Value = app.Pose(poseinx,1);
                app.Joint2Slider.Value = app.Pose(poseinx,2);
                app.Joint3Slider.Value = app.Pose(poseinx,3);
                
                %plot the robot and update postion and distance info
                app.updateRobot();
            else
                msgbox("Robot not created yet")
            end            
        end

        % Value changed function: Joint1Slider
        function Joint1SliderValueChanged(app, event)
            %plot the robot and update postion and distance info
            app.updateRobot();
        end

        % Value changed function: Joint2Slider
        function Joint2SliderValueChanged(app, event)
            %plot the robot and update postion and distance info
            app.updateRobot();
        end

        % Value changed function: Joint3Slider
        function Joint3SliderValueChanged(app, event)
            %plot the robot and update postion and distance info
            app.updateRobot();
        end

        % Button pushed function: OptimizeButton
        function Optimize(app, event)
            %before start, set flag as flase
            app.optimized = false;
            
            %read preset/measured DH parameters to DH variables
            a1 = app.a1EditField.Value;
            a2 = app.a2EditField.Value;
            a3 = app.a3EditField.Value;
            alpha1 = app.alpha1EditField.Value;
            alpha2 = app.alpha2EditField.Value;
            alpha3 = app.alpha3EditField.Value;
            d1 = app.d1EditField.Value;
            d2 = app.d2EditField.Value;
            d3 = app.d3EditField.Value;
            offset1 = app.offset1EditField.Value;
            offset2 = app.offset2EditField.Value;
            offset3 = app.offset3EditField.Value;
            baseX = app.xBaseTransl.Value;
            baseY = app.yBaseTransl.Value;
            baseZ = app.zBaseTransl.Value;
            
            %Assemble DH parameters for optimization
            MeasDH = [a1 a2 a3;alpha1 alpha2 alpha3;d1 d2 d3; 
                      offset1 offset2 offset3;baseX baseY baseZ];
            %set optimization options
            options = optimset('Display','final','TolX',1e-6, ...
                'PlotFcns',@optimplotfval,'MaxIter',500);
            %start optimization
            [x,fval,exitflag,output] = fminsearch(@CalcDistError,MeasDH, options)
            
            app.optimized = true;
            %optimization finished, fill the result to GUI
            app.errorEditField.Value = fval;
            app.A1EditField.Value = x(1,1);
            app.A2EditField.Value = x(1,2);
            app.A3EditField.Value = x(1,3);
            app.ALPHA1EditField.Value = x(2,1);
            app.ALPHA2EditField.Value = x(2,2);
            app.ALPHA3EditField.Value = x(2,3);
            app.D1EditField.Value = x(3,1);
            app.D2EditField.Value = x(3,2);
            app.D3EditField.Value = x(3,3);
            app.OFFSET1EditField.Value = x(4,1);
            app.OFFSET2EditField.Value = x(4,2);
            app.OFFSET3EditField.Value = x(4,3);
            app.baseXEditField.Value = x(5,1);
            app.baseYEditField.Value = x(5,2);
            app.baseZEditField.Value = x(5,3);
        end

        % Value changed function: a2EditField
        function update_a2(app, event)
            if app.IsRobotCreated == true
                %clear current figure before re-plot
                clf;
                %update robot a1 parameter
                app.G8Robot.links(2).a = app.a2EditField.Value;
                %plot the robot and update postion and distance info
                app.updateRobot();
            end            
        end

        % Value changed function: d1EditField
        function update_d1(app, event)
            if app.IsRobotCreated == true
                %clear current figure before re-plot
                clf;
                %update robot a1 parameter
                app.G8Robot.links(1).d = app.d1EditField.Value;
                %plot the robot and update postion and distance info
                app.updateRobot();
            end            
        end

        % Value changed function: d2EditField
        function update_d2(app, event)
            if app.IsRobotCreated == true
                %clear current figure before re-plot
                clf;
                %update robot a1 parameter
                app.G8Robot.links(2).d = app.d2EditField.Value;
                %plot the robot and update postion and distance info
                app.updateRobot();
            end            
        end

        % Value changed function: d3EditField
        function update_d3(app, event)
            if app.IsRobotCreated == true
                %clear current figure before re-plot
                clf;
                %update robot a1 parameter
                app.G8Robot.links(3).d = app.d3EditField.Value;
                %plot the robot and update postion and distance info
                app.updateRobot();
            end            
        end

        % Value changed function: alpha2EditField
        function update_alpha2(app, event)
            if app.IsRobotCreated == true
                %clear current figure before re-plot
                clf;
                %update robot a1 parameter
                app.G8Robot.links(2).alpha = deg2rad(app.alpha2EditField.Value);
                %plot the robot and update postion and distance info
                app.updateRobot();
            end            
        end

        % Value changed function: offset1EditField
        function update_offset1(app, event)
            if app.IsRobotCreated == true
                %clear current figure before re-plot
                %clf;
                %update robot a1 parameter
                app.G8Robot.offset(1) = app.offset1EditField.Value*pi/180;
                %plot the robot and update postion and distance info
                app.updateRobot();
            end  
        end

        % Value changed function: offset2EditField
        function update_offset2(app, event)
            if app.IsRobotCreated == true
                %clear current figure before re-plot
                %clf;
                %update robot a1 parameter
                app.G8Robot.offset(2) = app.offset2EditField.Value*pi/180;
                %plot the robot and update postion and distance info
                app.updateRobot();
            end 
        end

        % Button pushed function: UpdateDHparaButton
        function UpdateDHPara(app, event)
            if app.optimized
                app.a1EditField.Value = app.A1EditField.Value;
                app.a2EditField.Value = app.A2EditField.Value;
                app.a3EditField.Value = app.A3EditField.Value;
                app.alpha1EditField.Value = app.ALPHA1EditField.Value;
                app.alpha2EditField.Value = app.ALPHA2EditField.Value;
                app.alpha3EditField.Value = app.ALPHA3EditField.Value;
                app.d1EditField.Value = app.D1EditField.Value;
                app.d2EditField.Value = app.D2EditField.Value;
                app.d3EditField.Value = app.D3EditField.Value;
                app.offset1EditField.Value = app.OFFSET1EditField.Value;
                app.offset2EditField.Value = app.OFFSET2EditField.Value;
                app.offset3EditField.Value = app.OFFSET3EditField.Value;
                app.xBaseTransl.Value = app.baseXEditField.Value;
                app.yBaseTransl.Value = app.baseYEditField.Value;
                app.zBaseTransl.Value = app.baseZEditField.Value;
            end
        end

        % Value changed function: alpha1EditField
        function update_alpha1(app, event)
            if app.IsRobotCreated == true
                %clear current figure before re-plot
                %clf;
                %update robot a1 parameter
                app.G8Robot.links(1).alpha = deg2rad(app.alpha1EditField.Value);
                %plot the robot and update postion and distance info
                app.updateRobot();
            end 
        end

        % Menu selected function: ArduinoMenu
        function ConfigArduino(app, event)
            %Disable the menu to avoid reopen config window 
            app.ArduinoMenu.Enable = 'off';
            
            % Open the options dialog and pass inputs
            app.ConfigApp = Config(app, app.ComPort, app.JointServoPin);
            
        end

        % Close request function: G8RobotArmUIFigure
        function G8RobotArmUIFigureCloseRequest(app, event)
            if app.IsRobotCreated == true
                close(1)
            end
            
            % Delete both apps
            delete(app.ConfigApp)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.G8RobotArmUIFigure = uifigure('Visible', 'off');
            app.G8RobotArmUIFigure.Name = 'G8 Robot Arm';
            app.G8RobotArmUIFigure.CloseRequestFcn = createCallbackFcn(app, @G8RobotArmUIFigureCloseRequest, true);
            app.G8RobotArmUIFigure.KeyReleaseFcn = createCallbackFcn(app, @G8RobotArmUIFigureKeyRelease, true);
            app.FileMenu = uimenu(app.G8RobotArmUIFigure);
            app.FileMenu.Text = 'File';
            app.ReadDataMenu = uimenu(app.FileMenu);
            app.ReadDataMenu.MenuSelectedFcn = createCallbackFcn(app, @ReadData, true);
            app.ReadDataMenu.Text = 'ReadData';
            app.DifferentiateMenu = uimenu(app.FileMenu);
            app.DifferentiateMenu.MenuSelectedFcn = createCallbackFcn(app, @Differentiate, true);
            app.DifferentiateMenu.Text = 'Differentiate';
            app.CreateRobotMenu = uimenu(app.FileMenu);
            app.CreateRobotMenu.MenuSelectedFcn = createCallbackFcn(app, @CreateRobot, true);
            app.CreateRobotMenu.Text = 'CreateRobot';
            app.HardwareMenu = uimenu(app.G8RobotArmUIFigure);
            app.HardwareMenu.Text = 'Hardware';
            app.ConnectMenu = uimenu(app.HardwareMenu);
            app.ConnectMenu.MenuSelectedFcn = createCallbackFcn(app, @Connect, true);
            app.ConnectMenu.Text = 'Connect';
            app.TestArduinoMenu = uimenu(app.HardwareMenu);
            app.TestArduinoMenu.MenuSelectedFcn = createCallbackFcn(app, @TestArduino, true);
            app.TestArduinoMenu.Text = 'TestArduino';
            app.ConfigMenu = uimenu(app.G8RobotArmUIFigure);
            app.ConfigMenu.Text = 'Config';
            app.ArduinoMenu = uimenu(app.ConfigMenu);
            app.ArduinoMenu.MenuSelectedFcn = createCallbackFcn(app, @ConfigArduino, true);
            app.ArduinoMenu.Text = 'Arduino';
            app.DHparametersPanel = uipanel(app.G8RobotArmUIFigure);
            app.DHparametersPanel.Title = 'DH parameters';
            app.a1EditFieldLabel = uilabel(app.DHparametersPanel);
            app.a1EditFieldLabel.Text = 'a1';
            app.a1EditField = uieditfield(app.DHparametersPanel, 'numeric');
            app.a1EditField.ValueChangedFcn = createCallbackFcn(app, @Update_a1, true);
            app.a1EditField.Value = 0.1;
            app.a2EditFieldLabel = uilabel(app.DHparametersPanel);
            app.a2EditFieldLabel.Text = 'a2';
            app.a2EditField = uieditfield(app.DHparametersPanel, 'numeric');
            app.a2EditField.ValueChangedFcn = createCallbackFcn(app, @update_a2, true);
            app.a2EditField.Value = 0.073;
            app.a3EditFieldLabel = uilabel(app.DHparametersPanel);
            app.a3EditFieldLabel.Text = 'a3';
            app.a3EditField = uieditfield(app.DHparametersPanel, 'numeric');
            app.alpha1EditFieldLabel = uilabel(app.DHparametersPanel);
            app.alpha1EditFieldLabel.Text = 'alpha1';
            app.alpha1EditField = uieditfield(app.DHparametersPanel, 'numeric');
            app.alpha1EditField.ValueChangedFcn = createCallbackFcn(app, @update_alpha1, true);
            app.alpha2EditFieldLabel = uilabel(app.DHparametersPanel);
            app.alpha2EditFieldLabel.Text = 'alpha2';
            app.alpha2EditField = uieditfield(app.DHparametersPanel, 'numeric');
            app.alpha2EditField.ValueChangedFcn = createCallbackFcn(app, @update_alpha2, true);
            app.alpha2EditField.Value = -90;
            app.alpha3EditFieldLabel = uilabel(app.DHparametersPanel);
            app.alpha3EditFieldLabel.Text = 'alpha3';
            app.alpha3EditField = uieditfield(app.DHparametersPanel, 'numeric');
            app.d1EditFieldLabel = uilabel(app.DHparametersPanel);
            app.d1EditFieldLabel.Text = 'd1';
            app.d1EditField = uieditfield(app.DHparametersPanel, 'numeric');
            app.d1EditField.ValueChangedFcn = createCallbackFcn(app, @update_d1, true);
            app.d2EditFieldLabel = uilabel(app.DHparametersPanel);
            app.d2EditFieldLabel.Text = 'd2';
            app.d2EditField = uieditfield(app.DHparametersPanel, 'numeric');
            app.d2EditField.ValueChangedFcn = createCallbackFcn(app, @update_d2, true);
            app.d2EditField.Value = 0.02;
            app.d3EditFieldLabel = uilabel(app.DHparametersPanel);
            app.d3EditFieldLabel.Text = 'd3';
            app.d3EditField = uieditfield(app.DHparametersPanel, 'numeric');
            app.d3EditField.ValueChangedFcn = createCallbackFcn(app, @update_d3, true);
            app.d3EditField.Value = 0.02;
            app.BaseFrameTranslationLabel = uilabel(app.DHparametersPanel);
            app.BaseFrameTranslationLabel.Text = 'Base Frame Translation';
            app.xEditFieldLabel = uilabel(app.DHparametersPanel);
            app.xEditFieldLabel.Text = 'x';
            app.xBaseTransl = uieditfield(app.DHparametersPanel, 'numeric');
            app.yEditFieldLabel = uilabel(app.DHparametersPanel);
            app.yEditFieldLabel.Text = 'y';
            app.yBaseTransl = uieditfield(app.DHparametersPanel, 'numeric');
            app.yBaseTransl.Value = -0.08;
            app.zEditFieldLabel = uilabel(app.DHparametersPanel);
            app.zEditFieldLabel.Text = 'z';
            app.zBaseTransl = uieditfield(app.DHparametersPanel, 'numeric');
            app.zBaseTransl.Limits = [-180 180];
            app.zBaseTransl.Value = 0.047;
            app.offset1EditFieldLabel = uilabel(app.DHparametersPanel);
            app.offset1EditFieldLabel.Text = 'offset1';
            app.offset1EditField = uieditfield(app.DHparametersPanel, 'numeric');
            app.offset1EditField.ValueChangedFcn = createCallbackFcn(app, @update_offset1, true);
            app.offset1EditField.Value = -19;
            app.offset2EditFieldLabel = uilabel(app.DHparametersPanel);
            app.offset2EditFieldLabel.Text = 'offset2';
            app.offset2EditField = uieditfield(app.DHparametersPanel, 'numeric');
            app.offset2EditField.ValueChangedFcn = createCallbackFcn(app, @update_offset2, true);
            app.offset2EditField.Value = 16.8;
            app.offset3EditFieldLabel = uilabel(app.DHparametersPanel);
            app.offset3EditFieldLabel.Text = 'offset3';
            app.offset3EditField = uieditfield(app.DHparametersPanel, 'numeric');
            app.EndEffectorLocationPanel = uipanel(app.G8RobotArmUIFigure);
            app.EndEffectorLocationPanel.Title = 'End-Effector Location';
            app.XEditFieldLabel = uilabel(app.EndEffectorLocationPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.EndEffectorLocationPanel, 'numeric');
            app.YEditFieldLabel = uilabel(app.EndEffectorLocationPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.EndEffectorLocationPanel, 'numeric');
            app.ZEditFieldLabel = uilabel(app.EndEffectorLocationPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.EndEffectorLocationPanel, 'numeric');
            app.DistanceEditFieldLabel = uilabel(app.G8RobotArmUIFigure);
            app.DistanceEditFieldLabel.Text = 'Distance';
            app.DistanceEditField = uieditfield(app.G8RobotArmUIFigure, 'numeric');
            app.PoseDropDownLabel = uilabel(app.G8RobotArmUIFigure);
            app.PoseDropDownLabel.Text = 'Pose';
            app.PoseDropDown = uidropdown(app.G8RobotArmUIFigure);
            app.PoseDropDown.Items = {'Pose1_000', 'Pose2_090', 'Pose3_0-90', 'Pose4_900', 'Pose5_990', 'Pose6_9-90', 'Pose7_-900', 'Pose8_-990', 'Pose9_-9-90'};
            app.PoseDropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.PoseDropDown.ValueChangedFcn = createCallbackFcn(app, @ChangePose, true);
            app.PoseDropDown.Value = '2';
            app.Joint1Label = uilabel(app.G8RobotArmUIFigure);
            app.Joint1Label.Text = 'Joint1';
            app.Joint1Slider = uislider(app.G8RobotArmUIFigure);
            app.Joint1Slider.Limits = [-90 120];
            app.Joint1Slider.ValueChangedFcn = createCallbackFcn(app, @Joint1SliderValueChanged, true);
            app.Joint2Label = uilabel(app.G8RobotArmUIFigure);
            app.Joint2Label.Text = 'Joint2';
            app.Joint2Slider = uislider(app.G8RobotArmUIFigure);
            app.Joint2Slider.Limits = [-130 100];
            app.Joint2Slider.ValueChangedFcn = createCallbackFcn(app, @Joint2SliderValueChanged, true);
            app.Joint3Label = uilabel(app.G8RobotArmUIFigure);
            app.Joint3Label.Text = 'Joint3';
            app.Joint3Slider = uislider(app.G8RobotArmUIFigure);
            app.Joint3Slider.Limits = [0 135];
            app.Joint3Slider.ValueChangedFcn = createCallbackFcn(app, @Joint3SliderValueChanged, true);
            app.OptimizationPanel = uipanel(app.G8RobotArmUIFigure);
            app.OptimizationPanel.Title = 'Optimization';
            app.OptimizeButton = uibutton(app.OptimizationPanel, 'push');
            app.OptimizeButton.ButtonPushedFcn = createCallbackFcn(app, @Optimize, true);
            app.OptimizeButton.Text = 'Optimize';
            app.errorEditFieldLabel = uilabel(app.OptimizationPanel);
            app.errorEditFieldLabel.Text = 'error';
            app.errorEditField = uieditfield(app.OptimizationPanel, 'numeric');
            app.A1EditFieldLabel = uilabel(app.OptimizationPanel);
            app.A1EditFieldLabel.Text = 'A1';
            app.A1EditField = uieditfield(app.OptimizationPanel, 'numeric');
            app.A2EditFieldLabel = uilabel(app.OptimizationPanel);
            app.A2EditFieldLabel.Text = 'A2';
            app.A2EditField = uieditfield(app.OptimizationPanel, 'numeric');
            app.D1EditFieldLabel = uilabel(app.OptimizationPanel);
            app.D1EditFieldLabel.Text = 'D1';
            app.D1EditField = uieditfield(app.OptimizationPanel, 'numeric');
            app.D2EditFieldLabel = uilabel(app.OptimizationPanel);
            app.D2EditFieldLabel.Text = 'D2';
            app.D2EditField = uieditfield(app.OptimizationPanel, 'numeric');
            app.D3EditFieldLabel = uilabel(app.OptimizationPanel);
            app.D3EditFieldLabel.Text = 'D3';
            app.D3EditField = uieditfield(app.OptimizationPanel, 'numeric');
            app.OFFSET1EditFieldLabel = uilabel(app.OptimizationPanel);
            app.OFFSET1EditFieldLabel.Text = 'OFFSET1';
            app.OFFSET1EditField = uieditfield(app.OptimizationPanel, 'numeric');
            app.OFFSET2EditFieldLabel = uilabel(app.OptimizationPanel);
            app.OFFSET2EditFieldLabel.Text = 'OFFSET2';
            app.OFFSET2EditField = uieditfield(app.OptimizationPanel, 'numeric');
            app.A3EditFieldLabel = uilabel(app.OptimizationPanel);
            app.A3EditFieldLabel.Text = 'A3';
            app.A3EditField = uieditfield(app.OptimizationPanel, 'numeric');
            app.OFFSET3EditFieldLabel = uilabel(app.OptimizationPanel);
            app.OFFSET3EditFieldLabel.Text = 'OFFSET3';
            app.OFFSET3EditField = uieditfield(app.OptimizationPanel, 'numeric');
            app.ALPHA1EditFieldLabel = uilabel(app.OptimizationPanel);
            app.ALPHA1EditFieldLabel.Text = 'ALPHA1';
            app.ALPHA1EditField = uieditfield(app.OptimizationPanel, 'numeric');
            app.ALPHA2EditFieldLabel = uilabel(app.OptimizationPanel);
            app.ALPHA2EditFieldLabel.Text = 'ALPHA2';
            app.ALPHA2EditField = uieditfield(app.OptimizationPanel, 'numeric');
            app.ALPHA3EditFieldLabel = uilabel(app.OptimizationPanel);
            app.ALPHA3EditFieldLabel.Text = 'ALPHA3';
            app.ALPHA3EditField = uieditfield(app.OptimizationPanel, 'numeric');
            app.baseXEditFieldLabel = uilabel(app.OptimizationPanel);
            app.baseXEditFieldLabel.Text = 'baseX';
            app.baseXEditField = uieditfield(app.OptimizationPanel, 'numeric');
            app.baseYEditFieldLabel = uilabel(app.OptimizationPanel);
            app.baseYEditFieldLabel.Text = 'baseY';
            app.baseYEditField = uieditfield(app.OptimizationPanel, 'numeric');
            app.baseZEditFieldLabel = uilabel(app.OptimizationPanel);
            app.baseZEditFieldLabel.Text = 'baseZ';
            app.baseZEditField = uieditfield(app.OptimizationPanel, 'numeric');
            app.UpdateDHparaButton = uibutton(app.OptimizationPanel, 'push');
            app.UpdateDHparaButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateDHPara, true);
            app.UpdateDHparaButton.Text = 'Update DH para';
            % Show the figure after all components are created
            app.G8RobotArmUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = G8RobotArm

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.G8RobotArmUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.G8RobotArmUIFigure)
        end
    end
end