classdef ConfigHW < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ConfigUIFigure               matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        HardwareTab                  matlab.ui.container.Tab
        ArduinoUSBPortDropDownLabel  matlab.ui.control.Label
        ArduinoUSBPortDropDown       matlab.ui.control.DropDown
        Joint1MinPulseEditField      matlab.ui.control.NumericEditField
        PinLabel                     matlab.ui.control.Label
        MinPulseDurationusLabel      matlab.ui.control.Label
        MaxPulseDurationusLabel      matlab.ui.control.Label
        Joint1MaxPulseEditField      matlab.ui.control.NumericEditField
        Joint2MinPulseEditField      matlab.ui.control.NumericEditField
        Joint3MinPulseEditField      matlab.ui.control.NumericEditField
        Joint2MaxPulseEditField      matlab.ui.control.NumericEditField
        Joint3MaxPulseEditField      matlab.ui.control.NumericEditField
        Joint1ServoDropDownLabel     matlab.ui.control.Label
        Joint1ServoDropDown          matlab.ui.control.DropDown
        Joint2ServoDropDownLabel     matlab.ui.control.Label
        Joint2ServoDropDown          matlab.ui.control.DropDown
        Joint3ServoDropDownLabel     matlab.ui.control.Label
        Joint3ServoDropDown          matlab.ui.control.DropDown
        DHParametersTab              matlab.ui.container.Tab
        BaseFrameTranslationLabel    matlab.ui.control.Label
        a1EditFieldLabel             matlab.ui.control.Label
        a1EditField                  matlab.ui.control.NumericEditField
        a2EditFieldLabel             matlab.ui.control.Label
        a2EditField                  matlab.ui.control.NumericEditField
        a3EditFieldLabel             matlab.ui.control.Label
        a3EditField                  matlab.ui.control.NumericEditField
        alpha1EditFieldLabel         matlab.ui.control.Label
        alpha1EditField              matlab.ui.control.NumericEditField
        alpha2EditFieldLabel         matlab.ui.control.Label
        alpha2EditField              matlab.ui.control.NumericEditField
        alpha3EditFieldLabel         matlab.ui.control.Label
        alpha3EditField              matlab.ui.control.NumericEditField
        d1EditFieldLabel             matlab.ui.control.Label
        d1EditField                  matlab.ui.control.NumericEditField
        d2EditFieldLabel             matlab.ui.control.Label
        d2EditField                  matlab.ui.control.NumericEditField
        d3EditFieldLabel             matlab.ui.control.Label
        d3EditField                  matlab.ui.control.NumericEditField
        offset3EditFieldLabel        matlab.ui.control.Label
        offset3EditField             matlab.ui.control.NumericEditField
        offset2EditFieldLabel        matlab.ui.control.Label
        offset2EditField             matlab.ui.control.NumericEditField
        offset1EditFieldLabel        matlab.ui.control.Label
        offset1EditField             matlab.ui.control.NumericEditField
        xEditFieldLabel              matlab.ui.control.Label
        xEditField                   matlab.ui.control.NumericEditField
        yEditFieldLabel              matlab.ui.control.Label
        yEditField                   matlab.ui.control.NumericEditField
        zEditFieldLabel              matlab.ui.control.Label
        zEditField                   matlab.ui.control.NumericEditField
        GameTab                      matlab.ui.container.Tab
        VLLStartXEditField           matlab.ui.control.NumericEditField
        VLLStartYEditField           matlab.ui.control.NumericEditField
        VRLStartXEditField           matlab.ui.control.NumericEditField
        VRLStartYEditField           matlab.ui.control.NumericEditField
        HULStartXEditField           matlab.ui.control.NumericEditField
        HULStartYEditField           matlab.ui.control.NumericEditField
        HDLStartXEditField           matlab.ui.control.NumericEditField
        HDLStartYEditField           matlab.ui.control.NumericEditField
        HDLEndYEditField             matlab.ui.control.NumericEditField
        HULEndYEditField             matlab.ui.control.NumericEditField
        VRLEndYEditField             matlab.ui.control.NumericEditField
        HDLEndXEditField             matlab.ui.control.NumericEditField
        HULEndXEditField             matlab.ui.control.NumericEditField
        VLLEndYEditField             matlab.ui.control.NumericEditField
        VRLEndXEditField             matlab.ui.control.NumericEditField
        VLLEndXEditField             matlab.ui.control.NumericEditField
        StartXLabel                  matlab.ui.control.Label
        StartYLabel                  matlab.ui.control.Label
        EndYLabel                    matlab.ui.control.Label
        EndXLabel                    matlab.ui.control.Label
        Center1XEditField            matlab.ui.control.NumericEditField
        Center1YEditField            matlab.ui.control.NumericEditField
        Center2XEditField            matlab.ui.control.NumericEditField
        Center2YEditField            matlab.ui.control.NumericEditField
        Center3XEditField            matlab.ui.control.NumericEditField
        Center3YEditField            matlab.ui.control.NumericEditField
        Center4XEditField            matlab.ui.control.NumericEditField
        Center4YEditField            matlab.ui.control.NumericEditField
        Center5XEditField            matlab.ui.control.NumericEditField
        Center5YEditField            matlab.ui.control.NumericEditField
        Center6XEditField            matlab.ui.control.NumericEditField
        Center6YEditField            matlab.ui.control.NumericEditField
        Center7XEditField            matlab.ui.control.NumericEditField
        Center7YEditField            matlab.ui.control.NumericEditField
        Center8XEditField            matlab.ui.control.NumericEditField
        Center8YEditField            matlab.ui.control.NumericEditField
        XLabel                       matlab.ui.control.Label
        YLabel                       matlab.ui.control.Label
        VerticalLeftLineLabel        matlab.ui.control.Label
        VerticalRightLineLabel       matlab.ui.control.Label
        HorizontalUpLineLabel        matlab.ui.control.Label
        HorizontalDownLineLabel      matlab.ui.control.Label
        Center1Label                 matlab.ui.control.Label
        Center2Label                 matlab.ui.control.Label
        Center3Label                 matlab.ui.control.Label
        Center4Label                 matlab.ui.control.Label
        Center5Label                 matlab.ui.control.Label
        Center6Label                 matlab.ui.control.Label
        Center7Label                 matlab.ui.control.Label
        Center8Label                 matlab.ui.control.Label
        Center9XEditField            matlab.ui.control.NumericEditField
        Center9YEditField            matlab.ui.control.NumericEditField
        Center9Label                 matlab.ui.control.Label
        CenterRLabel                 matlab.ui.control.Label
        CenterREditField             matlab.ui.control.NumericEditField
        XLabel_2                     matlab.ui.control.Label
        YLabel_2                     matlab.ui.control.Label
        InfoTab                      matlab.ui.container.Tab
        Image                        matlab.ui.control.Image
        OKButton                     matlab.ui.control.Button
        CancelButton                 matlab.ui.control.Button
    end

    
    properties (Access = private)
        mainApp % main window app object
        data %all configuration data
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, data, ComPort, ServoPin)
            % Store main app in property for CloseRequestFcn to use
            app.mainApp = mainApp;
            app.data = data;
            
            app.ArduinoUSBPortDropDown.Items= serialportlist("all");
            if ~isempty(app.ArduinoUSBPortDropDown.Items)
                app.ArduinoUSBPortDropDown.Value = ComPort;
            end
            app.Joint1ServoDropDown.Value = ServoPin(1);
            app.Joint2ServoDropDown.Value = ServoPin(2);
            app.Joint3ServoDropDown.Value = ServoPin(3);
            
            app.a1EditField.Value = data{strcmp("DH_a1",data),2};
            app.a2EditField.Value = data{strcmp("DH_a2",data),2};
            app.a3EditField.Value = data{strcmp("DH_a3",data),2};
            app.alpha1EditField.Value = data{strcmp("DH_alpha1",data),2};
            app.alpha2EditField.Value = data{strcmp("DH_alpha2",data),2};
            app.alpha3EditField.Value = data{strcmp("DH_alpha3",data),2};
            app.d1EditField.Value = data{strcmp("DH_d1",data),2};
            app.d2EditField.Value = data{strcmp("DH_d2",data),2};
            app.d3EditField.Value = data{strcmp("DH_d3",data),2};
            app.offset1EditField.Value = data{strcmp("DH_offset1",data),2};
            app.offset2EditField.Value = data{strcmp("DH_offset2",data),2};
            app.offset3EditField.Value = data{strcmp("DH_offset3",data),2};
            app.xEditField.Value = data{strcmp("DH_baseX",data),2};
            app.yEditField.Value = data{strcmp("DH_baseY",data),2};
            app.zEditField.Value = data{strcmp("DH_baseZ",data),2};
            
            app.Joint1MinPulseEditField.Value = data{strcmp("Servo1MinPulse",data),2};
            app.Joint1MaxPulseEditField.Value = data{strcmp("Servo1MaxPulse",data),2};
            app.Joint2MinPulseEditField.Value = data{strcmp("Servo2MinPulse",data),2};
            app.Joint2MaxPulseEditField.Value = data{strcmp("Servo2MaxPulse",data),2};
            app.Joint3MinPulseEditField.Value = data{strcmp("Servo3MinPulse",data),2};
            app.Joint3MaxPulseEditField.Value = data{strcmp("Servo3MaxPulse",data),2};
            
            %Vertical Left Line Start position and end position
            app.VLLStartXEditField.Value = data{strcmp("Grid_VLS_X",data),2};
            app.VLLStartYEditField.Value = data{strcmp("Grid_VLS_Y",data),2};
            app.VLLEndXEditField.Value = data{strcmp("Grid_VLE_X",data),2};
            app.VLLEndYEditField.Value = data{strcmp("Grid_VLE_Y",data),2};
            %Vertical Right Line Start position and end position
            app.VRLStartXEditField.Value = data{strcmp("Grid_VRS_X",data),2};
            app.VRLStartYEditField.Value = data{strcmp("Grid_VRS_Y",data),2};
            app.VRLEndXEditField.Value = data{strcmp("Grid_VRE_X",data),2};
            app.VRLEndYEditField.Value = data{strcmp("Grid_VRE_Y",data),2};
            %Horizontal upper Line Start position and end position
            app.HULStartXEditField.Value = data{strcmp("Grid_HUS_X",data),2};
            app.HULStartYEditField.Value = data{strcmp("Grid_HUS_Y",data),2};
            app.HULEndXEditField.Value = data{strcmp("Grid_HUE_X",data),2};
            app.HULEndYEditField.Value = data{strcmp("Grid_HUE_Y",data),2};
            %Horizontal down Line Start position and end position
            app.HDLStartXEditField.Value = data{strcmp("Grid_HDS_X",data),2};
            app.HDLStartYEditField.Value = data{strcmp("Grid_HDS_Y",data),2};
            app.HDLEndXEditField.Value = data{strcmp("Grid_HDE_X",data),2};
            app.HDLEndYEditField.Value = data{strcmp("Grid_HDE_Y",data),2};
            
            app.Center1XEditField.Value = data{strcmp("Square1_X",data),2};
            app.Center1YEditField.Value = data{strcmp("Square1_Y",data),2};
            app.Center2XEditField.Value = data{strcmp("Square2_X",data),2};
            app.Center2YEditField.Value = data{strcmp("Square2_Y",data),2};
            app.Center3XEditField.Value = data{strcmp("Square3_X",data),2};
            app.Center3YEditField.Value = data{strcmp("Square3_Y",data),2};
            app.Center4XEditField.Value = data{strcmp("Square4_X",data),2};
            app.Center4YEditField.Value = data{strcmp("Square4_Y",data),2};
            app.Center5XEditField.Value = data{strcmp("Square5_X",data),2};
            app.Center5YEditField.Value = data{strcmp("Square5_Y",data),2};
            app.Center6XEditField.Value = data{strcmp("Square6_X",data),2};
            app.Center6YEditField.Value = data{strcmp("Square6_Y",data),2};
            app.Center7XEditField.Value = data{strcmp("Square7_X",data),2};
            app.Center7YEditField.Value = data{strcmp("Square7_Y",data),2};
            app.Center8XEditField.Value = data{strcmp("Square8_X",data),2};
            app.Center8YEditField.Value = data{strcmp("Square8_Y",data),2};
            app.Center9XEditField.Value = data{strcmp("Square9_X",data),2};
            app.Center9YEditField.Value = data{strcmp("Square9_Y",data),2};
        end

        % Button pushed function: OKButton
        function Confirm(app, event)
            com_port = app.ArduinoUSBPortDropDown.Value;
            servo_pin ={app.Joint1ServoDropDown.Value, ...
                        app.Joint2ServoDropDown.Value, ...
                        app.Joint3ServoDropDown.Value};
            % Call main app's public function
            updateHWConfig(app.mainApp, com_port,servo_pin);
            
            % Delete the dialog box
            delete(app)
        end

        % Button pushed function: CancelButton
        function Cancel(app, event)
            % Enable the Plot Opions button in main app
            app.mainApp.HWConfigMenu.Enable = 'on';
            
            % Delete the dialog box 
            delete(app)
        end

        % Close request function: ConfigUIFigure
        function ConfigUIFigureCloseRequest(app, event)
            % Enable the Plot Opions button in main app
            app.mainApp.HWConfigMenu.Enable = 'on';
            
            % Delete the dialog box 
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ConfigUIFigure = uifigure('Visible', 'off');
            app.ConfigUIFigure.Name = 'Config';
            app.ConfigUIFigure.CloseRequestFcn = createCallbackFcn(app, @ConfigUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.ConfigUIFigure);
            app.HardwareTab = uitab(app.TabGroup);
            app.HardwareTab.Title = 'Hardware';
            app.ArduinoUSBPortDropDownLabel = uilabel(app.HardwareTab);
            app.ArduinoUSBPortDropDownLabel.Text = 'Arduino USB Port';
            app.ArduinoUSBPortDropDown = uidropdown(app.HardwareTab);
            app.Joint1MinPulseEditField = uieditfield(app.HardwareTab, 'numeric');
            app.Joint1MinPulseEditField.Value = 575;
            app.PinLabel = uilabel(app.HardwareTab);
            app.PinLabel.Text = 'Pin';
            app.MinPulseDurationusLabel = uilabel(app.HardwareTab);
            app.MinPulseDurationusLabel.Text = 'MinPulseDuration(us)';
            app.MaxPulseDurationusLabel = uilabel(app.HardwareTab);
            app.MaxPulseDurationusLabel.Text = 'MaxPulseDuration(us)';
            app.Joint1MaxPulseEditField = uieditfield(app.HardwareTab, 'numeric');
            app.Joint1MaxPulseEditField.Value = 2460;
            app.Joint2MinPulseEditField = uieditfield(app.HardwareTab, 'numeric');
            app.Joint2MinPulseEditField.Value = 575;
            app.Joint3MinPulseEditField = uieditfield(app.HardwareTab, 'numeric');
            app.Joint3MinPulseEditField.Value = 640;
            app.Joint2MaxPulseEditField = uieditfield(app.HardwareTab, 'numeric');
            app.Joint2MaxPulseEditField.Value = 2460;
            app.Joint3MaxPulseEditField = uieditfield(app.HardwareTab, 'numeric');
            app.Joint3MaxPulseEditField.Value = 2250;
            app.Joint1ServoDropDownLabel = uilabel(app.HardwareTab);
            app.Joint1ServoDropDownLabel.Text = 'Joint1 Servo';
            app.Joint1ServoDropDown = uidropdown(app.HardwareTab);
            app.Joint1ServoDropDown.Items = {'D3', 'D5', 'D6', 'D9', 'D10', 'D11', ''};
            app.Joint1ServoDropDown.Value = 'D3';
            app.Joint2ServoDropDownLabel = uilabel(app.HardwareTab);
            app.Joint2ServoDropDownLabel.Text = 'Joint2 Servo';
            app.Joint2ServoDropDown = uidropdown(app.HardwareTab);
            app.Joint2ServoDropDown.Items = {'D3', 'D5', 'D6', 'D9', 'D10', 'D11'};
            app.Joint2ServoDropDown.Value = 'D5';
            app.Joint3ServoDropDownLabel = uilabel(app.HardwareTab);
            app.Joint3ServoDropDownLabel.Text = 'Joint3 Servo';
            app.Joint3ServoDropDown = uidropdown(app.HardwareTab);
            app.Joint3ServoDropDown.Items = {'D3', 'D5', 'D6', 'D9', 'D10', 'D11'};
            app.Joint3ServoDropDown.Value = 'D6';
            app.DHParametersTab = uitab(app.TabGroup);
            app.DHParametersTab.Title = 'DH Parameters';
            app.BaseFrameTranslationLabel = uilabel(app.DHParametersTab);
            app.BaseFrameTranslationLabel.Text = 'Base Frame Translation';
            app.a1EditFieldLabel = uilabel(app.DHParametersTab);
            app.a1EditFieldLabel.Text = 'a1';
            app.a1EditField = uieditfield(app.DHParametersTab, 'numeric');
            app.a2EditFieldLabel = uilabel(app.DHParametersTab);
            app.a2EditFieldLabel.Text = 'a2';
            app.a2EditField = uieditfield(app.DHParametersTab, 'numeric');
            app.a3EditFieldLabel = uilabel(app.DHParametersTab);
            app.a3EditFieldLabel.Text = 'a3';
            app.a3EditField = uieditfield(app.DHParametersTab, 'numeric');
            app.alpha1EditFieldLabel = uilabel(app.DHParametersTab);
            app.alpha1EditFieldLabel.Text = 'alpha1';
            app.alpha1EditField = uieditfield(app.DHParametersTab, 'numeric');
            app.alpha2EditFieldLabel = uilabel(app.DHParametersTab);
            app.alpha2EditFieldLabel.Text = 'alpha2';
            app.alpha2EditField = uieditfield(app.DHParametersTab, 'numeric');
            app.alpha3EditFieldLabel = uilabel(app.DHParametersTab);
            app.alpha3EditFieldLabel.Text = 'alpha3';
            app.alpha3EditField = uieditfield(app.DHParametersTab, 'numeric');
            app.d1EditFieldLabel = uilabel(app.DHParametersTab);
            app.d1EditFieldLabel.Text = 'd1';
            app.d1EditField = uieditfield(app.DHParametersTab, 'numeric');
            app.d2EditFieldLabel = uilabel(app.DHParametersTab);
            app.d2EditFieldLabel.Text = 'd2';
            app.d2EditField = uieditfield(app.DHParametersTab, 'numeric');
            app.d3EditFieldLabel = uilabel(app.DHParametersTab);
            app.d3EditFieldLabel.Text = 'd3';
            app.d3EditField = uieditfield(app.DHParametersTab, 'numeric');
            app.offset3EditFieldLabel = uilabel(app.DHParametersTab);
            app.offset3EditFieldLabel.Text = 'offset3';
            app.offset3EditField = uieditfield(app.DHParametersTab, 'numeric');
            app.offset2EditFieldLabel = uilabel(app.DHParametersTab);
            app.offset2EditFieldLabel.Text = 'offset2';
            app.offset2EditField = uieditfield(app.DHParametersTab, 'numeric');
            app.offset1EditFieldLabel = uilabel(app.DHParametersTab);
            app.offset1EditFieldLabel.Text = 'offset1';
            app.offset1EditField = uieditfield(app.DHParametersTab, 'numeric');
            app.xEditFieldLabel = uilabel(app.DHParametersTab);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.DHParametersTab, 'numeric');
            app.yEditFieldLabel = uilabel(app.DHParametersTab);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.DHParametersTab, 'numeric');
            app.zEditFieldLabel = uilabel(app.DHParametersTab);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.DHParametersTab, 'numeric');
            app.GameTab = uitab(app.TabGroup);
            app.GameTab.Title = 'Game';
            app.VLLStartXEditField = uieditfield(app.GameTab, 'numeric');
            app.VLLStartXEditField.ValueDisplayFormat = '%.2f';
            app.VLLStartXEditField.Value = 0.16;
            app.VLLStartYEditField = uieditfield(app.GameTab, 'numeric');
            app.VLLStartYEditField.Value = -0.04;
            app.VRLStartXEditField = uieditfield(app.GameTab, 'numeric');
            app.VRLStartXEditField.Value = 0.16;
            app.VRLStartYEditField = uieditfield(app.GameTab, 'numeric');
            app.VRLStartYEditField.Value = -0.08;
            app.HULStartXEditField = uieditfield(app.GameTab, 'numeric');
            app.HULStartXEditField.Value = 0.12;
            app.HULStartYEditField = uieditfield(app.GameTab, 'numeric');
            app.HULStartYEditField.ValueDisplayFormat = '%.2f';
            app.HDLStartXEditField = uieditfield(app.GameTab, 'numeric');
            app.HDLStartXEditField.ValueDisplayFormat = '%.2f';
            app.HDLStartXEditField.Value = 0.08;
            app.HDLStartYEditField = uieditfield(app.GameTab, 'numeric');
            app.HDLStartYEditField.ValueDisplayFormat = '%.2f';
            app.HDLEndYEditField = uieditfield(app.GameTab, 'numeric');
            app.HDLEndYEditField.ValueDisplayFormat = '%.2f';
            app.HDLEndYEditField.Value = -0.12;
            app.HULEndYEditField = uieditfield(app.GameTab, 'numeric');
            app.HULEndYEditField.ValueDisplayFormat = '%.2f';
            app.HULEndYEditField.Value = -0.12;
            app.VRLEndYEditField = uieditfield(app.GameTab, 'numeric');
            app.VRLEndYEditField.Value = -0.08;
            app.HDLEndXEditField = uieditfield(app.GameTab, 'numeric');
            app.HDLEndXEditField.ValueDisplayFormat = '%.2f';
            app.HDLEndXEditField.Value = 0.08;
            app.HULEndXEditField = uieditfield(app.GameTab, 'numeric');
            app.HULEndXEditField.ValueDisplayFormat = '%.2f';
            app.HULEndXEditField.Value = 0.12;
            app.VLLEndYEditField = uieditfield(app.GameTab, 'numeric');
            app.VLLEndYEditField.Value = -0.04;
            app.VRLEndXEditField = uieditfield(app.GameTab, 'numeric');
            app.VRLEndXEditField.Value = 0.04;
            app.VLLEndXEditField = uieditfield(app.GameTab, 'numeric');
            app.VLLEndXEditField.Value = 0.04;
            app.StartXLabel = uilabel(app.GameTab);
            app.StartXLabel.Text = 'StartX';
            app.StartYLabel = uilabel(app.GameTab);
            app.StartYLabel.Text = 'StartY';
            app.EndYLabel = uilabel(app.GameTab);
            app.EndYLabel.Text = 'EndY';
            app.EndXLabel = uilabel(app.GameTab);
            app.EndXLabel.Text = 'EndX';
            app.Center1XEditField = uieditfield(app.GameTab, 'numeric');
            app.Center1XEditField.ValueDisplayFormat = '%.2f';
            app.Center1XEditField.Value = 0.14;
            app.Center1YEditField = uieditfield(app.GameTab, 'numeric');
            app.Center1YEditField.ValueDisplayFormat = '%.2f';
            app.Center1YEditField.Value = -0.02;
            app.Center2XEditField = uieditfield(app.GameTab, 'numeric');
            app.Center2XEditField.ValueDisplayFormat = '%.2f';
            app.Center2XEditField.Value = 0.1;
            app.Center2YEditField = uieditfield(app.GameTab, 'numeric');
            app.Center2YEditField.ValueDisplayFormat = '%.2f';
            app.Center2YEditField.Value = -0.02;
            app.Center3XEditField = uieditfield(app.GameTab, 'numeric');
            app.Center3XEditField.ValueDisplayFormat = '%.2f';
            app.Center3XEditField.Value = 0.06;
            app.Center3YEditField = uieditfield(app.GameTab, 'numeric');
            app.Center3YEditField.ValueDisplayFormat = '%.2f';
            app.Center3YEditField.Value = -0.02;
            app.Center4XEditField = uieditfield(app.GameTab, 'numeric');
            app.Center4XEditField.ValueDisplayFormat = '%.2f';
            app.Center4XEditField.Value = 0.14;
            app.Center4YEditField = uieditfield(app.GameTab, 'numeric');
            app.Center4YEditField.ValueDisplayFormat = '%.2f';
            app.Center4YEditField.Value = -0.06;
            app.Center5XEditField = uieditfield(app.GameTab, 'numeric');
            app.Center5XEditField.ValueDisplayFormat = '%.2f';
            app.Center5XEditField.Value = 0.1;
            app.Center5YEditField = uieditfield(app.GameTab, 'numeric');
            app.Center5YEditField.ValueDisplayFormat = '%.2f';
            app.Center5YEditField.Value = -0.06;
            app.Center6XEditField = uieditfield(app.GameTab, 'numeric');
            app.Center6XEditField.ValueDisplayFormat = '%.2f';
            app.Center6XEditField.Value = 0.06;
            app.Center6YEditField = uieditfield(app.GameTab, 'numeric');
            app.Center6YEditField.ValueDisplayFormat = '%.2f';
            app.Center6YEditField.Value = -0.06;
            app.Center7XEditField = uieditfield(app.GameTab, 'numeric');
            app.Center7XEditField.ValueDisplayFormat = '%.2f';
            app.Center7XEditField.Value = 0.14;
            app.Center7YEditField = uieditfield(app.GameTab, 'numeric');
            app.Center7YEditField.ValueDisplayFormat = '%.2f';
            app.Center7YEditField.Value = -0.1;
            app.Center8XEditField = uieditfield(app.GameTab, 'numeric');
            app.Center8XEditField.ValueDisplayFormat = '%.2f';
            app.Center8XEditField.Value = 0.1;
            app.Center8YEditField = uieditfield(app.GameTab, 'numeric');
            app.Center8YEditField.ValueDisplayFormat = '%.2f';
            app.Center8YEditField.Value = -0.1;
            app.XLabel = uilabel(app.GameTab);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.GameTab);
            app.YLabel.Text = 'Y';
            app.VerticalLeftLineLabel = uilabel(app.GameTab);
            app.VerticalLeftLineLabel.Text = 'Vertical Left Line';
            app.VerticalRightLineLabel = uilabel(app.GameTab);
            app.VerticalRightLineLabel.Text = 'Vertical Right Line';
            app.HorizontalUpLineLabel = uilabel(app.GameTab);
            app.HorizontalUpLineLabel.Text = 'Horizontal Up Line';
            app.HorizontalDownLineLabel = uilabel(app.GameTab);
            app.HorizontalDownLineLabel.Text = 'Horizontal Down Line';
            app.Center1Label = uilabel(app.GameTab);
            app.Center1Label.Text = 'Center1';
            app.Center2Label = uilabel(app.GameTab);
            app.Center2Label.Text = 'Center2';
            app.Center3Label = uilabel(app.GameTab);
            app.Center3Label.Text = 'Center3';
            app.Center4Label = uilabel(app.GameTab);
            app.Center4Label.Text = 'Center4';
            app.Center5Label = uilabel(app.GameTab);
            app.Center5Label.Text = 'Center5';
            app.Center6Label = uilabel(app.GameTab);
            app.Center6Label.Text = 'Center6';
            app.Center7Label = uilabel(app.GameTab);
            app.Center7Label.Text = 'Center7';
            app.Center8Label = uilabel(app.GameTab);
            app.Center8Label.Text = 'Center8';
            app.Center9XEditField = uieditfield(app.GameTab, 'numeric');
            app.Center9XEditField.ValueDisplayFormat = '%.2f';
            app.Center9XEditField.Value = 0.06;
            app.Center9YEditField = uieditfield(app.GameTab, 'numeric');
            app.Center9YEditField.ValueDisplayFormat = '%.2f';
            app.Center9YEditField.Value = -0.1;
            app.Center9Label = uilabel(app.GameTab);
            app.Center9Label.Text = 'Center9';
            app.CenterRLabel = uilabel(app.GameTab);
            app.CenterRLabel.Text = 'CenterR';
            app.CenterREditField = uieditfield(app.GameTab, 'numeric');
            app.CenterREditField.ValueDisplayFormat = '%.2f';
            app.CenterREditField.Value = 0.06;
            app.XLabel_2 = uilabel(app.GameTab);
            app.XLabel_2.Text = 'X';
            app.YLabel_2 = uilabel(app.GameTab);
            app.YLabel_2.Text = 'Y';
            app.InfoTab = uitab(app.TabGroup);
            app.InfoTab.Title = 'Info';
            app.Image = uiimage(app.InfoTab);
            app.Image.ImageSource = 'GameBoardConfig.png';
            app.OKButton = uibutton(app.ConfigUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @Confirm, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.ConfigUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @Cancel, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.ConfigUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ConfigHW(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ConfigUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ConfigUIFigure)
        end
    end
end