classdef ConfigurationDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ConfigurationUIFigure           matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        HardwareTab                     matlab.ui.container.Tab
        ArduinoUSBPortDropDownLabel     matlab.ui.control.Label
        ArduinoUSBPortDropDown          matlab.ui.control.DropDown
        Joint1MinPulseEditField         matlab.ui.control.NumericEditField
        PinLabel                        matlab.ui.control.Label
        MinPulseDurationusLabel         matlab.ui.control.Label
        MaxPulseDurationusLabel         matlab.ui.control.Label
        Joint1MaxPulseEditField         matlab.ui.control.NumericEditField
        Joint2MinPulseEditField         matlab.ui.control.NumericEditField
        Joint3MinPulseEditField         matlab.ui.control.NumericEditField
        Joint2MaxPulseEditField         matlab.ui.control.NumericEditField
        Joint3MaxPulseEditField         matlab.ui.control.NumericEditField
        Joint1ServoDropDownLabel        matlab.ui.control.Label
        Joint1ServoDropDown             matlab.ui.control.DropDown
        Joint2ServoDropDownLabel        matlab.ui.control.Label
        Joint2ServoDropDown             matlab.ui.control.DropDown
        Joint3ServoDropDownLabel        matlab.ui.control.Label
        Joint3ServoDropDown             matlab.ui.control.DropDown
        DHParametersTab                 matlab.ui.container.Tab
        BaseFrameTranslationLabel       matlab.ui.control.Label
        a1EditFieldLabel                matlab.ui.control.Label
        a1EditField                     matlab.ui.control.NumericEditField
        a2EditFieldLabel                matlab.ui.control.Label
        a2EditField                     matlab.ui.control.NumericEditField
        a3EditFieldLabel                matlab.ui.control.Label
        a3EditField                     matlab.ui.control.NumericEditField
        alpha1EditFieldLabel            matlab.ui.control.Label
        alpha1EditField                 matlab.ui.control.NumericEditField
        alpha2EditFieldLabel            matlab.ui.control.Label
        alpha2EditField                 matlab.ui.control.NumericEditField
        alpha3EditFieldLabel            matlab.ui.control.Label
        alpha3EditField                 matlab.ui.control.NumericEditField
        d1EditFieldLabel                matlab.ui.control.Label
        d1EditField                     matlab.ui.control.NumericEditField
        d2EditFieldLabel                matlab.ui.control.Label
        d2EditField                     matlab.ui.control.NumericEditField
        d3EditFieldLabel                matlab.ui.control.Label
        d3EditField                     matlab.ui.control.NumericEditField
        offset3EditFieldLabel           matlab.ui.control.Label
        offset3EditField                matlab.ui.control.NumericEditField
        offset2EditFieldLabel           matlab.ui.control.Label
        offset2EditField                matlab.ui.control.NumericEditField
        offset1EditFieldLabel           matlab.ui.control.Label
        offset1EditField                matlab.ui.control.NumericEditField
        xEditFieldLabel                 matlab.ui.control.Label
        xEditField                      matlab.ui.control.NumericEditField
        yEditFieldLabel                 matlab.ui.control.Label
        yEditField                      matlab.ui.control.NumericEditField
        zEditFieldLabel                 matlab.ui.control.Label
        zEditField                      matlab.ui.control.NumericEditField
        GameTab                         matlab.ui.container.Tab
        VLLStartXEditField              matlab.ui.control.NumericEditField
        VLLStartYEditField              matlab.ui.control.NumericEditField
        VRLStartXEditField              matlab.ui.control.NumericEditField
        VRLStartYEditField              matlab.ui.control.NumericEditField
        HULStartXEditField              matlab.ui.control.NumericEditField
        HULStartYEditField              matlab.ui.control.NumericEditField
        HDLStartXEditField              matlab.ui.control.NumericEditField
        HDLStartYEditField              matlab.ui.control.NumericEditField
        HDLEndYEditField                matlab.ui.control.NumericEditField
        HULEndYEditField                matlab.ui.control.NumericEditField
        VRLEndYEditField                matlab.ui.control.NumericEditField
        HDLEndXEditField                matlab.ui.control.NumericEditField
        HULEndXEditField                matlab.ui.control.NumericEditField
        VLLEndYEditField                matlab.ui.control.NumericEditField
        VRLEndXEditField                matlab.ui.control.NumericEditField
        VLLEndXEditField                matlab.ui.control.NumericEditField
        StartXLabel                     matlab.ui.control.Label
        StartYLabel                     matlab.ui.control.Label
        EndYLabel                       matlab.ui.control.Label
        EndXLabel                       matlab.ui.control.Label
        Center1XEditField               matlab.ui.control.NumericEditField
        Center1YEditField               matlab.ui.control.NumericEditField
        Center2XEditField               matlab.ui.control.NumericEditField
        Center2YEditField               matlab.ui.control.NumericEditField
        Center3XEditField               matlab.ui.control.NumericEditField
        Center3YEditField               matlab.ui.control.NumericEditField
        Center4XEditField               matlab.ui.control.NumericEditField
        Center4YEditField               matlab.ui.control.NumericEditField
        Center5XEditField               matlab.ui.control.NumericEditField
        Center5YEditField               matlab.ui.control.NumericEditField
        Center6XEditField               matlab.ui.control.NumericEditField
        Center6YEditField               matlab.ui.control.NumericEditField
        Center7XEditField               matlab.ui.control.NumericEditField
        Center7YEditField               matlab.ui.control.NumericEditField
        Center8XEditField               matlab.ui.control.NumericEditField
        Center8YEditField               matlab.ui.control.NumericEditField
        XLabel                          matlab.ui.control.Label
        YLabel                          matlab.ui.control.Label
        VerticalLeftLineLabel           matlab.ui.control.Label
        VerticalRightLineLabel          matlab.ui.control.Label
        HorizontalUpLineLabel           matlab.ui.control.Label
        HorizontalDownLineLabel         matlab.ui.control.Label
        Center1Label                    matlab.ui.control.Label
        Center2Label                    matlab.ui.control.Label
        Center3Label                    matlab.ui.control.Label
        Center4Label                    matlab.ui.control.Label
        Center5Label                    matlab.ui.control.Label
        Center6Label                    matlab.ui.control.Label
        Center7Label                    matlab.ui.control.Label
        Center8Label                    matlab.ui.control.Label
        Center9XEditField               matlab.ui.control.NumericEditField
        Center9YEditField               matlab.ui.control.NumericEditField
        Center9Label                    matlab.ui.control.Label
        CenterRLabel                    matlab.ui.control.Label
        CenterREditField                matlab.ui.control.NumericEditField
        XLabel_2                        matlab.ui.control.Label
        YLabel_2                        matlab.ui.control.Label
        DrawmovetrajectoryCheckBox      matlab.ui.control.CheckBox
        GenerateTrajectoryfileCheckBox  matlab.ui.control.CheckBox
        UseTrajectoryfiletodrawCheckBox  matlab.ui.control.CheckBox
        InfoTab                         matlab.ui.container.Tab
        Image                           matlab.ui.control.Image
        OKButton                        matlab.ui.control.Button
        CancelButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        mainApp % main window app object
        data %all configuration data
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, data, ComPort, ServoPin)
            % Store main app in property for CloseRequestFcn to use
            app.mainApp = mainApp;
            app.data = data;
            
            app.ArduinoUSBPortDropDown.Items= serialportlist("all");
            if ~isempty(app.ArduinoUSBPortDropDown.Items)
                app.ArduinoUSBPortDropDown.Value = ComPort;
            end
            app.Joint1ServoDropDown.Value = ServoPin(1);
            app.Joint2ServoDropDown.Value = ServoPin(2);
            app.Joint3ServoDropDown.Value = ServoPin(3);
            
            app.a1EditField.Value = data{strcmp("DH_a1",data),2};
            app.a2EditField.Value = data{strcmp("DH_a2",data),2};
            app.a3EditField.Value = data{strcmp("DH_a3",data),2};
            app.alpha1EditField.Value = data{strcmp("DH_alpha1",data),2};
            app.alpha2EditField.Value = data{strcmp("DH_alpha2",data),2};
            app.alpha3EditField.Value = data{strcmp("DH_alpha3",data),2};
            app.d1EditField.Value = data{strcmp("DH_d1",data),2};
            app.d2EditField.Value = data{strcmp("DH_d2",data),2};
            app.d3EditField.Value = data{strcmp("DH_d3",data),2};
            app.offset1EditField.Value = data{strcmp("DH_offset1",data),2};
            app.offset2EditField.Value = data{strcmp("DH_offset2",data),2};
            app.offset3EditField.Value = data{strcmp("DH_offset3",data),2};
            app.xEditField.Value = data{strcmp("DH_baseX",data),2};
            app.yEditField.Value = data{strcmp("DH_baseY",data),2};
            app.zEditField.Value = data{strcmp("DH_baseZ",data),2};
            
            app.Joint1MinPulseEditField.Value = data{strcmp("Servo1MinPulse",data),2};
            app.Joint1MaxPulseEditField.Value = data{strcmp("Servo1MaxPulse",data),2};
            app.Joint2MinPulseEditField.Value = data{strcmp("Servo2MinPulse",data),2};
            app.Joint2MaxPulseEditField.Value = data{strcmp("Servo2MaxPulse",data),2};
            app.Joint3MinPulseEditField.Value = data{strcmp("Servo3MinPulse",data),2};
            app.Joint3MaxPulseEditField.Value = data{strcmp("Servo3MaxPulse",data),2};
            
            %Vertical Left Line Start position and end position
            app.VLLStartXEditField.Value = data{strcmp("Grid_VLS_X",data),2};
            app.VLLStartYEditField.Value = data{strcmp("Grid_VLS_Y",data),2};
            app.VLLEndXEditField.Value = data{strcmp("Grid_VLE_X",data),2};
            app.VLLEndYEditField.Value = data{strcmp("Grid_VLE_Y",data),2};
            %Vertical Right Line Start position and end position
            app.VRLStartXEditField.Value = data{strcmp("Grid_VRS_X",data),2};
            app.VRLStartYEditField.Value = data{strcmp("Grid_VRS_Y",data),2};
            app.VRLEndXEditField.Value = data{strcmp("Grid_VRE_X",data),2};
            app.VRLEndYEditField.Value = data{strcmp("Grid_VRE_Y",data),2};
            %Horizontal upper Line Start position and end position
            app.HULStartXEditField.Value = data{strcmp("Grid_HUS_X",data),2};
            app.HULStartYEditField.Value = data{strcmp("Grid_HUS_Y",data),2};
            app.HULEndXEditField.Value = data{strcmp("Grid_HUE_X",data),2};
            app.HULEndYEditField.Value = data{strcmp("Grid_HUE_Y",data),2};
            %Horizontal down Line Start position and end position
            app.HDLStartXEditField.Value = data{strcmp("Grid_HDS_X",data),2};
            app.HDLStartYEditField.Value = data{strcmp("Grid_HDS_Y",data),2};
            app.HDLEndXEditField.Value = data{strcmp("Grid_HDE_X",data),2};
            app.HDLEndYEditField.Value = data{strcmp("Grid_HDE_Y",data),2};
            
            app.Center1XEditField.Value = data{strcmp("Center1_X",data),2};
            app.Center1YEditField.Value = data{strcmp("Center1_Y",data),2};
            app.Center2XEditField.Value = data{strcmp("Center2_X",data),2};
            app.Center2YEditField.Value = data{strcmp("Center2_Y",data),2};
            app.Center3XEditField.Value = data{strcmp("Center3_X",data),2};
            app.Center3YEditField.Value = data{strcmp("Center3_Y",data),2};
            app.Center4XEditField.Value = data{strcmp("Center4_X",data),2};
            app.Center4YEditField.Value = data{strcmp("Center4_Y",data),2};
            app.Center5XEditField.Value = data{strcmp("Center5_X",data),2};
            app.Center5YEditField.Value = data{strcmp("Center5_Y",data),2};
            app.Center6XEditField.Value = data{strcmp("Center6_X",data),2};
            app.Center6YEditField.Value = data{strcmp("Center6_Y",data),2};
            app.Center7XEditField.Value = data{strcmp("Center7_X",data),2};
            app.Center7YEditField.Value = data{strcmp("Center7_Y",data),2};
            app.Center8XEditField.Value = data{strcmp("Center8_X",data),2};
            app.Center8YEditField.Value = data{strcmp("Center8_Y",data),2};
            app.Center9XEditField.Value = data{strcmp("Center9_X",data),2};
            app.Center9YEditField.Value = data{strcmp("Center9_Y",data),2};
            app.CenterREditField.Value = data{strcmp("Radius",data),2};
            
            if data{strcmp("isRecordTraj",data),2}
                app.GenerateTrajectoryfileCheckBox.Value = 1;
            else
                app.GenerateTrajectoryfileCheckBox.Value = 0;
            end
            
            if data{strcmp("isDrawMoveTraj",data),2}
                app.DrawmovetrajectoryCheckBox.Value = 1;
            else
                app.DrawmovetrajectoryCheckBox.Value = 0;
            end
            
            if data{strcmp("isUseFileTraj",data),2}
                app.UseTrajectoryfiletodrawCheckBox.Value = 1;
            else
                app.UseTrajectoryfiletodrawCheckBox.Value = 0;
            end
            
        end

        % Button pushed function: OKButton
        function Confirm(app, event)
            com_port = app.ArduinoUSBPortDropDown.Value;
            servo_pin = {app.Joint1ServoDropDown.Value; ...
                        app.Joint2ServoDropDown.Value; ...
                        app.Joint3ServoDropDown.Value};
            % Call main app's public function
            updateSysConfig(app.mainApp, app.data,com_port,servo_pin);
            
            % Delete the dialog box
            delete(app)
        end

        % Button pushed function: CancelButton
        function Cancel(app, event)
            % Enable the Plot Opions button in main app
            app.mainApp.ConfigurationMenu.Enable = 'on';
            
            % Delete the dialog box 
            delete(app)
        end

        % Close request function: ConfigurationUIFigure
        function ConfigurationUIFigureCloseRequest(app, event)
            % Enable the Plot Opions button in main app
            app.mainApp.ConfigurationMenu.Enable = 'on';
            
            % Delete the dialog box 
            delete(app)
        end

        % Value changed function: Joint1MaxPulseEditField, 
        % Joint1MinPulseEditField, Joint2MaxPulseEditField, 
        % Joint2MinPulseEditField, Joint3MaxPulseEditField, 
        % Joint3MinPulseEditField
        function ChangePulseDuration(app, event)
            if event.Source.Tag == "SERVO1MINPULSE"
                app.data{strcmp("Servo1MinPulse",app.data),2} = app.Joint1MinPulseEditField.Value;
            elseif event.Source.Tag == "SERVO2MINPULSE"
                app.data{strcmp("Servo2MinPulse",app.data),2} = app.Joint2MinPulseEditField.Value;
            elseif event.Source.Tag == "SERVO3MINPULSE"
                app.data{strcmp("Servo3MinPulse",app.data),2} = app.Joint3MinPulseEditField.Value;
            elseif event.Source.Tag == "SERVO1MAXPULSE"
                app.data{strcmp("Servo1MaxPulse",app.data),2} = app.Joint1MinPulseEditField.Value;
            elseif event.Source.Tag == "SERVO2MAXPULSE"
                app.data{strcmp("Servo2MaxPulse",app.data),2} = app.Joint2MaxPulseEditField.Value;
            elseif event.Source.Tag == "SERVO3MAXPULSE"
                app.data{strcmp("Servo3MaxPulse",app.data),2} = app.Joint3MaxPulseEditField.Value;
            else
            end
        end

        % Value changed function: a1EditField, a2EditField, 
        % a3EditField, alpha1EditField, alpha2EditField, 
        % alpha3EditField, d1EditField, d2EditField, d3EditField, 
        % offset1EditField, offset2EditField, offset3EditField, 
        % xEditField, yEditField, zEditField
        function ChangeDHPara(app, event)
            if event.Source.Tag == "DH_A1"
                app.data{strcmp("DH_a1",app.data),2} = app.a1EditField.Value
            elseif event.Source.Tag == "DH_A2"
                app.data{strcmp("DH_a2",app.data),2} = app.a2EditField.Value;
            elseif event.Source.Tag == "DH_A3"
                app.data{strcmp("DH_a3",app.data),2} = app.a3EditField.Value;
            elseif event.Source.Tag == "DH_ALPHA1"
                app.data{strcmp("DH_alpha1",app.data),2} = app.alpha1EditField.Value;
            elseif event.Source.Tag == "DH_ALPHA2"
                app.data{strcmp("DH_alpha2",app.data),2} = app.alpha2EditField.Value;
            elseif event.Source.Tag == "DH_ALPHA3"
                app.data{strcmp("DH_alpha3",app.data),2} = app.alpha3EditField.Value;
            elseif event.Source.Tag == "DH_D1"
                app.data{strcmp("DH_d1",app.data),2} = app.d1EditField.Value;
            elseif event.Source.Tag == "DH_D2"
                app.data{strcmp("DH_d2",app.data),2} = app.d2EditField.Value;
            elseif event.Source.Tag == "DH_D3"
                app.data{strcmp("DH_d3",app.data),2} = app.d3EditField.Value;
            elseif event.Source.Tag == "DH_OFFSET1"
                app.data{strcmp("DH_offset1",app.data),2} = app.offset1EditField.Value;
            elseif event.Source.Tag == "DH_OFFSET2"
                app.data{strcmp("DH_offset2",app.data),2} = app.offset2EditField.Value;
            elseif event.Source.Tag == "DH_OFFSET3"
                app.data{strcmp("DH_offset3",app.data),2} = app.offset3EditField.Value;
            elseif event.Source.Tag == "DH_BASEX"
                app.data{strcmp("DH_baseX",app.data),2} = app.xEditField.Value;
            elseif event.Source.Tag == "DH_BASEY"
                app.data{strcmp("DH_baseY",app.data),2} = app.yEditField.Value;
            elseif event.Source.Tag == "DH_BASEZ"
                app.data{strcmp("DH_baseZ",app.data),2} = app.zEditField.Value;
            else
                
            end
            
        end

        % Value changed function: Center1XEditField, 
        % Center1YEditField, Center2XEditField, Center2YEditField, 
        % Center3XEditField, Center3YEditField, Center4XEditField, 
        % Center4YEditField, Center5XEditField, Center5YEditField, 
        % Center6XEditField, Center6YEditField, Center7XEditField, 
        % Center7YEditField, Center8XEditField, Center8YEditField, 
        % Center9XEditField, Center9YEditField, CenterREditField, 
        % HDLEndXEditField, HDLEndYEditField, HDLStartXEditField, 
        % HDLStartYEditField, HULEndXEditField, HULEndYEditField, 
        % HULStartXEditField, HULStartYEditField, VLLEndXEditField, 
        % VLLEndYEditField, VLLStartXEditField, VLLStartYEditField, 
        % VRLEndXEditField, VRLEndYEditField, VRLStartXEditField, 
        % VRLStartYEditField
        function ChangeGamePara(app, event)
            if event.Source.Tag == "VLL_START_X"
                app.data{strcmp("Grid_VLS_X",app.data),2} = app.VLLStartXEditField.Value;
            elseif event.Source.Tag == "VLL_START_Y"
                app.data{strcmp("Grid_VLS_Y",app.data),2} = app.VLLStartYEditField.Value;
            elseif event.Source.Tag == "VLL_END_X"
                app.data{strcmp("Grid_VLE_X",app.data),2} = app.VLLEndXEditField.Value;
            elseif event.Source.Tag == "VLL_END_Y"
                app.data{strcmp("Grid_VLE_Y",app.data),2} = app.VLLEndYEditField.Value;
            elseif event.Source.Tag == "VRL_START_X"
                app.data{strcmp("Grid_VRS_X",app.data),2} = app.VRLStartXEditField.Value;
            elseif event.Source.Tag == "VRL_START_Y"
                app.data{strcmp("Grid_VRS_Y",app.data),2} = app.VRLStartYEditField.Value;
            elseif event.Source.Tag == "VRL_END_X"
                app.data{strcmp("Grid_VRE_X",app.data),2} = app.VRLEndXEditField.Value;
            elseif event.Source.Tag == "VRL_END_Y"
                app.data{strcmp("Grid_VRE_Y",app.data),2} = app.VRLEndYEditField.Value;
            elseif event.Source.Tag == "HUL_START_X"
                app.data{strcmp("Grid_HUS_X",app.data),2} = app.HULStartXEditField.Value;
            elseif event.Source.Tag == "HUL_START_Y"
                app.data{strcmp("Grid_HUS_Y",app.data),2} = app.HULStartYEditField.Value;
            elseif event.Source.Tag == "HUL_END_X"
                app.data{strcmp("Grid_HUE_X",app.data),2} = app.HULEndXEditField.Value;
            elseif event.Source.Tag == "HUL_END_Y"
                app.data{strcmp("Grid_HUE_Y",app.data),2} = app.HULEndYEditField.Value;
            elseif event.Source.Tag == "HDL_START_X"
                app.data{strcmp("Grid_HDS_X",app.data),2} = app.HDLStartXEditField.Value;
            elseif event.Source.Tag == "HDL_START_Y"
                app.data{strcmp("Grid_HDS_Y",app.data),2} = app.HDLStartYEditField.Value;
            elseif event.Source.Tag == "HDL_END_X"
                app.data{strcmp("Grid_HDE_X",app.data),2} = app.HDLEndXEditField.Value;
            elseif event.Source.Tag == "HDL_END_Y"
                app.data{strcmp("Grid_HDE_Y",app.data),2} = app.HDLEndYEditField.Value;
            elseif event.Source.Tag == "CENTER1_X"
                app.data{strcmp("Center1_X",app.data),2} = app.Center1XEditField.Value;
            elseif event.Source.Tag == "CENTER1_Y"
                app.data{strcmp("Center1_Y",app.data),2} = app.Center1YEditField.Value;
            elseif event.Source.Tag == "CENTER2_X"
                app.data{strcmp("Center2_X",app.data),2} = app.Center2XEditField.Value;
            elseif event.Source.Tag == "CENTER2_Y"
                app.data{strcmp("Center2_Y",app.data),2} = app.Center2YEditField.Value;
            elseif event.Source.Tag == "CENTER3_X"
                app.data{strcmp("Center3_X",app.data),2} = app.Center3XEditField.Value;
            elseif event.Source.Tag == "CENTER3_Y"
                app.data{strcmp("Center3_Y",app.data),2} = app.Center3YEditField.Value;
            elseif event.Source.Tag == "CENTER4_X"
                app.data{strcmp("Center4_X",app.data),2} = app.Center4XEditField.Value;
            elseif event.Source.Tag == "CENTER4_Y"
                app.data{strcmp("Center4_Y",app.data),2} = app.Center4YEditField.Value;
            elseif event.Source.Tag == "CENTER5_X"
                app.data{strcmp("Center5_X",app.data),2} = app.Center5XEditField.Value;
            elseif event.Source.Tag == "CENTER5_Y"
                app.data{strcmp("Center5_Y",app.data),2} = app.Center5YEditField.Value;
            elseif event.Source.Tag == "CENTER6_X"
                app.data{strcmp("Center6_X",app.data),2} = app.Center6XEditField.Value;
            elseif event.Source.Tag == "CENTER6_Y"
                app.data{strcmp("Center6_Y",app.data),2} = app.Center6YEditField.Value;
            elseif event.Source.Tag == "CENTER7_X"
                app.data{strcmp("Center7_X",app.data),2} = app.Center7XEditField.Value;
            elseif event.Source.Tag == "CENTER7_Y"
                app.data{strcmp("Center7_Y",app.data),2} = app.Center7YEditField.Value;
            elseif event.Source.Tag == "CENTER8_X"
                app.data{strcmp("Center8_X",app.data),2} = app.Center8XEditField.Value;
            elseif event.Source.Tag == "CENTER8_Y"
                app.data{strcmp("Center8_Y",app.data),2} = app.Center8YEditField.Value;
            elseif event.Source.Tag == "CENTER9_X"
                app.data{strcmp("Center9_X",app.data),2} = app.Center9XEditField.Value;
            elseif event.Source.Tag == "CENTER9_Y"
                app.data{strcmp("Center9_Y",app.data),2} = app.Center9YEditField.Value;
            elseif event.Source.Tag == "CENTER_R"
                app.data{strcmp("Radius",app.data),2} = app.CenterREditField.Value;
            end
        end

        % Value changed function: DrawmovetrajectoryCheckBox
        function ChangeDrawMovTraj(app, event)
            value = app.DrawmovetrajectoryCheckBox.Value;
            app.data{strcmp("isDrawMoveTraj",app.data),2} = value;
        end

        % Value changed function: GenerateTrajectoryfileCheckBox
        function ChangeGenTrajFile(app, event)
            value = app.GenerateTrajectoryfileCheckBox.Value;
            app.data{strcmp("isRecordTraj",app.data),2} = value;
        end

        % Value changed function: UseTrajectoryfiletodrawCheckBox
        function ChangeUseTrajFile(app, event)
            value = app.UseTrajectoryfiletodrawCheckBox.Value;
            app.data{strcmp("isUseFileTraj",app.data),2} = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ConfigurationUIFigure = uifigure('Visible', 'off');
            app.ConfigurationUIFigure.Name = 'Configuration';
            app.ConfigurationUIFigure.CloseRequestFcn = createCallbackFcn(app, @ConfigurationUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.ConfigurationUIFigure);
            app.HardwareTab = uitab(app.TabGroup);
            app.HardwareTab.Title = 'Hardware';
            app.ArduinoUSBPortDropDownLabel = uilabel(app.HardwareTab);
            app.ArduinoUSBPortDropDownLabel.Text = 'Arduino USB Port';
            app.ArduinoUSBPortDropDown = uidropdown(app.HardwareTab);
            app.Joint1MinPulseEditField = uieditfield(app.HardwareTab, 'numeric');
            app.Joint1MinPulseEditField.ValueChangedFcn = createCallbackFcn(app, @ChangePulseDuration, true);
            app.Joint1MinPulseEditField.Tag = 'SERVO1MINPULSE';
            app.PinLabel = uilabel(app.HardwareTab);
            app.PinLabel.Text = 'Pin';
            app.MinPulseDurationusLabel = uilabel(app.HardwareTab);
            app.MinPulseDurationusLabel.Text = 'MinPulseDuration(us)';
            app.MaxPulseDurationusLabel = uilabel(app.HardwareTab);
            app.MaxPulseDurationusLabel.Text = 'MaxPulseDuration(us)';
            app.Joint1MaxPulseEditField = uieditfield(app.HardwareTab, 'numeric');
            app.Joint1MaxPulseEditField.ValueChangedFcn = createCallbackFcn(app, @ChangePulseDuration, true);
            app.Joint1MaxPulseEditField.Tag = 'SERVO1MAXPULSE';
            app.Joint2MinPulseEditField = uieditfield(app.HardwareTab, 'numeric');
            app.Joint2MinPulseEditField.ValueChangedFcn = createCallbackFcn(app, @ChangePulseDuration, true);
            app.Joint2MinPulseEditField.Tag = 'SERVO2MINPULSE';
            app.Joint3MinPulseEditField = uieditfield(app.HardwareTab, 'numeric');
            app.Joint3MinPulseEditField.ValueChangedFcn = createCallbackFcn(app, @ChangePulseDuration, true);
            app.Joint3MinPulseEditField.Tag = 'SERVO3MINPULSE';
            app.Joint2MaxPulseEditField = uieditfield(app.HardwareTab, 'numeric');
            app.Joint2MaxPulseEditField.ValueChangedFcn = createCallbackFcn(app, @ChangePulseDuration, true);
            app.Joint2MaxPulseEditField.Tag = 'SERVO2MAXPULSE';
            app.Joint3MaxPulseEditField = uieditfield(app.HardwareTab, 'numeric');
            app.Joint3MaxPulseEditField.ValueChangedFcn = createCallbackFcn(app, @ChangePulseDuration, true);
            app.Joint3MaxPulseEditField.Tag = 'SERVO3MAXPULSE';
            app.Joint1ServoDropDownLabel = uilabel(app.HardwareTab);
            app.Joint1ServoDropDownLabel.Text = 'Joint1 Servo';
            app.Joint1ServoDropDown = uidropdown(app.HardwareTab);
            app.Joint1ServoDropDown.Items = {'D3', 'D5', 'D6', 'D9', 'D10', 'D11', ''};
            app.Joint1ServoDropDown.Value = 'D3';
            app.Joint2ServoDropDownLabel = uilabel(app.HardwareTab);
            app.Joint2ServoDropDownLabel.Text = 'Joint2 Servo';
            app.Joint2ServoDropDown = uidropdown(app.HardwareTab);
            app.Joint2ServoDropDown.Items = {'D3', 'D5', 'D6', 'D9', 'D10', 'D11'};
            app.Joint2ServoDropDown.Value = 'D5';
            app.Joint3ServoDropDownLabel = uilabel(app.HardwareTab);
            app.Joint3ServoDropDownLabel.Text = 'Joint3 Servo';
            app.Joint3ServoDropDown = uidropdown(app.HardwareTab);
            app.Joint3ServoDropDown.Items = {'D3', 'D5', 'D6', 'D9', 'D10', 'D11'};
            app.Joint3ServoDropDown.Value = 'D6';
            app.DHParametersTab = uitab(app.TabGroup);
            app.DHParametersTab.Title = 'DH Parameters';
            app.BaseFrameTranslationLabel = uilabel(app.DHParametersTab);
            app.BaseFrameTranslationLabel.Text = 'Base Frame Translation';
            app.a1EditFieldLabel = uilabel(app.DHParametersTab);
            app.a1EditFieldLabel.Text = 'a1';
            app.a1EditField = uieditfield(app.DHParametersTab, 'numeric');
            app.a1EditField.ValueChangedFcn = createCallbackFcn(app, @ChangeDHPara, true);
            app.a1EditField.Tag = 'DH_A1';
            app.a2EditFieldLabel = uilabel(app.DHParametersTab);
            app.a2EditFieldLabel.Text = 'a2';
            app.a2EditField = uieditfield(app.DHParametersTab, 'numeric');
            app.a2EditField.ValueChangedFcn = createCallbackFcn(app, @ChangeDHPara, true);
            app.a2EditField.Tag = 'DH_A2';
            app.a3EditFieldLabel = uilabel(app.DHParametersTab);
            app.a3EditFieldLabel.Text = 'a3';
            app.a3EditField = uieditfield(app.DHParametersTab, 'numeric');
            app.a3EditField.ValueChangedFcn = createCallbackFcn(app, @ChangeDHPara, true);
            app.a3EditField.Tag = 'DH_A3';
            app.alpha1EditFieldLabel = uilabel(app.DHParametersTab);
            app.alpha1EditFieldLabel.Text = 'alpha1';
            app.alpha1EditField = uieditfield(app.DHParametersTab, 'numeric');
            app.alpha1EditField.ValueChangedFcn = createCallbackFcn(app, @ChangeDHPara, true);
            app.alpha1EditField.Tag = 'DH_ALPHA1';
            app.alpha2EditFieldLabel = uilabel(app.DHParametersTab);
            app.alpha2EditFieldLabel.Text = 'alpha2';
            app.alpha2EditField = uieditfield(app.DHParametersTab, 'numeric');
            app.alpha2EditField.ValueChangedFcn = createCallbackFcn(app, @ChangeDHPara, true);
            app.alpha2EditField.Tag = 'DH_ALPHA2';
            app.alpha3EditFieldLabel = uilabel(app.DHParametersTab);
            app.alpha3EditFieldLabel.Text = 'alpha3';
            app.alpha3EditField = uieditfield(app.DHParametersTab, 'numeric');
            app.alpha3EditField.ValueChangedFcn = createCallbackFcn(app, @ChangeDHPara, true);
            app.alpha3EditField.Tag = 'DH_ALPHA3';
            app.d1EditFieldLabel = uilabel(app.DHParametersTab);
            app.d1EditFieldLabel.Text = 'd1';
            app.d1EditField = uieditfield(app.DHParametersTab, 'numeric');
            app.d1EditField.ValueChangedFcn = createCallbackFcn(app, @ChangeDHPara, true);
            app.d1EditField.Tag = 'DH_D1';
            app.d2EditFieldLabel = uilabel(app.DHParametersTab);
            app.d2EditFieldLabel.Text = 'd2';
            app.d2EditField = uieditfield(app.DHParametersTab, 'numeric');
            app.d2EditField.ValueChangedFcn = createCallbackFcn(app, @ChangeDHPara, true);
            app.d2EditField.Tag = 'DH_D2';
            app.d3EditFieldLabel = uilabel(app.DHParametersTab);
            app.d3EditFieldLabel.Text = 'd3';
            app.d3EditField = uieditfield(app.DHParametersTab, 'numeric');
            app.d3EditField.ValueChangedFcn = createCallbackFcn(app, @ChangeDHPara, true);
            app.d3EditField.Tag = 'DH_D3';
            app.offset3EditFieldLabel = uilabel(app.DHParametersTab);
            app.offset3EditFieldLabel.Text = 'offset3';
            app.offset3EditField = uieditfield(app.DHParametersTab, 'numeric');
            app.offset3EditField.ValueChangedFcn = createCallbackFcn(app, @ChangeDHPara, true);
            app.offset3EditField.Tag = 'DH_OFFSET3';
            app.offset2EditFieldLabel = uilabel(app.DHParametersTab);
            app.offset2EditFieldLabel.Text = 'offset2';
            app.offset2EditField = uieditfield(app.DHParametersTab, 'numeric');
            app.offset2EditField.ValueChangedFcn = createCallbackFcn(app, @ChangeDHPara, true);
            app.offset2EditField.Tag = 'DH_OFFSET2';
            app.offset1EditFieldLabel = uilabel(app.DHParametersTab);
            app.offset1EditFieldLabel.Text = 'offset1';
            app.offset1EditField = uieditfield(app.DHParametersTab, 'numeric');
            app.offset1EditField.ValueChangedFcn = createCallbackFcn(app, @ChangeDHPara, true);
            app.offset1EditField.Tag = 'DH_OFFSET1';
            app.xEditFieldLabel = uilabel(app.DHParametersTab);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.DHParametersTab, 'numeric');
            app.xEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeDHPara, true);
            app.xEditField.Tag = 'DH_BASEX';
            app.yEditFieldLabel = uilabel(app.DHParametersTab);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.DHParametersTab, 'numeric');
            app.yEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeDHPara, true);
            app.yEditField.Tag = 'DH_BASEY';
            app.zEditFieldLabel = uilabel(app.DHParametersTab);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.DHParametersTab, 'numeric');
            app.zEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeDHPara, true);
            app.zEditField.Tag = 'DH_BASEZ';
            app.GameTab = uitab(app.TabGroup);
            app.GameTab.Title = 'Game';
            app.VLLStartXEditField = uieditfield(app.GameTab, 'numeric');
            app.VLLStartXEditField.ValueDisplayFormat = '%.3f';
            app.VLLStartXEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeGamePara, true);
            app.VLLStartXEditField.Tag = 'VLL_START_X';
            app.VLLStartYEditField = uieditfield(app.GameTab, 'numeric');
            app.VLLStartYEditField.ValueDisplayFormat = '%.3f';
            app.VLLStartYEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeGamePara, true);
            app.VLLStartYEditField.Tag = 'VLL_START_Y';
            app.VRLStartXEditField = uieditfield(app.GameTab, 'numeric');
            app.VRLStartXEditField.ValueDisplayFormat = '%.3f';
            app.VRLStartXEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeGamePara, true);
            app.VRLStartXEditField.Tag = 'VRL_START_X';
            app.VRLStartYEditField = uieditfield(app.GameTab, 'numeric');
            app.VRLStartYEditField.ValueDisplayFormat = '%.3f';
            app.VRLStartYEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeGamePara, true);
            app.VRLStartYEditField.Tag = 'VRL_START_Y';
            app.HULStartXEditField = uieditfield(app.GameTab, 'numeric');
            app.HULStartXEditField.ValueDisplayFormat = '%.3f';
            app.HULStartXEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeGamePara, true);
            app.HULStartXEditField.Tag = 'HUL_START_X';
            app.HULStartYEditField = uieditfield(app.GameTab, 'numeric');
            app.HULStartYEditField.ValueDisplayFormat = '%.3f';
            app.HULStartYEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeGamePara, true);
            app.HULStartYEditField.Tag = 'HUL_START_Y';
            app.HDLStartXEditField = uieditfield(app.GameTab, 'numeric');
            app.HDLStartXEditField.ValueDisplayFormat = '%.3f';
            app.HDLStartXEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeGamePara, true);
            app.HDLStartXEditField.Tag = 'HDL_START_X';
            app.HDLStartYEditField = uieditfield(app.GameTab, 'numeric');
            app.HDLStartYEditField.ValueDisplayFormat = '%.3f';
            app.HDLStartYEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeGamePara, true);
            app.HDLStartYEditField.Tag = 'HDL_START_Y';
            app.HDLEndYEditField = uieditfield(app.GameTab, 'numeric');
            app.HDLEndYEditField.ValueDisplayFormat = '%.3f';
            app.HDLEndYEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeGamePara, true);
            app.HDLEndYEditField.Tag = 'HDL_END_Y';
            app.HULEndYEditField = uieditfield(app.GameTab, 'numeric');
            app.HULEndYEditField.ValueDisplayFormat = '%.3f';
            app.HULEndYEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeGamePara, true);
            app.HULEndYEditField.Tag = 'HUL_END_Y';
            app.VRLEndYEditField = uieditfield(app.GameTab, 'numeric');
            app.VRLEndYEditField.ValueDisplayFormat = '%.3f';
            app.VRLEndYEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeGamePara, true);
            app.VRLEndYEditField.Tag = 'VRL_END_Y';
            app.HDLEndXEditField = uieditfield(app.GameTab, 'numeric');
            app.HDLEndXEditField.ValueDisplayFormat = '%.3f';
            app.HDLEndXEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeGamePara, true);
            app.HDLEndXEditField.Tag = 'HDL_END_X';
            app.HULEndXEditField = uieditfield(app.GameTab, 'numeric');
            app.HULEndXEditField.ValueDisplayFormat = '%.3f';
            app.HULEndXEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeGamePara, true);
            app.HULEndXEditField.Tag = 'HUL_END_X';
            app.VLLEndYEditField = uieditfield(app.GameTab, 'numeric');
            app.VLLEndYEditField.ValueDisplayFormat = '%.3f';
            app.VLLEndYEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeGamePara, true);
            app.VLLEndYEditField.Tag = 'VLL_END_Y';
            app.VRLEndXEditField = uieditfield(app.GameTab, 'numeric');
            app.VRLEndXEditField.ValueDisplayFormat = '%.3f';
            app.VRLEndXEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeGamePara, true);
            app.VRLEndXEditField.Tag = 'VRL_END_X';
            app.VLLEndXEditField = uieditfield(app.GameTab, 'numeric');
            app.VLLEndXEditField.ValueDisplayFormat = '%.3f';
            app.VLLEndXEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeGamePara, true);
            app.VLLEndXEditField.Tag = 'VLL_END_X';
            app.StartXLabel = uilabel(app.GameTab);
            app.StartXLabel.Text = 'StartX';
            app.StartYLabel = uilabel(app.GameTab);
            app.StartYLabel.Text = 'StartY';
            app.EndYLabel = uilabel(app.GameTab);
            app.EndYLabel.Text = 'EndY';
            app.EndXLabel = uilabel(app.GameTab);
            app.EndXLabel.Text = 'EndX';
            app.Center1XEditField = uieditfield(app.GameTab, 'numeric');
            app.Center1XEditField.ValueDisplayFormat = '%.3f';
            app.Center1XEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeGamePara, true);
            app.Center1XEditField.Tag = 'CENTER1_X';
            app.Center1YEditField = uieditfield(app.GameTab, 'numeric');
            app.Center1YEditField.ValueDisplayFormat = '%.3f';
            app.Center1YEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeGamePara, true);
            app.Center1YEditField.Tag = 'CENTER1_Y';
            app.Center2XEditField = uieditfield(app.GameTab, 'numeric');
            app.Center2XEditField.ValueDisplayFormat = '%.3f';
            app.Center2XEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeGamePara, true);
            app.Center2XEditField.Tag = 'CENTER2_X';
            app.Center2YEditField = uieditfield(app.GameTab, 'numeric');
            app.Center2YEditField.ValueDisplayFormat = '%.3f';
            app.Center2YEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeGamePara, true);
            app.Center2YEditField.Tag = 'CENTER2_Y';
            app.Center3XEditField = uieditfield(app.GameTab, 'numeric');
            app.Center3XEditField.ValueDisplayFormat = '%.3f';
            app.Center3XEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeGamePara, true);
            app.Center3XEditField.Tag = 'CENTER3_X';
            app.Center3YEditField = uieditfield(app.GameTab, 'numeric');
            app.Center3YEditField.ValueDisplayFormat = '%.3f';
            app.Center3YEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeGamePara, true);
            app.Center3YEditField.Tag = 'CENTER3_Y';
            app.Center4XEditField = uieditfield(app.GameTab, 'numeric');
            app.Center4XEditField.ValueDisplayFormat = '%.3f';
            app.Center4XEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeGamePara, true);
            app.Center4XEditField.Tag = 'CENTER4_X';
            app.Center4YEditField = uieditfield(app.GameTab, 'numeric');
            app.Center4YEditField.ValueDisplayFormat = '%.3f';
            app.Center4YEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeGamePara, true);
            app.Center4YEditField.Tag = 'CENTER4_Y';
            app.Center5XEditField = uieditfield(app.GameTab, 'numeric');
            app.Center5XEditField.ValueDisplayFormat = '%.3f';
            app.Center5XEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeGamePara, true);
            app.Center5XEditField.Tag = 'CENTER5_X';
            app.Center5YEditField = uieditfield(app.GameTab, 'numeric');
            app.Center5YEditField.ValueDisplayFormat = '%.3f';
            app.Center5YEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeGamePara, true);
            app.Center5YEditField.Tag = 'CENTER5_Y';
            app.Center6XEditField = uieditfield(app.GameTab, 'numeric');
            app.Center6XEditField.ValueDisplayFormat = '%.3f';
            app.Center6XEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeGamePara, true);
            app.Center6XEditField.Tag = 'CENTER6_X';
            app.Center6YEditField = uieditfield(app.GameTab, 'numeric');
            app.Center6YEditField.ValueDisplayFormat = '%.3f';
            app.Center6YEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeGamePara, true);
            app.Center6YEditField.Tag = 'CENTER6_Y';
            app.Center7XEditField = uieditfield(app.GameTab, 'numeric');
            app.Center7XEditField.ValueDisplayFormat = '%.3f';
            app.Center7XEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeGamePara, true);
            app.Center7XEditField.Tag = 'CENTER7_X';
            app.Center7YEditField = uieditfield(app.GameTab, 'numeric');
            app.Center7YEditField.ValueDisplayFormat = '%.3f';
            app.Center7YEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeGamePara, true);
            app.Center7YEditField.Tag = 'CENTER7_Y';
            app.Center8XEditField = uieditfield(app.GameTab, 'numeric');
            app.Center8XEditField.ValueDisplayFormat = '%.3f';
            app.Center8XEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeGamePara, true);
            app.Center8XEditField.Tag = 'CENTER8_X';
            app.Center8YEditField = uieditfield(app.GameTab, 'numeric');
            app.Center8YEditField.ValueDisplayFormat = '%.3f';
            app.Center8YEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeGamePara, true);
            app.Center8YEditField.Tag = 'CENTER8_Y';
            app.XLabel = uilabel(app.GameTab);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.GameTab);
            app.YLabel.Text = 'Y';
            app.VerticalLeftLineLabel = uilabel(app.GameTab);
            app.VerticalLeftLineLabel.Text = 'Vertical Left Line';
            app.VerticalRightLineLabel = uilabel(app.GameTab);
            app.VerticalRightLineLabel.Text = 'Vertical Right Line';
            app.HorizontalUpLineLabel = uilabel(app.GameTab);
            app.HorizontalUpLineLabel.Text = 'Horizontal Up Line';
            app.HorizontalDownLineLabel = uilabel(app.GameTab);
            app.HorizontalDownLineLabel.Text = 'Horizontal Down Line';
            app.Center1Label = uilabel(app.GameTab);
            app.Center1Label.Text = 'Center1';
            app.Center2Label = uilabel(app.GameTab);
            app.Center2Label.Text = 'Center2';
            app.Center3Label = uilabel(app.GameTab);
            app.Center3Label.Text = 'Center3';
            app.Center4Label = uilabel(app.GameTab);
            app.Center4Label.Text = 'Center4';
            app.Center5Label = uilabel(app.GameTab);
            app.Center5Label.Text = 'Center5';
            app.Center6Label = uilabel(app.GameTab);
            app.Center6Label.Text = 'Center6';
            app.Center7Label = uilabel(app.GameTab);
            app.Center7Label.Text = 'Center7';
            app.Center8Label = uilabel(app.GameTab);
            app.Center8Label.Text = 'Center8';
            app.Center9XEditField = uieditfield(app.GameTab, 'numeric');
            app.Center9XEditField.ValueDisplayFormat = '%.3f';
            app.Center9XEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeGamePara, true);
            app.Center9XEditField.Tag = 'CENTER9_X';
            app.Center9YEditField = uieditfield(app.GameTab, 'numeric');
            app.Center9YEditField.ValueDisplayFormat = '%.3f';
            app.Center9YEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeGamePara, true);
            app.Center9YEditField.Tag = 'CENTER9_Y';
            app.Center9Label = uilabel(app.GameTab);
            app.Center9Label.Text = 'Center9';
            app.CenterRLabel = uilabel(app.GameTab);
            app.CenterRLabel.Text = 'CenterR';
            app.CenterREditField = uieditfield(app.GameTab, 'numeric');
            app.CenterREditField.ValueDisplayFormat = '%.3f';
            app.CenterREditField.ValueChangedFcn = createCallbackFcn(app, @ChangeGamePara, true);
            app.CenterREditField.Tag = 'CENTER_R';
            app.XLabel_2 = uilabel(app.GameTab);
            app.XLabel_2.Text = 'X';
            app.YLabel_2 = uilabel(app.GameTab);
            app.YLabel_2.Text = 'Y';
            app.DrawmovetrajectoryCheckBox = uicheckbox(app.GameTab);
            app.DrawmovetrajectoryCheckBox.ValueChangedFcn = createCallbackFcn(app, @ChangeDrawMovTraj, true);
            app.DrawmovetrajectoryCheckBox.Text = 'Draw move trajectory';
            app.GenerateTrajectoryfileCheckBox = uicheckbox(app.GameTab);
            app.GenerateTrajectoryfileCheckBox.ValueChangedFcn = createCallbackFcn(app, @ChangeGenTrajFile, true);
            app.GenerateTrajectoryfileCheckBox.Text = 'Generate Trajectory file';
            app.UseTrajectoryfiletodrawCheckBox = uicheckbox(app.GameTab);
            app.UseTrajectoryfiletodrawCheckBox.ValueChangedFcn = createCallbackFcn(app, @ChangeUseTrajFile, true);
            app.UseTrajectoryfiletodrawCheckBox.Text = 'Use Trajectory file to draw';
            app.UseTrajectoryfiletodrawCheckBox.Value = true;
            app.InfoTab = uitab(app.TabGroup);
            app.InfoTab.Title = 'Info';
            app.Image = uiimage(app.InfoTab);
            app.Image.ImageSource = 'GameBoardConfig.png';
            app.OKButton = uibutton(app.ConfigurationUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @Confirm, true);
            app.OKButton.Text = 'OK';
            app.CancelButton = uibutton(app.ConfigurationUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @Cancel, true);
            app.CancelButton.Text = 'Cancel';
            % Show the figure after all components are created
            app.ConfigurationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ConfigurationDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ConfigurationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ConfigurationUIFigure)
        end
    end
end