classdef TestRobotDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        XEditFieldLabel         matlab.ui.control.Label
        XEditField              matlab.ui.control.NumericEditField
        YEditFieldLabel         matlab.ui.control.Label
        YEditField              matlab.ui.control.NumericEditField
        ZEditFieldLabel         matlab.ui.control.Label
        ZEditField              matlab.ui.control.NumericEditField
        DistanceEditFieldLabel  matlab.ui.control.Label
        DistanceEditField       matlab.ui.control.NumericEditField
        CreateRobotButton       matlab.ui.control.Button
        ConnectButton           matlab.ui.control.Button
        TabGroup                matlab.ui.container.TabGroup
        BasicTab                matlab.ui.container.Tab
        PoseDropDownLabel       matlab.ui.control.Label
        PoseDropDown            matlab.ui.control.DropDown
        Joint1SliderLabel       matlab.ui.control.Label
        Joint1Slider            matlab.ui.control.Slider
        Joint2SliderLabel       matlab.ui.control.Label
        Joint2Slider            matlab.ui.control.Slider
        Joint3SliderLabel       matlab.ui.control.Label
        Joint3Slider            matlab.ui.control.Slider
        Joint1EditField         matlab.ui.control.NumericEditField
        Joint2EditField         matlab.ui.control.NumericEditField
        Joint3EditField         matlab.ui.control.NumericEditField
        Servo1EditFieldLabel    matlab.ui.control.Label
        Servo1EditField         matlab.ui.control.NumericEditField
        Servo2EditFieldLabel    matlab.ui.control.Label
        Servo2EditField         matlab.ui.control.NumericEditField
        Servo3EditFieldLabel    matlab.ui.control.Label
        Servo3EditField         matlab.ui.control.NumericEditField
        GameTab                 matlab.ui.container.Tab
        RangeTestButton         matlab.ui.control.Button
        DrawLineButton          matlab.ui.control.Button
        DrawOButton             matlab.ui.control.Button
        DrawXButton             matlab.ui.control.Button
        DrawGridButton          matlab.ui.control.Button
        LineDropDown            matlab.ui.control.DropDown
        ODropDown               matlab.ui.control.DropDown
        XDropDown               matlab.ui.control.DropDown
        DisconnectButton        matlab.ui.control.Button
    end

    
    properties (Access = private)
        mainApp % main window app object
        game TicTacToe
        data
        DhPara
        qlim
        robot
        arduino_hardware = [];
        JointServo1 = [];
        JointServo2 = [];
        JointServo3 = [];
        JointServoPin
        ComPort        %Arduino COM port
        % some useful poses
        Pose = [0 0 0;          %Pose1
                0 90 0;       %Pose2
                0 -90 0;      %Pose3
                90 0 0;       %Pose4
                90 90 0;    %Pose5
                90 -90 0;   %Pose6
                -90 0 0;      %Pose7
                -90 90 0;    %Pose8
                -90 -90 0];  %Pose9
    end
    
    methods (Access = private)
        function updateRobot(app,q)
            if isempty(app.robot) == false
                %Re-plot robot for new pose
                app.robot.plot(q)
                %Recalculate the Transformation matrix after joint angle
                %changed
                T = app.robot.fkine(q);
                %Update the EE position on GUI
                app.XEditField.Value = T.t(1);
                app.YEditField.Value = T.t(2);
                app.ZEditField.Value = T.t(3);
                %Calculate the distance of EE and update on GUI
                app.DistanceEditField.Value = sqrt(T.t(1)^2 + T.t(2)^2 + T.t(3)^2);
                
                %Control motor
                controlMotor(app,q);
            else
                msgbox("Robot not created yet")
            end
        end
        
        function controlMotor(app,q)
            %control motor to requested angle
            % q: requested angle vector
            if isempty(app.JointServo1) == false
                %clip data between joint limits
                angle1 = app.clip(rad2deg(q(1)),app.qlim(1,1),app.qlim(1,2));

                %change degree to servo input range(0,1)
                angle1 = (app.qlim(1,2) - angle1)/(app.qlim(1,2) - app.qlim(1,1));
                %clip the data between 0 and 1
                angle1 = app.clip(angle1,0,1);
                %control servo to right angle
                writePosition(app.JointServo1, angle1);
                app.Servo1EditField.Value = readPosition(app.JointServo1);
            end

            if isempty(app.JointServo2) == false
                %clip data between joint limits
                angle2 = app.clip(rad2deg(q(2)),app.qlim(2,1),app.qlim(2,2));

                %change degree to servo input range(0,1)
                angle2 = (app.qlim(2,2) - angle2)/(app.qlim(2,2) - app.qlim(2,1));
                %clip the data between 0 and 1
                angle2 = app.clip(angle2,0,1);
                %control servo to right angle
                writePosition(app.JointServo2, angle2);
                app.Servo2EditField.Value = readPosition(app.JointServo2);
            end
            
%             if isempty(app.JointServo3) == false
%                 %clip data between joint limits
%                 angle3 = app.clip(rad2deg(q(3)),app.qlim(3,1),app.qlim(3,2));
% 
%                 %change degree to servo input range(0,1)
%                 angle3 = (app.qlim(3,2) - angle3)/(app.qlim(3,2) - app.qlim(3,1));
%                 %clip the data between 0 and 1
%                 angle3 = app.clip(angle3,0,1);
%                 %control servo to right angle
%                 writePosition(app.JointServo3, angle3);
%             end
            
        end
        
        function a = clip(app,x,low,high)
            a = min(max(x,low),high);
        end
        
        function disconnectHW(app)
            clear app.arduino_hardware
            clear app.robot
            clear app.JointServo1
            clear app.JointServo2
            clear app.JointServo3
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            % Store main app in property for CloseRequestFcn to use
            app.mainApp = mainApp;
            app.data = mainApp.data;
            app.DhPara = mainApp.DhPara;
            app.qlim = mainApp.qlim;
            if isempty(mainApp.G8Robot) == false
                app.robot = mainApp.G8Robot;
            end
            
            if isempty(mainApp.arduino_hardware) == false
                app.arduino_hardware = mainApp.arduino_hardware;
            end
            
            if isempty(mainApp.JointServo1) == false
                app.JointServo1 = mainApp.JointServo1;
            end
            if isempty(mainApp.JointServo2) == false
                app.JointServo2 = mainApp.JointServo2;
            end
            if isempty(mainApp.JointServo3) == false
                app.JointServo3 = mainApp.JointServo3;
            end
            
            if isempty(mainApp.JointServoPin) == false
                app.JointServoPin = mainApp.JointServoPin;
            end
            
            if isempty(mainApp.ComPort)  == false
                app.ComPort = mainApp.ComPort;
            end
            
            %Set initial joints angle according to pose selection
            poseInx = str2num(app.PoseDropDown.Value);
            app.Joint1Slider.Value = app.Pose(poseInx,1);
            app.Joint2Slider.Value = app.Pose(poseInx,2);
            app.Joint3Slider.Value = app.Pose(poseInx,3);
            app.Joint1EditField.Value = app.Joint1Slider.Value;
            app.Joint2EditField.Value = app.Joint2Slider.Value;
            app.Joint3EditField.Value = app.Joint3Slider.Value;
        end

        % Value changed function: PoseDropDown
        function ChangePose(app, event)
            %make sure robot is created already
            if isempty(app.robot) == false
                %update joints angle according to pose selection
                poseinx = str2num(app.PoseDropDown.Value);
                app.Joint1Slider.Value = app.Pose(poseinx,1);
                app.Joint2Slider.Value = app.Pose(poseinx,2);
                app.Joint3Slider.Value = app.Pose(poseinx,3);
                app.Joint1EditField.Value = app.Joint1Slider.Value;
                app.Joint2EditField.Value = app.Joint2Slider.Value;
                app.Joint3EditField.Value = app.Joint3Slider.Value;
                
                %plot the robot and update postion and distance info
                q= deg2rad(app.Pose(poseinx,:));
                app.updateRobot(q);
            else
                msgbox("Robot not created yet")
            end
            
        end

        % Value changed function: Joint1EditField, Joint1Slider
        function ChangeJoint1(app, event)
            if event.Source.Tag == "JOINT1SLIDER"
                app.Joint1EditField.Value = app.Joint1Slider.Value;
            elseif event.Source.Tag == "JOINT1EDITER"
                app.Joint1Slider.Value = app.Joint1EditField.Value;
            end
            %plot the robot and update postion and distance info
            q = deg2rad([app.Joint1Slider.Value app.Joint2Slider.Value app.Joint3Slider.Value]);
            app.updateRobot(q);
            
        end

        % Value changed function: Joint2EditField, Joint2Slider
        function ChangeJoint2(app, event)
            if event.Source.Tag == "JOINT2SLIDER"
                app.Joint2EditField.Value = app.Joint2Slider.Value;
            elseif event.Source.Tag == "JOINT2EDITER"
                app.Joint2Slider.Value = app.Joint2EditField.Value;
            end
            app.Joint2EditField.Value = app.Joint2Slider.Value;
            %plot the robot and update postion and distance info
            q = deg2rad([app.Joint1Slider.Value app.Joint2Slider.Value app.Joint3Slider.Value]);
            app.updateRobot(q);
            
        end

        % Value changed function: Joint3EditField, Joint3Slider
        function ChaengJoint3(app, event)
            if event.Source.Tag == "JOINT3SLIDER"
                app.Joint3EditField.Value = app.Joint3Slider.Value;
            elseif event.Source.Tag == "JOINT3EDITER"
                app.Joint3Slider.Value = app.Joint3EditField.Value;
            end
            app.Joint3EditField.Value = app.Joint3Slider.Value;
            %plot the robot and update postion and distance info
            q = deg2rad([app.Joint1Slider.Value app.Joint2Slider.Value app.Joint3Slider.Value]);
            app.updateRobot(q);
            
            if isempty(app.JointServo3) == false
                %clip data between joint limits
                angle3 = app.clip(rad2deg(q(3)),app.qlim(3,1),app.qlim(3,2));

                %change degree to servo input range(0,1)
                angle3 = (app.qlim(3,2) - angle3)/(app.qlim(3,2) - app.qlim(3,1));
                %clip the data between 0 and 1
                angle3 = app.clip(angle3,0,1);
                %control servo to right angle
                writePosition(app.JointServo3, angle3);
                app.Servo3EditField.Value = readPosition(app.JointServo3);
            end
            
        end

        % Value changed function: XEditField
        function ChangeX(app, event)
            value = app.XEditField.Value;
            
        end

        % Value changed function: YEditField
        function ChangeY(app, event)
            value = app.YEditField.Value;
            
        end

        % Button pushed function: ConnectButton
        function ConnectHW(app, event)
            if isempty(app.arduino_hardware)
                try
                    app.arduino_hardware = arduino(app.ComPort, 'Uno', 'Libraries', 'Servo');
                catch ME
                    warn_msg = ["Connect failure","Indentifier:",ME.identifier,"Message:",ME.message];
                    msgbox(warn_msg);
                    return;
                end
            end
            
            try
                if isempty(app.arduino_hardware) == false
                    if isempty(app.JointServo1)
                        
                        app.JointServo1 = servo(app.arduino_hardware, app.JointServoPin{1}, ...
                            'MinPulseDuration', app.data{strcmp("Servo1MinPulse",app.data),2}*10^(-6), ...
                            'MaxPulseDuration', app.data{strcmp("Servo1MaxPulse",app.data),2}*10^(-6));
                    end
                    if isempty(app.JointServo2)
                        
                        app.JointServo2 = servo(app.arduino_hardware, app.JointServoPin{2}, ...
                            'MinPulseDuration', app.data{strcmp("Servo2MinPulse",app.data),2}*10^(-6), ...
                            'MaxPulseDuration', app.data{strcmp("Servo2MaxPulse",app.data),2}*10^(-6));
                    end
                    if isempty(app.JointServo3)
                        
                        app.JointServo3 = servo(app.arduino_hardware, app.JointServoPin{3}, ...
                            'MinPulseDuration', app.data{strcmp("Servo3MinPulse",app.data),2}*10^(-6), ...
                            'MaxPulseDuration', app.data{strcmp("Servo3MaxPulse",app.data),2}*10^(-6));
                    end
                end
                
            catch ME
                warn_msg = ["Servo failure","Indentifier:",ME.identifier,"Message:",ME.message];
                msgbox(warn_msg);
                return;
            end
            %plot the robot and update postion and distance info
            q = deg2rad([app.Joint1Slider.Value app.Joint2Slider.Value app.Joint3Slider.Value]);
            app.updateRobot(q);
            msgbox('Arduino connected');
        end

        % Button pushed function: CreateRobotButton
        function CreateRobot(app, event)
            if isempty(app.robot)
                clear L
                deg = pi/180;
                
                % Define links of robot
                L(1) = Revolute('d', app.data{strcmp("DH_d1",app.data),2}, ...   % link length (Dennavit-Hartenberg notation)
                    'a', app.data{strcmp("DH_a1",app.data),2}, ...               % link offset (Dennavit-Hartenberg notation)
                    'alpha', app.data{strcmp("DH_alpha1",app.data),2}*deg, ...       % link twist (Dennavit-Hartenberg notation)
                    'qlim', app.qlim(1,:)*deg, ...          % minimum and maximum joint angle
                    'offset',app.data{strcmp("DH_offset1",app.data),2}*deg ); 
                
                L(2) = Revolute('d', app.data{strcmp("DH_d2",app.data),2}, ...
                    'a', app.data{strcmp("DH_a2",app.data),2}, ...
                    'alpha', app.data{strcmp("DH_alpha2",app.data),2}*deg, ...
                    'qlim', app.qlim(2,:)*deg, ...
                    'offset',app.data{strcmp("DH_offset2",app.data),2}*deg );
                
                L(3) = Revolute('d', app.data{strcmp("DH_d3",app.data),2}, ...
                    'a', app.data{strcmp("DH_a3",app.data),2}, ...
                    'alpha', app.data{strcmp("DH_alpha3",app.data),2}*deg,  ...
                    'qlim', app.qlim(3,:)*deg, ...
                    'offset',app.data{strcmp("DH_offset3",app.data),2}*deg  );
    
                %Build the robot
                app.robot = SerialLink(L, 'name', 'G8 Robot Arm', ...
                    'tool',transl(0,0,0), ...
                    'base',transl(app.data{strcmp("DH_baseX",app.data),2}, ...
                                  app.data{strcmp("DH_baseY",app.data),2}, ...
                                  app.data{strcmp("DH_baseZ",app.data),2}));
            end
            %plot the robot and update postion and distance info
            q = deg2rad([app.Joint1Slider.Value app.Joint2Slider.Value app.Joint3Slider.Value]);
            app.updateRobot(q);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Enable the Plot Opions button in main app
            app.mainApp.TestRobotMenu.Enable = 'on';
            disconnectHW(app);
            % Delete the dialog box 
            delete(app)
            
        end

        % Button pushed function: RangeTestButton
        function TestRange(app, event)
            if (~isempty(app.robot))
                if (~isempty(app.qlim))
                    for angle1 = deg2rad([app.qlim(1,1):10:app.qlim(1,2)])
                        for angle2 = deg2rad([app.qlim(2,1):10:app.qlim(2,2)])
                            app.robot.plot([angle1,angle2,0]);
                            T = app.robot.fkine([angle1,angle2,0]);
                            
                            hold on
                            plot3(T.t(1), T.t(2), 0, '.','Color','red')
                            
                            if isempty(app.JointServo1) == false
                                writeposition(app.JointServo1,angle1);
                            end
                            if isempty(app.JointServo2) == false
                                writeposition(app.JointServo2,angle2);
                            end
                        end
                        
                    end
                else
                    msgbox("qlim is not set")
                end
            else
                msgbox("robot not created")
            end
        end

        % Button pushed function: DrawGridButton
        function DrawGrid(app, event)
            if (isempty(app.game))
                app.game = TicTacToe;
                if isempty(app.robot)
                    app.game.createRobot();
                else
                    app.game.G8Robot = app.robot;
                end
                if isempty(app.arduino_hardware)
                    app.game.connectRobot(app.ComPort,app.JointServoPin); 
                else
                    app.game.arduinoHW = app.arduino_hardware;
                    app.game.jointServo1 = app.JointServo1;
                    app.game.jointServo2 = app.JointServo2;
                    app.game.jointServo3 = app.JointServo3;
                    
                end
                
            end
            grid = [app.data{strcmp("Grid_VLS_X",app.data),2},app.data{strcmp("Grid_VLS_Y",app.data),2}, ...
                    app.data{strcmp("Grid_VLE_X",app.data),2},app.data{strcmp("Grid_VLE_Y",app.data),2}; ...
                    app.data{strcmp("Grid_VRS_X",app.data),2},app.data{strcmp("Grid_VRS_Y",app.data),2}, ...
                    app.data{strcmp("Grid_VRE_X",app.data),2},app.data{strcmp("Grid_VRE_Y",app.data),2}; ...
                    app.data{strcmp("Grid_HUS_X",app.data),2},app.data{strcmp("Grid_HUS_Y",app.data),2}, ...
                    app.data{strcmp("Grid_HUE_X",app.data),2},app.data{strcmp("Grid_HUE_Y",app.data),2}; ...
                    app.data{strcmp("Grid_HDS_X",app.data),2},app.data{strcmp("Grid_HDS_Y",app.data),2}, ...
                    app.data{strcmp("Grid_HDE_X",app.data),2},app.data{strcmp("Grid_HDE_Y",app.data),2}];
            app.game.isDrawMoveTraj = app.data{strcmp("isDrawMoveTraj",app.data),2};
            app.game.isRecordTraj = app.data{strcmp("isRecordTraj",app.data),2};
            app.game.isUseFileTraj = app.data{strcmp("isUseFileTraj",app.data),2};
            app.game.drawGrid(grid);
            %Update the EE position on GUI
            app.XEditField.Value = app.game.T.t(1);
            app.YEditField.Value = app.game.T.t(2);
            app.ZEditField.Value = app.game.T.t(3);
            %Calculate the distance of EE and update on GUI
            app.DistanceEditField.Value = sqrt(app.game.T.t(1)^2 + ...
                app.game.T.t(2)^2 + app.game.T.t(3)^2);
            
        end

        % Button pushed function: DrawLineButton
        function DrawLine(app, event)
            if (isempty(app.game))
                app.game = TicTacToe;
                if isempty(app.robot)
                    app.game.createRobot();
                else
                    app.game.G8Robot = app.robot;
                end
                if isempty(app.arduino_hardware)
                    app.game.connectRobot(app.ComPort,app.JointServoPin); 
                else
                    app.game.arduinoHW = app.arduino_hardware;
                    app.game.jointServo1 = app.JointServo1;
                    app.game.jointServo2 = app.JointServo2;
                    app.game.jointServo3 = app.JointServo3;
                    
                end
            end
            lineType = app.LineDropDown.Value;
            if lineType == "VerticalLeft"
                startPoint = [app.data{strcmp("Grid_VLS_X",app.data),2}, app.data{strcmp("Grid_VLS_Y",app.data),2}, 0];
                endPoint = [app.data{strcmp("Grid_VLE_X",app.data),2}, app.data{strcmp("Grid_VLE_Y",app.data),2}, 0];
                lineType = "Left";
            elseif lineType == "VerticalRight"
                startPoint = [app.data{strcmp("Grid_VRS_X",app.data),2}, app.data{strcmp("Grid_VRS_Y",app.data),2}, 0];
                endPoint = [app.data{strcmp("Grid_VRE_X",app.data),2}, app.data{strcmp("Grid_VRE_Y",app.data),2}, 0];
                lineType = "Right";
            elseif lineType == "HorizontalUp"
                startPoint = [app.data{strcmp("Grid_HUS_X",app.data),2}, app.data{strcmp("Grid_HUS_Y",app.data),2}, 0];
                endPoint = [app.data{strcmp("Grid_HUE_X",app.data),2}, app.data{strcmp("Grid_HUE_Y",app.data),2}, 0];    
                lineType = "Upper";
            elseif lineType == "HorizontalDown"
                startPoint = [app.data{strcmp("Grid_HDS_X",app.data),2}, app.data{strcmp("Grid_HDS_Y",app.data),2}, 0];
                endPoint = [app.data{strcmp("Grid_HDE_X",app.data),2}, app.data{strcmp("Grid_HDE_Y",app.data),2}, 0];
                lineType = "Lower";
            end
            app.game.isDrawMoveTraj = app.data{strcmp("isDrawMoveTraj",app.data),2};
            app.game.isRecordTraj = app.data{strcmp("isRecordTraj",app.data),2};
            app.game.isUseFileTraj = app.data{strcmp("isUseFileTraj",app.data),2};
            
            app.game.drawLine(lineType,startPoint, endPoint);

            %Update the EE position on GUI
            app.XEditField.Value = app.game.T.t(1);
            app.YEditField.Value = app.game.T.t(2);
            app.ZEditField.Value = app.game.T.t(3);
            %Calculate the distance of EE and update on GUI
            app.DistanceEditField.Value = sqrt(app.game.T.t(1)^2 + ...
                app.game.T.t(2)^2 + app.game.T.t(3)^2);
        end

        % Button pushed function: DrawOButton
        function DrawO(app, event)
            if (isempty(app.game))
                app.game = TicTacToe;
                app.game.createRobot();
                app.game.connectRobot(app.ComPort,app.JointServoPin);
            end
            squareNum = str2num(app.ODropDown.Value);
            app.game.r = app.data{strcmp("Radius",app.data),2};
            app.game.center = zeros([9 5]);
            if squareNum == 1
                app.game.center(squareNum,1) = app.data{strcmp("Center1_X",app.data),2};
                app.game.center(squareNum,2) = app.data{strcmp("Center1_Y",app.data),2};
                app.game.center(squareNum,4) = 1;
                app.game.center(squareNum,5) = 1;
            elseif squareNum == 2
                app.game.center(squareNum,1) = app.data{strcmp("Center2_X",app.data),2};
                app.game.center(squareNum,2) = app.data{strcmp("Center2_Y",app.data),2};
                app.game.center(squareNum,4) = 2;
                app.game.center(squareNum,5) = 1;
            elseif squareNum == 3
                app.game.center(squareNum,1) = app.data{strcmp("Center3_X",app.data),2};
                app.game.center(squareNum,2) = app.data{strcmp("Center3_Y",app.data),2};
                app.game.center(squareNum,4) = 3;
                app.game.center(squareNum,5) = 1;
            elseif squareNum == 4
                app.game.center(squareNum,1) = app.data{strcmp("Center4_X",app.data),2};
                app.game.center(squareNum,2) = app.data{strcmp("Center4_Y",app.data),2};
                app.game.center(squareNum,4) = 1;
                app.game.center(squareNum,5) = 2;
            elseif squareNum == 5
                app.game.center(squareNum,1) = app.data{strcmp("Center5_X",app.data),2};
                app.game.center(squareNum,2) = app.data{strcmp("Center5_Y",app.data),2};
                app.game.center(squareNum,4) = 2;
                app.game.center(squareNum,5) = 2;
            elseif squareNum == 6
                app.game.center(squareNum,1) = app.data{strcmp("Center6_X",app.data),2};
                app.game.center(squareNum,2) = app.data{strcmp("Center6_Y",app.data),2};
                app.game.center(squareNum,4) = 3;
                app.game.center(squareNum,5) = 2;
            elseif squareNum == 7
                app.game.center(squareNum,1) = app.data{strcmp("Center7_X",app.data),2};
                app.game.center(squareNum,2) = app.data{strcmp("Center7_Y",app.data),2};
                app.game.center(squareNum,4) = 1;
                app.game.center(squareNum,5) = 3;
            elseif squareNum == 8
                app.game.center(squareNum,1) = app.data{strcmp("Center8_X",app.data),2};
                app.game.center(squareNum,2) = app.data{strcmp("Center8_Y",app.data),2};
                app.game.center(squareNum,4) = 2;
                app.game.center(squareNum,5) = 3;
            elseif squareNum == 9
                app.game.center(squareNum,1) = app.data{strcmp("Center9_X",app.data),2};
                app.game.center(squareNum,2) = app.data{strcmp("Center9_Y",app.data),2};
                app.game.center(squareNum,4) = 3;
                app.game.center(squareNum,5) = 3;
            else
                
            end
            app.game.isDrawMoveTraj = app.data{strcmp("isDrawMoveTraj",app.data),2};
            app.game.isRecordTraj = app.data{strcmp("isRecordTraj",app.data),2};
            app.game.isUseFileTraj = app.data{strcmp("isUseFileTraj",app.data),2};
            app.game.drawO(squareNum);
            
            %Update the EE position on GUI
            app.XEditField.Value = app.game.T.t(1);
            app.YEditField.Value = app.game.T.t(2);
            app.ZEditField.Value = app.game.T.t(3);
            %Calculate the distance of EE and update on GUI
            app.DistanceEditField.Value = sqrt(app.game.T.t(1)^2 + ...
                app.game.T.t(2)^2 + app.game.T.t(3)^2);
        end

        % Button pushed function: DrawXButton
        function DrawX(app, event)
            if (isempty(app.game))
                app.game = TicTacToe;
                app.game.createRobot();
                app.game.connectRobot(app.ComPort,app.JointServoPin);
            end
            squareNum = str2num(app.XDropDown.Value)
            app.game.r = app.data{strcmp("Radius",app.data),2};
            app.game.center = zeros([9 5]);
            if squareNum == 1
                app.game.center(squareNum,1) = app.data{strcmp("Center1_X",app.data),2};
                app.game.center(squareNum,2) = app.data{strcmp("Center1_Y",app.data),2};
                app.game.center(squareNum,4) = 1;
                app.game.center(squareNum,5) = 1;
            elseif squareNum == 2
                app.game.center(squareNum,1) = app.data{strcmp("Center2_X",app.data),2};
                app.game.center(squareNum,2) = app.data{strcmp("Center2_Y",app.data),2};
                app.game.center(squareNum,4) = 2;
                app.game.center(squareNum,5) = 1;
            elseif squareNum == 3
                app.game.center(squareNum,1) = app.data{strcmp("Center3_X",app.data),2};
                app.game.center(squareNum,2) = app.data{strcmp("Center3_Y",app.data),2};
                app.game.center(squareNum,4) = 3;
                app.game.center(squareNum,5) = 1;
            elseif squareNum == 4
                app.game.center(squareNum,1) = app.data{strcmp("Center4_X",app.data),2};
                app.game.center(squareNum,2) = app.data{strcmp("Center4_Y",app.data),2};
                app.game.center(squareNum,4) = 1;
                app.game.center(squareNum,5) = 2;
            elseif squareNum == 5
                app.game.center(squareNum,1) = app.data{strcmp("Center5_X",app.data),2};
                app.game.center(squareNum,2) = app.data{strcmp("Center5_Y",app.data),2};
                app.game.center(squareNum,4) = 2;
                app.game.center(squareNum,5) = 2;
            elseif squareNum == 6
                app.game.center(squareNum,1) = app.data{strcmp("Center6_X",app.data),2};
                app.game.center(squareNum,2) = app.data{strcmp("Center6_Y",app.data),2};
                app.game.center(squareNum,4) = 3;
                app.game.center(squareNum,5) = 2;
            elseif squareNum == 7
                app.game.center(squareNum,1) = app.data{strcmp("Center7_X",app.data),2};
                app.game.center(squareNum,2) = app.data{strcmp("Center7_Y",app.data),2};
                app.game.center(squareNum,4) = 1;
                app.game.center(squareNum,5) = 3;
            elseif squareNum == 8
                app.game.center(squareNum,1) = app.data{strcmp("Center8_X",app.data),2};
                app.game.center(squareNum,2) = app.data{strcmp("Center8_Y",app.data),2};
                app.game.center(squareNum,4) = 2;
                app.game.center(squareNum,5) = 3;
            elseif squareNum == 9
                app.game.center(squareNum,1) = app.data{strcmp("Center9_X",app.data),2};
                app.game.center(squareNum,2) = app.data{strcmp("Center9_Y",app.data),2};
                app.game.center(squareNum,4) = 3;
                app.game.center(squareNum,5) = 3;
            else
                
            end
            app.game.isDrawMoveTraj = app.data{strcmp("isDrawMoveTraj",app.data),2};
            app.game.isRecordTraj = app.data{strcmp("isRecordTraj",app.data),2};
            app.game.isUseFileTraj = app.data{strcmp("isUseFileTraj",app.data),2};
            app.game.drawX(squareNum);
            %Update the EE position on GUI
            app.XEditField.Value = app.game.T.t(1);
            app.YEditField.Value = app.game.T.t(2);
            app.ZEditField.Value = app.game.T.t(3);
            %Calculate the distance of EE and update on GUI
            app.DistanceEditField.Value = sqrt(app.game.T.t(1)^2 + ...
                app.game.T.t(2)^2 + app.game.T.t(3)^2);
        end

        % Callback function
        function DrawVLine(app, event)
            if (isempty(app.game))
                app.game = TicTacToe;
                app.game.createRobot();
                app.game.connectRobot(app.ComPort,app.JointServoPin);
            end
            lineType = app.LineDropDown.Value;
            if lineType == "VerticalLeft"
                startPoint = [app.data{strcmp("Grid_VLS_X",app.data),2}, app.data{strcmp("Grid_VLS_Y",app.data),2}, 0];
                endPoint = [app.data{strcmp("Grid_VLE_X",app.data),2}, app.data{strcmp("Grid_VLE_Y",app.data),2}, 0];
            elseif lineType == "VerticalRight"
                startPoint = [app.data{strcmp("Grid_VRS_X",app.data),2}, app.data{strcmp("Grid_VRS_Y",app.data),2}, 0];
                endPoint = [app.data{strcmp("Grid_VRE_X",app.data),2}, app.data{strcmp("Grid_VRE_Y",app.data),2}, 0];
            elseif lineType == "HorizontalUp"
                startPoint = [app.data{strcmp("Grid_HUS_X",app.data),2}, app.data{strcmp("Grid_HUS_Y",app.data),2}, 0];
                endPoint = [app.data{strcmp("Grid_HUE_X",app.data),2}, app.data{strcmp("Grid_HUE_Y",app.data),2}, 0];    
            elseif lineType == "HorizontalDown"
                startPoint = [app.data{strcmp("Grid_HDS_X",app.data),2}, app.data{strcmp("Grid_HDS_Y",app.data),2}, 0];
                endPoint = [app.data{strcmp("Grid_HDE_X",app.data),2}, app.data{strcmp("Grid_HDE_Y",app.data),2}, 0];
            end
            app.game.drawVLine(startPoint, endPoint);
            %Update the EE position on GUI
            app.XEditField.Value = app.game.T.t(1);
            app.YEditField.Value = app.game.T.t(2);
            app.ZEditField.Value = app.game.T.t(3);
            %Calculate the distance of EE and update on GUI
            app.DistanceEditField.Value = sqrt(app.game.T.t(1)^2 + ...
                app.game.T.t(2)^2 + app.game.T.t(3)^2);
        end

        % Callback function
        function DrawHLine(app, event)
            if (isempty(app.game))
                app.game = TicTacToe;
                app.game.createRobot();
                app.game.connectRobot(app.ComPort,app.JointServoPin);
            end
            lineType = app.LineDropDown.Value;
            if lineType == "VerticalLeft"
                startPoint = [app.data{strcmp("Grid_VLS_X",app.data),2}, app.data{strcmp("Grid_VLS_Y",app.data),2}, 0];
                endPoint = [app.data{strcmp("Grid_VLE_X",app.data),2}, app.data{strcmp("Grid_VLE_Y",app.data),2}, 0];
            elseif lineType == "VerticalRight"
                startPoint = [app.data{strcmp("Grid_VRS_X",app.data),2}, app.data{strcmp("Grid_VRS_Y",app.data),2}, 0];
                endPoint = [app.data{strcmp("Grid_VRE_X",app.data),2}, app.data{strcmp("Grid_VRE_Y",app.data),2}, 0];
            elseif lineType == "HorizontalUp"
                startPoint = [app.data{strcmp("Grid_HUS_X",app.data),2}, app.data{strcmp("Grid_HUS_Y",app.data),2}, 0];
                endPoint = [app.data{strcmp("Grid_HUE_X",app.data),2}, app.data{strcmp("Grid_HUE_Y",app.data),2}, 0];    
            elseif lineType == "HorizontalDown"
                startPoint = [app.data{strcmp("Grid_HDS_X",app.data),2}, app.data{strcmp("Grid_HDS_Y",app.data),2}, 0];
                endPoint = [app.data{strcmp("Grid_HDE_X",app.data),2}, app.data{strcmp("Grid_HDE_Y",app.data),2}, 0];
            end
            angle3 = app.Angle3EditField.Value;
            app.game.drawHLine(startPoint, endPoint,angle3);
            %Update the EE position on GUI
            app.XEditField.Value = app.game.T.t(1);
            app.YEditField.Value = app.game.T.t(2);
            app.ZEditField.Value = app.game.T.t(3);
            %Calculate the distance of EE and update on GUI
            app.DistanceEditField.Value = sqrt(app.game.T.t(1)^2 + ...
                app.game.T.t(2)^2 + app.game.T.t(3)^2);
        end

        % Button pushed function: DisconnectButton
        function DisconnectHW(app, event)
            clear app.arduino_hardware
            clear app.JointServo1
            clear app.JointServo2
            clear app.JointServo3
        end

        % Value changed function: Servo1EditField
        function ChangeServo1(app, event)
            value = app.Servo1EditField.Value;
            writePosition(app.JointServo1, value);
        end

        % Value changed function: Servo2EditField
        function ChangeServo2(app, event)
            value = app.Servo2EditField.Value;
            writePosition(app.JointServo2, value);
        end

        % Value changed function: Servo3EditField
        function ChangeServo3(app, event)
            value = app.Servo3EditField.Value;
            writePosition(app.JointServo3, value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.XEditField.ValueDisplayFormat = '%.3f';
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeX, true);
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditField.ValueDisplayFormat = '%.3f';
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeY, true);
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditField.ValueDisplayFormat = '%.3f';
            app.ZEditField.Editable = 'off';
            app.DistanceEditFieldLabel = uilabel(app.UIFigure);
            app.DistanceEditFieldLabel.Text = 'Distance';
            app.DistanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.DistanceEditField.ValueDisplayFormat = '%.3f';
            app.DistanceEditField.Editable = 'off';
            app.CreateRobotButton = uibutton(app.UIFigure, 'push');
            app.CreateRobotButton.ButtonPushedFcn = createCallbackFcn(app, @CreateRobot, true);
            app.CreateRobotButton.Text = 'CreateRobot';
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectHW, true);
            app.ConnectButton.Text = 'Connect';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.BasicTab = uitab(app.TabGroup);
            app.BasicTab.Title = 'Basic';
            app.PoseDropDownLabel = uilabel(app.BasicTab);
            app.PoseDropDownLabel.Text = 'Pose';
            app.PoseDropDown = uidropdown(app.BasicTab);
            app.PoseDropDown.Items = {'Pose1_000', 'Pose2_090', 'Pose3_0-90', 'Pose4_900', 'Pose5_990', 'Pose6_9-90', 'Pose7_-900', 'Pose8_-990', 'Pose9_-9-90'};
            app.PoseDropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.PoseDropDown.ValueChangedFcn = createCallbackFcn(app, @ChangePose, true);
            app.PoseDropDown.Value = '1';
            app.Joint1SliderLabel = uilabel(app.BasicTab);
            app.Joint1SliderLabel.Text = 'Joint1';
            app.Joint1Slider = uislider(app.BasicTab);
            app.Joint1Slider.Limits = [-112 90];
            app.Joint1Slider.MajorTicks = [-90 -60 -30 0 30 60 90];
            app.Joint1Slider.ValueChangedFcn = createCallbackFcn(app, @ChangeJoint1, true);
            app.Joint1Slider.MinorTicks = [-100 -90 -80 -70 -60 -50 -40 -30 -20 -10 0 10 20 30 40 50 60 70 80 90];
            app.Joint1Slider.Tag = 'JOINT1SLIDER';
            app.Joint2SliderLabel = uilabel(app.BasicTab);
            app.Joint2SliderLabel.Text = 'Joint2';
            app.Joint2Slider = uislider(app.BasicTab);
            app.Joint2Slider.Limits = [-90 112];
            app.Joint2Slider.MajorTicks = [-90 -60 -30 0 30 60 90 100];
            app.Joint2Slider.MajorTickLabels = {'-90', '-60', '-30', '0', '30', '60', '90'};
            app.Joint2Slider.ValueChangedFcn = createCallbackFcn(app, @ChangeJoint2, true);
            app.Joint2Slider.MinorTicks = [-90 -80 -70 -60 -50 -40 -30 -20 -10 0 10 20 30 40 50 60 70 80 90];
            app.Joint2Slider.Tag = 'JOINT2SLIDER';
            app.Joint3SliderLabel = uilabel(app.BasicTab);
            app.Joint3SliderLabel.Text = 'Joint3';
            app.Joint3Slider = uislider(app.BasicTab);
            app.Joint3Slider.Limits = [0 165];
            app.Joint3Slider.MajorTicks = [0 30 60 90 120 150];
            app.Joint3Slider.ValueChangedFcn = createCallbackFcn(app, @ChaengJoint3, true);
            app.Joint3Slider.Tag = 'JOINT3SLIDER';
            app.Joint3Slider.Value = 150;
            app.Joint1EditField = uieditfield(app.BasicTab, 'numeric');
            app.Joint1EditField.Limits = [-112 90];
            app.Joint1EditField.ValueDisplayFormat = '%.2f';
            app.Joint1EditField.ValueChangedFcn = createCallbackFcn(app, @ChangeJoint1, true);
            app.Joint1EditField.Tag = 'JOINT1EDITER';
            app.Joint2EditField = uieditfield(app.BasicTab, 'numeric');
            app.Joint2EditField.Limits = [-112 90];
            app.Joint2EditField.ValueDisplayFormat = '%.2f';
            app.Joint2EditField.ValueChangedFcn = createCallbackFcn(app, @ChangeJoint2, true);
            app.Joint2EditField.Tag = 'JOINT2EDITER';
            app.Joint3EditField = uieditfield(app.BasicTab, 'numeric');
            app.Joint3EditField.Limits = [0 165];
            app.Joint3EditField.ValueDisplayFormat = '%.2f';
            app.Joint3EditField.ValueChangedFcn = createCallbackFcn(app, @ChaengJoint3, true);
            app.Joint3EditField.Tag = 'JOINT3EDITER';
            app.Servo1EditFieldLabel = uilabel(app.BasicTab);
            app.Servo1EditFieldLabel.Text = 'Servo1';
            app.Servo1EditField = uieditfield(app.BasicTab, 'numeric');
            app.Servo1EditField.Limits = [0 1];
            app.Servo1EditField.ValueChangedFcn = createCallbackFcn(app, @ChangeServo1, true);
            app.Servo2EditFieldLabel = uilabel(app.BasicTab);
            app.Servo2EditFieldLabel.Text = 'Servo2';
            app.Servo2EditField = uieditfield(app.BasicTab, 'numeric');
            app.Servo2EditField.Limits = [0 1];
            app.Servo2EditField.ValueChangedFcn = createCallbackFcn(app, @ChangeServo2, true);
            app.Servo3EditFieldLabel = uilabel(app.BasicTab);
            app.Servo3EditFieldLabel.Text = 'Servo3';
            app.Servo3EditField = uieditfield(app.BasicTab, 'numeric');
            app.Servo3EditField.Limits = [0 1];
            app.Servo3EditField.ValueChangedFcn = createCallbackFcn(app, @ChangeServo3, true);
            app.GameTab = uitab(app.TabGroup);
            app.GameTab.Title = 'Game';
            app.RangeTestButton = uibutton(app.GameTab, 'push');
            app.RangeTestButton.ButtonPushedFcn = createCallbackFcn(app, @TestRange, true);
            app.RangeTestButton.Text = 'RangeTest';
            app.DrawLineButton = uibutton(app.GameTab, 'push');
            app.DrawLineButton.ButtonPushedFcn = createCallbackFcn(app, @DrawLine, true);
            app.DrawLineButton.Text = 'Draw Line';
            app.DrawOButton = uibutton(app.GameTab, 'push');
            app.DrawOButton.ButtonPushedFcn = createCallbackFcn(app, @DrawO, true);
            app.DrawOButton.Text = 'Draw O';
            app.DrawXButton = uibutton(app.GameTab, 'push');
            app.DrawXButton.ButtonPushedFcn = createCallbackFcn(app, @DrawX, true);
            app.DrawXButton.Text = 'Draw X';
            app.DrawGridButton = uibutton(app.GameTab, 'push');
            app.DrawGridButton.ButtonPushedFcn = createCallbackFcn(app, @DrawGrid, true);
            app.DrawGridButton.Text = 'Draw Grid';
            app.LineDropDown = uidropdown(app.GameTab);
            app.LineDropDown.Items = {'VerticalLeft', 'VerticalRight', 'HorizontalUp', 'HorizontalDown'};
            app.LineDropDown.Value = 'VerticalLeft';
            app.ODropDown = uidropdown(app.GameTab);
            app.ODropDown.Items = {'Square1', 'Square2', 'Square3', 'Square4', 'Square5', 'Square6', 'Square7', 'Square8', 'Square9'};
            app.ODropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.ODropDown.Value = '1';
            app.XDropDown = uidropdown(app.GameTab);
            app.XDropDown.Items = {'Square1', 'Square2', 'Square3', 'Square4', 'Square5', 'Square6', 'Square7', 'Square8', 'Square9'};
            app.XDropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.XDropDown.Value = '1';
            app.DisconnectButton = uibutton(app.UIFigure, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectHW, true);
            app.DisconnectButton.Text = 'Disconnect';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TestRobotDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end