classdef TicTacToeApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        G8RobotArmUIFigure       matlab.ui.Figure
        OperationMenu            matlab.ui.container.Menu
        ConnectMenu              matlab.ui.container.Menu
        CreateRobotMenu          matlab.ui.container.Menu
        OptimizeMenu             matlab.ui.container.Menu
        TestRobotMenu            matlab.ui.container.Menu
        ConfigMenu               matlab.ui.container.Menu
        HWConfigMenu             matlab.ui.container.Menu
        DHParaCfgMenu            matlab.ui.container.Menu
        StartButton              matlab.ui.control.Button
        GameInfoLabel            matlab.ui.control.Label
        GameTextArea             matlab.ui.control.TextArea
        TicTacToeGameBoardPanel  matlab.ui.container.Panel
        Image1                   matlab.ui.control.Image
        Image4                   matlab.ui.control.Image
        Image7                   matlab.ui.control.Image
        Image8                   matlab.ui.control.Image
        Image2                   matlab.ui.control.Image
        Image5                   matlab.ui.control.Image
        Image9                   matlab.ui.control.Image
        Image3                   matlab.ui.control.Image
        Image6                   matlab.ui.control.Image
    end

    
    properties (Access = private)
        arduino_initialized = true;
        IsRobotCreated = false;
        optimized = false;
        ConfigHWApp             % Config Hardware Dialog box app        
        ConfigDhApp             % Config DH para Dialog box app
        TestRobotApp
        game TicTacToe
        outputText
    end
    
    properties (Access = public)
        arduino_hardware = [];
        JointServo1 = [];
        JointServo2 = [];
        JointServo3 = [];
        G8Robot = [];
        DhPara = [0.1 0.073 0;      %a1 a2 a3
                  0   -90   0;      %alpha1 alpha2 alpha3
                  0   0.02  0.02;   %d1 d2 d3
                  -19 16.8  0;      %offset1 offset2 offset2
                  0   -0.08 0.047]; %basex basey basez
        %definition of robot joint angle limits
        qlim = [-102 90; -90 102;0 165];
        ComPort = "COM5"        % Initial Arduino COM port
        JointServoPin = ["D3" "D5" "D6"]
    end
    
    methods (Access = private)
        
        function updateRobot(app)
            if app.IsRobotCreated == true
                %Re-plot robot for new pose
                if isempty(app.JointServo1) == false
                    angle1 = ( app.Joint1Slider.Limits(2) - app.Joint1Slider.Value)/...
                             (app.Joint1Slider.Limits(2) - app.Joint1Slider.Limits(1));
                    %angle1 = app.Joint1Spinner.Value;
                    writePosition(app.JointServo1, angle1);
                end
                
                if isempty(app.JointServo2) == false
                    angle2 = (app.Joint2Slider.Limits(2) - app.Joint2Slider.Value)/...
                             (app.Joint2Slider.Limits(2) - app.Joint2Slider.Limits(1));
                    %angle2 = app.Joint2Spinner.Value;
                    writePosition(app.JointServo2, angle2);
                end
                
                
                app.G8Robot.plot([app.Joint1Slider.Value*pi/180 ...
                    app.Joint2Slider.Value*pi/180 ...
                    app.Joint3Slider.Value*pi/180],"jvec")
                %Recalculate the Transformation matrix after joint angle
                %changed
                T = app.G8Robot.fkine([app.Joint1Slider.Value*pi/180 ...
                    app.Joint2Slider.Value*pi/180 ...
                    app.Joint3Slider.Value*pi/180]);
                %Update the EE position on GUI
                app.XEditField.Value = T.t(1);
                app.YEditField.Value = T.t(2);
                app.ZEditField.Value = T.t(3);
                %Calculate the distance of EE and update on GUI
                app.DistanceEditField.Value = sqrt(T.t(1)^2 + T.t(2)^2 + T.t(3)^2);
            else
                msgbox("Robot not created yet")
            end
        end
        
        
        function drawGrid(app,q0)
            q = app.MovePen(q0, 0.16, -0.06);
            
            T = app.G8Robot.fkine(q);
            gridLine1 = zeros([10 3]);
            for i = 1:1:12
                gridLine1(i,:) = T.t;
                q = app.G8Robot.ikine(T,'q0',q,'mask',[1 1 1 0 0 0]);
                app.G8Robot.plot(q);
                
                if isempty(app.JointServo1) == false
                    angle1 = ( app.Joint1Slider.Limits(2) - rad2deg(q(1)))/...
                             (app.Joint1Slider.Limits(2) - app.Joint1Slider.Limits(1));
                    angle1 = app.minmax(angle1);
                    writePosition(app.JointServo1, angle1);
                end
                
                if isempty(app.JointServo2) == false
                    angle2 = (app.Joint2Slider.Limits(2) - rad2deg(q(2)))/...
                             (app.Joint2Slider.Limits(2) - app.Joint2Slider.Limits(1));
                    angle2 = app.minmax(angle2);
                    writePosition(app.JointServo2, angle2);
                end

                T = app.G8Robot.fkine(q);
                app.Joint1Slider.Value = rad2deg(q(1));
                app.Joint2Slider.Value = rad2deg(q(2));
                
                T.t(1) = T.t(1) - 0.01;
                pause(1)
            end
     
        end
        
        function PenUp(app)
            if isempty(app.JointServo3) == false
                writePosition(app.JointServo3, 0.5);
            end
            
        end
        
        function PenDown(app)
            if isempty(app.JointServo3) == false
                writePosition(app.JointServo3, 0);
            end
        end
        
        function q = MovePen(app, q0, x, y)
            %Move pen up
            app.PenUp();
            pause(1);
            %Get start point T
            T = app.G8Robot.fkine(q0);
            
            %Set target position
            T.t(1) = x;
            T.t(2) = y;

            q = app.G8Robot.ikine(T,'q0',q0,'mask',[1 1 1 0 0 0]);
            
            %Update on model
            app.G8Robot.plot(q);
            
            %Drive motor to target position
            if isempty(app.JointServo1) == false
                angle1 = ( app.Joint1Slider.Limits(2) - rad2deg(q(1)))/...
                         (app.Joint1Slider.Limits(2) - app.Joint1Slider.Limits(1));
                %angle1 = app.Joint1Spinner.Value;
                writePosition(app.JointServo1, angle1);
            end
            
            if isempty(app.JointServo2) == false
                angle2 = (app.Joint2Slider.Limits(2) - rad2deg(q(2)))/...
                         (app.Joint2Slider.Limits(2) - app.Joint2Slider.Limits(1));
                %angle2 = app.Joint2Spinner.Value;
                writePosition(app.JointServo2, angle2);
            end
            pause(1);
            %Move pen up
            app.PenDown()
            pause(1);
            app.Joint1Slider.Value = rad2deg(q(1));
            app.Joint2Slider.Value = rad2deg(q(2));
        end
        
        function results = minmax(~,x)
            results = min(max(x,0),1); % restricts input X between 0 and 1
        end
        
        function outPut(app,str)
            string = sprintf('%s\n', str);
            app.outputText=horzcat(app.outputText,string); 
            app.GameTextArea.Value = app.outputText;
        end

        
        function loadPicForRobot(app,position)
            switch position
                case 1
                    app.Image1.ImageSource = "O.png";
                case 2
                    app.Image2.ImageSource = "O.png";
                case 3
                    app.Image3.ImageSource = "O.png";
                case 4
                    app.Image4.ImageSource = "O.png";
                case 5
                    app.Image5.ImageSource = "O.png";
                case 6
                    app.Image6.ImageSource = "O.png";
                case 7
                    app.Image7.ImageSource = "O.png";
                case 8
                    app.Image8.ImageSource = "O.png";
                case 9
                    app.Image9.ImageSource = "O.png";
                otherwise
                    msgbox("robot select wrong position!")
            end
            
        end
        
        function loadPicForUser(app,position)
            switch position
                case 1
                    app.Image1.ImageSource = "X.png";
                case 2
                    app.Image2.ImageSource = "X.png";
                case 3
                    app.Image3.ImageSource = "X.png";
                case 4
                    app.Image4.ImageSource = "X.png";
                case 5
                    app.Image5.ImageSource = "X.png";
                case 6
                    app.Image6.ImageSource = "X.png";
                case 7
                    app.Image7.ImageSource = "X.png";
                case 8
                    app.Image8.ImageSource = "X.png";
                case 9
                    app.Image9.ImageSource = "X.png";
                otherwise
                    msgbox("robot select wrong position!")
            end
            
        end
        
        function clearBoard(app)
            app.Image1.ImageSource = "blank.png";
            app.Image2.ImageSource = "blank.png";
            app.Image3.ImageSource = "blank.png";
            app.Image4.ImageSource = "blank.png";
            app.Image5.ImageSource = "blank.png";
            app.Image6.ImageSource = "blank.png";
            app.Image7.ImageSource = "blank.png";
            app.Image8.ImageSource = "blank.png";
            app.Image9.ImageSource = "blank.png";
            
            figure(1)
            clf(1)
        end
        
        function disableImages(app)
            app.Image1.Enable = "off";
            app.Image2.Enable = "off";
            app.Image3.Enable = "off";
            app.Image4.Enable = "off";
            app.Image5.Enable = "off";
            app.Image6.Enable = "off";
            app.Image7.Enable = "off";
            app.Image8.Enable = "off";
            app.Image9.Enable = "off";
        end
        
        function enableImages(app)
            app.Image1.Enable = "on";
            app.Image2.Enable = "on";
            app.Image3.Enable = "on";
            app.Image4.Enable = "on";
            app.Image5.Enable = "on";
            app.Image6.Enable = "on";
            app.Image7.Enable = "on";
            app.Image8.Enable = "on";
            app.Image9.Enable = "on";
        end
        
        function userClick(app,position)
            
            app.disableImages();
            if app.game.state == "running"
                row = app.game.center(position,4);
                column = app.game.center(position,5);
                if (app.game.board(row,column) == 255) && (app.game.player == "user")
                    loadPicForUser(app,position);
                    app.game.drawX(position);
                    [done,who] = app.game.checkForWin();
                    if done == true
                        if who =='X'
                            msg = "You Win！";
                        elseif who == 'O'
                            msg = "You Lose!";
                        else
                            msg = "This is a draw!";
                        end
                        
                        outPut(app,msg);
                        outPut(app,"Game finished");
                        msgbox(msg)
                        app.game.state = "done";
                        app.StartButton.Enable = "on";
                    else
                        outPut(app,"robot is playing...")
                        app.game.player = "robot";
                        positionO = app.game.computeNextMove();
                        app.game.drawO(positionO);
                        loadPicForRobot(app,positionO);
                        [done,who] = app.game.checkForWin();
                        if done == true
                            if who =='X'
                                msg = "You Win！";
                            elseif who == 'O'
                                msg = "You Lose!";
                            else
                                msg = "This is a draw!";
                            end
                            
                            outPut(app,msg);
                            outPut(app,"Game finished");
                            msgbox(msg);
                            app.game.state = "done";
                            app.StartButton.Enable = "on";
                        else
                            app.game.player = "user";
                            outPut(app,"user is playing...")
                        end
                    end
                    
                end
            end
            app.enableImages();
        end
    end
    
    methods (Access = public)
        
        
        function updateHWConfig(app, port, servoPin)
            app.ComPort = port;     %Update port
            app.JointServoPin = servoPin;
            %re-enable config arduino port
            app.HWConfigMenu.Enable = 'on';
        end
        
        function updateDhParaCfg(app,DhPara)
            app.DhPara = DhPara;
            %re-enable config arduino port
            app.DHParaCfgMenu.Enable = 'on';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           app.outputText{1} = sprintf('%s\n', 'Game info will be displayed here');
            app.GameTextArea.Value=app.outputText{1};
            
        end

        % Menu selected function: ConnectMenu
        function Connect(app, event)
            try
                app.arduino_hardware = arduino(app.ComPort, 'Uno', ...
                    'Libraries', 'Servo');
                app.arduino_initialized = true;
            catch ME
                warn_msg = ["Connect failure","Indentifier:",ME.identifier,"Message:",ME.message];
                msgbox(warn_msg);
                return;
            end
            
            try
                if isempty(app.arduino_hardware) == false
                    app.JointServo1 = servo(app.arduino_hardware, app.JointServoPin(1), ...
                        'MinPulseDuration', 5.75*10^(-4), 'MaxPulseDuration', 2.46*10^(-3));
                    app.JointServo2 = servo(app.arduino_hardware, app.JointServoPin(2), ...
                        'MinPulseDuration', 5.75*10^(-4), 'MaxPulseDuration', 2.46*10^(-3));
                    app.JointServo3 = servo(app.arduino_hardware, app.JointServoPin(3), ...
                        'MinPulseDuration', 6.4*10^(-4), 'MaxPulseDuration', 2.25*10^(-3));
                end
                
            catch ME
                warn_msg = ["Servo failure","Indentifier:",ME.identifier,"Message:",ME.message];
                msgbox(warn_msg);
                return;
            end
            
            app.arduino_initialized = true;
            msgbox('Arduino connected');
        end

        % Menu selected function: CreateRobotMenu
        function CreateRobot(app, event)
            clear L
            deg = pi/180;
            
            % Define links of robot
            L(1) = Revolute('d', app.DhPara(3,1), ...   % link length (Dennavit-Hartenberg notation)
                'a', app.DhPara(1,1), ...               % link offset (Dennavit-Hartenberg notation)
                'alpha', app.DhPara(2,1)*deg, ...       % link twist (Dennavit-Hartenberg notation)
                'qlim', app.qlim(1,:), ...                      % minimum and maximum joint angle
                'offset',app.DhPara(4,1)*deg ); 
            
            L(2) = Revolute('d', app.DhPara(3,2), ...
                'a', app.DhPara(1,2), ...
                'alpha', app.DhPara(2,2)*deg, ...
                'qlim', app.qlim(2,:), ...
                'offset',app.DhPara(4,2)*deg );
            
            L(3) = Revolute('d', app.DhPara(3,3), ...
                'a', app.DhPara(1,3), ...
                'alpha', app.DhPara(2,3)*deg,  ...
                'qlim', app.qlim(3,:), ...
                'offset',app.DhPara(4,3)*deg  );

            %Build the robot
            app.G8Robot = SerialLink(L, 'name', 'G8 Robot Arm', ...
                'tool',transl(0,0,0), ...
                'base',transl(app.DhPara(5,1), app.DhPara(5,2),app.DhPara(5,3)));
            %Set the robot created flag
            app.IsRobotCreated = true;
            %plot the robot and update postion and distance info
            app.updateRobot();
        end

        % Key release function: G8RobotArmUIFigure
        function G8RobotArmUIFigureKeyRelease(app, event)
            %make sure robot is created already
            if app.IsRobotCreated == true
                %Get current robot EE transformation matrix
                q1 = [app.Joint1Slider.Value*pi/180 ...
                    app.Joint2Slider.Value*pi/180 ...
                    app.Joint3Slider.Value*pi/180];
                T = app.G8Robot.fkine(q1);
                %adjust x or y position according to key press
                if event.Key == "leftarrow"
                    T.t(2) = T.t(2) + 0.005;    %move to Y + 0.005m
                elseif event.Key == "rightarrow"
                    T.t(2) = T.t(2) - 0.005;    %move to Y - 0.005m
                elseif event.Key == "uparrow"
                    T.t(1) = T.t(1) + 0.005;    %move to X + 0.005m
                elseif event.Key == "downarrow"
                    T.t(1) = T.t(1) - 0.005;    %move to X - 0.005m
                else 
                end
                %Do inverse kinematics
                q2 = app.G8Robot.ikine(T,'q0',q1,'mask',[1 1 1 0 0 0]);
                if (isempty(q2) == false)
                    %update joints angle after ikine
                    q2deg = rad2deg(q2);
                    if((q2deg(1) <= app.Joint1Slider.Limits(2)) && ...
                       (q2deg(1) >= app.Joint1Slider.Limits(1)) && ...
                       (q2deg(2) <= app.Joint2Slider.Limits(2)) && ...
                       (q2deg(2) >= app.Joint2Slider.Limits(1)))
                   
                        app.Joint1Slider.Value = q2deg(1);
                        app.Joint2Slider.Value = q2deg(2);
                        app.Joint3Slider.Value = q2deg(3);
                        %plot the robot and update postion and distance info
                        app.updateRobot();
                    else
                        msg = sprintf("Joint angle reaches limit:\n" + ...
                                      "Joint1 angle:%0.1f\n" + ...
                                      "Joint2 angle:%0.1f",q2deg(1),q2deg(2));
                        msgbox(msg);
                    end
                else
                    msgbox("can't reach the target");
                end
            else
                msgbox("Robot not created yet")
            end
        end

        % Callback function
        function ChangePose(app, event)
            %make sure robot is created already
            if app.IsRobotCreated == true
                %update joints angle according to pose selection
                poseinx = str2num(app.PoseDropDown.Value);
                app.Joint1Slider.Value = app.Pose(poseinx,1);
                app.Joint2Slider.Value = app.Pose(poseinx,2);
                app.Joint3Slider.Value = app.Pose(poseinx,3);
                
                %plot the robot and update postion and distance info
                app.updateRobot();
            else
                msgbox("Robot not created yet")
            end            
        end

        % Callback function
        function Joint1SliderValueChanged(app, event)
            %plot the robot and update postion and distance info
            app.updateRobot();
        end

        % Callback function
        function Joint2SliderValueChanged(app, event)
            %plot the robot and update postion and distance info
            app.updateRobot();
        end

        % Callback function
        function Joint3SliderValueChanged(app, event)
            %plot the robot and update postion and distance info
            app.updateRobot();
        end

        % Menu selected function: OptimizeMenu
        function Optimize(app, event)
            %before start, set flag as flase
            app.optimized = false;
            
            %set optimization options
            options = optimset('Display','final','TolX',1e-6, ...
                'PlotFcns',@optimplotfval,'MaxIter',500);
            %start optimization
            [x,fval,exitflag,output] = fminsearch(@CalcDistError,app.DhPara, options)
            
            app.optimized = true;
            %optimization finished, update the DH parameters
            app.DhPara = x;
        end

        % Callback function
        function Update_a1(app, event)
            if app.IsRobotCreated == true
                %clear current figure before re-plot
                clf;
                %update robot a1 parameter
                app.G8Robot.links(1).a = app.a1EditField.Value;
                %plot the robot and update postion and distance info
                app.updateRobot();
            end
        end

        % Callback function
        function update_a2(app, event)
            if app.IsRobotCreated == true
                %clear current figure before re-plot
                clf;
                %update robot a1 parameter
                app.G8Robot.links(2).a = app.a2EditField.Value;
                %plot the robot and update postion and distance info
                app.updateRobot();
            end            
        end

        % Callback function
        function update_d1(app, event)
            if app.IsRobotCreated == true
                %clear current figure before re-plot
                clf;
                %update robot a1 parameter
                app.G8Robot.links(1).d = app.d1EditField.Value;
                %plot the robot and update postion and distance info
                app.updateRobot();
            end            
        end

        % Callback function
        function update_d2(app, event)
            if app.IsRobotCreated == true
                %clear current figure before re-plot
                clf;
                %update robot a1 parameter
                app.G8Robot.links(2).d = app.d2EditField.Value;
                %plot the robot and update postion and distance info
                app.updateRobot();
            end            
        end

        % Callback function
        function update_d3(app, event)
            if app.IsRobotCreated == true
                %clear current figure before re-plot
                clf;
                %update robot a1 parameter
                app.G8Robot.links(3).d = app.d3EditField.Value;
                %plot the robot and update postion and distance info
                app.updateRobot();
            end            
        end

        % Callback function
        function update_alpha1(app, event)
            if app.IsRobotCreated == true
                %clear current figure before re-plot
                %clf;
                %update robot a1 parameter
                app.G8Robot.links(1).alpha = deg2rad(app.alpha1EditField.Value);
                %plot the robot and update postion and distance info
                app.updateRobot();
            end 
        end

        % Callback function
        function update_alpha2(app, event)
            if app.IsRobotCreated == true
                %clear current figure before re-plot
                clf;
                %update robot a1 parameter
                app.G8Robot.links(2).alpha = deg2rad(app.alpha2EditField.Value);
                %plot the robot and update postion and distance info
                app.updateRobot();
            end            
        end

        % Callback function
        function update_offset1(app, event)
            if app.IsRobotCreated == true
                %clear current figure before re-plot
                %clf;
                %update robot a1 parameter
                app.G8Robot.offset(1) = app.offset1EditField.Value*pi/180;
                %plot the robot and update postion and distance info
                app.updateRobot();
            end  
        end

        % Callback function
        function update_offset2(app, event)
            if app.IsRobotCreated == true
                %clear current figure before re-plot
                %clf;
                %update robot a1 parameter
                app.G8Robot.offset(2) = app.offset2EditField.Value*pi/180;
                %plot the robot and update postion and distance info
                app.updateRobot();
            end 
        end

        % Menu selected function: DHParaCfgMenu
        function ConfigDHParameters(app, event)
            %Disable the menu to avoid reopen config window 
            app.DHParaCfgMenu.Enable = 'off';
            
            % Open the options dialog and pass inputs
            app.ConfigDhApp = ConfigDhPara(app, app.DhPara);
        end

        % Menu selected function: HWConfigMenu
        function ConfigHWPara(app, event)
            %Disable the menu to avoid reopen config window 
            app.HWConfigMenu.Enable = 'off';
            
            % Open the options dialog and pass inputs
            app.ConfigHWApp = ConfigHW(app, app.ComPort, app.JointServoPin);
            
        end

        % Close request function: G8RobotArmUIFigure
        function G8RobotArmUIFigureCloseRequest(app, event)
            %close all windows
            close all
            
            % Delete both apps
            delete(app.ConfigHWApp)
            delete(app.ConfigDhApp)
            delete(app.TestRobotApp)
            delete(app)
            
        end

        % Callback function
        function UpdateServo(app, event)

            app.updateRobot();
        end

        % Button pushed function: StartButton
        function StartGame(app, event)
            if app.StartButton.Text == "Start"
    
                app.GameTextArea.Value = '';
                %check robot created
                %check HW connected
                
                %clear board
                clearBoard(app)
                
                %Initialize game
                app.game = TicTacToe;
                outPut(app,"Game initialized.")
                app.game.createRobot();
                outPut(app,"Robot model created.")
                [result, warnmsg] = app.game.connectRobot(app.ComPort,app.JointServoPin);
                if result == 0
                    outPut(app,"Robot Hardware connected.")
                else
                    outPut(app,warnmsg)
                end
                app.game.state = "ready";
    
                app.game.drawGrid();
                outPut(app,"Game board setup finished.")
                app.game.state = "running";
                outPut(app,"Ready to play.")
                
                app.game.player = "user";
                outPut(app,"Now user to play")
            elseif app.StartButton.Text == "Stop"
                app.game.state = "Terminated";
            else
            end
        end

        % Menu selected function: TestRobotMenu
        function TestRobot(app, event)
            %Disable the menu to avoid reopen config window 
            app.TestRobotMenu.Enable = 'off';
            
            % Open the options dialog and pass inputs
            app.TestRobotApp = TestRobotDialog(app);
        end

        % Image clicked function: Image1
        function ClickOnPic1(app, event)
            userClick(app,1);
%             app.disableImages();
%             if app.game.state == "running"
%                 if (app.game.board(1,1) == 255) && (app.game.player == "user")
%                     app.Image1.ImageSource = 'X.png';
%                     app.game.drawX(1);
%                     [done,who] = app.game.checkForWin();
%                     if done == true
%                         if who =='X'
%                             msg = "You Win！";
%                         elseif who == 'O'
%                             msg = "You Lose!";
%                         else
%                             msg = "This is a draw!";
%                         end
%                         
%                         outPut(app,msg);
%                         outPut(app,"Game finished");
%                         msgbox(msg)
%                         app.game.state = "done";
%                         app.StartButton.Enable = "on";
%                     else
%                         outPut(app,"robot is playing...")
%                         app.game.player = "robot";
%                         position = app.game.computeNextMove();
%                         app.game.drawO(position);
%                         loadPicForRobot(app,position);
%                         [done,who] = app.game.checkForWin();
%                         if done == true
%                             if who =='X'
%                                 msg = "You Win！";
%                             elseif who == 'O'
%                                 msg = "You Lose!";
%                             else
%                                 msg = "This is a draw!";
%                             end
%                             
%                             outPut(app,msg);
%                             outPut(app,"Game finished");
%                             msgbox(msg);
%                             app.game.state = "done";
%                             app.StartButton.Enable = "on";
%                         else
%                             app.game.player = "user";
%                             outPut(app,"user is playing...")
%                         end
%                     end
%                     
%                 end
%             end
%             app.enableImages();
        end

        % Image clicked function: Image2
        function ClickOnPic2(app, event)
            userClick(app,2);
%             if app.game.state == "running"
%                 if (app.game.board(2,1) == 255) && (app.game.player == "user")
%                     app.Image2.ImageSource = 'X.png';
%                     app.game.drawX(2);
%                     [done,who] = app.game.checkForWin();
%                     if done == true
%                         if who =='X'
%                             msg = "You Win！";
%                         elseif who == 'O'
%                             msg = "You Lose!";
%                         else
%                             msg = "This is a draw!";
%                         end
%                         
%                         outPut(app,msg);
%                         outPut(app,"Game finished");
%                         msgbox(msg)
%                         app.game.state = "done";
%                         app.StartButton.Enable = "on";
%                     else
%                         outPut(app,"robot is playing...")
%                         app.game.player = "robot";
%                         position = app.game.computeNextMove();
%                         app.game.drawO(position);
%                         loadPicForRobot(app,position);
%                         [done,who] = app.game.checkForWin();
%                         if done == true
%                             if who =='X'
%                                 msg = "You Win！";
%                             elseif who == 'O'
%                                 msg = "You Lose!";
%                             else
%                                 msg = "This is a draw!";
%                             end
%                             
%                             outPut(app,msg);
%                             outPut(app,"Game finished");
%                             msgbox(msg);
%                             app.game.state = "done";
%                             app.StartButton.Enable = "on";
%                         else
%                             app.game.player = "user";
%                             outPut(app,"user is playing...")
%                         end
%                     end
%                     
%                 end
%             end
%             app.enableImages();
        end

        % Image clicked function: Image3
        function ClickOnPic3(app, event)
            userClick(app,3);
%             if app.game.state == "running"
%                 if (app.game.board(3,1) == 255) && (app.game.player == "user")
%                     app.Image3.ImageSource = 'X.png';
%                     app.game.drawX(3);
%                     [done,who] = app.game.checkForWin();
%                     if done == true
%                         if who =='X'
%                             msg = "You Win！";
%                         elseif who == 'O'
%                             msg = "You Lose!";
%                         else
%                             msg = "This is a draw!";
%                         end
%                         
%                         outPut(app,msg);
%                         outPut(app,"Game finished");
%                         msgbox(msg)
%                         app.game.state = "done";
%                         app.StartButton.Enable = "on";
%                     else
%                         outPut(app,"robot is playing...")
%                         app.game.player = "robot";
%                         position = app.game.computeNextMove();
%                         app.game.drawO(position);
%                         loadPicForRobot(app,position);
%                         [done,who] = app.game.checkForWin();
%                         if done == true
%                             if who =='X'
%                                 msg = "You Win！";
%                             elseif who == 'O'
%                                 msg = "You Lose!";
%                             else
%                                 msg = "This is a draw!";
%                             end
%                             
%                             outPut(app,msg);
%                             outPut(app,"Game finished");
%                             msgbox(msg);
%                             app.game.state = "done";
%                             app.StartButton.Enable = "on";
%                         else
%                             app.game.player = "user";
%                             outPut(app,"user is playing...")
%                         end
%                     end
%                     
%                 end
%             end
%             app.enableImages();
        end

        % Image clicked function: Image4
        function ClickOnPic4(app, event)
            userClick(app,4);
%             if app.game.state == "running"
%                 if (app.game.board(1,2) == 255) && (app.game.player == "user")
%                     app.Image4.ImageSource = 'X.png';
%                     app.game.drawX(4);
%                     [done,who] = app.game.checkForWin();
%                     if done == true
%                         if who =='X'
%                             msg = "You Win！";
%                         elseif who == 'O'
%                             msg = "You Lose!";
%                         else
%                             msg = "This is a draw!";
%                         end
%                         
%                         outPut(app,msg);
%                         outPut(app,"Game finished");
%                         msgbox(msg)
%                         app.game.state = "done";
%                         app.StartButton.Enable = "on";
%                     else
%                         outPut(app,"robot is playing...")
%                         app.game.player = "robot";
%                         position = app.game.computeNextMove();
%                         app.game.drawO(position);
%                         loadPicForRobot(app,position);
%                         [done,who] = app.game.checkForWin();
%                         if done == true
%                             if who =='X'
%                                 msg = "You Win！";
%                             elseif who == 'O'
%                                 msg = "You Lose!";
%                             else
%                                 msg = "This is a draw!";
%                             end
%                             
%                             outPut(app,msg);
%                             outPut(app,"Game finished");
%                             msgbox(msg);
%                             app.game.state = "done";
%                             app.StartButton.Enable = "on";
%                         else
%                             app.game.player = "user";
%                             outPut(app,"user is playing...")
%                         end
%                     end
%                     
%                 end
%             end
%             app.enableImages();
        end

        % Image clicked function: Image5
        function ClickOnPic5(app, event)
            userClick(app,5);
%             if app.game.state == "running"
%                 if (app.game.board(2,2) == 255) && (app.game.player == "user")
%                     app.Image5.ImageSource = 'X.png';
%                     app.game.drawX(5);
%                     [done,who] = app.game.checkForWin();
%                     if done == true
%                         if who =='X'
%                             msg = "You Win！";
%                         elseif who == 'O'
%                             msg = "You Lose!";
%                         else
%                             msg = "This is a draw!";
%                         end
%                         
%                         outPut(app,msg);
%                         outPut(app,"Game finished");
%                         msgbox(msg)
%                         app.game.state = "done";
%                         app.StartButton.Enable = "on";
%                     else
%                         outPut(app,"robot is playing...")
%                         app.game.player = "robot";
%                         position = app.game.computeNextMove();
%                         app.game.drawO(position);
%                         loadPicForRobot(app,position);
%                         [done,who] = app.game.checkForWin();
%                         if done == true
%                             if who =='X'
%                                 msg = "You Win！";
%                             elseif who == 'O'
%                                 msg = "You Lose!";
%                             else
%                                 msg = "This is a draw!";
%                             end
%                             
%                             outPut(app,msg);
%                             outPut(app,"Game finished");
%                             msgbox(msg);
%                             app.game.state = "done";
%                             app.StartButton.Enable = "on";
%                         else
%                             app.game.player = "user";
%                             outPut(app,"user is playing...")
%                         end
%                     end
%                     
%                 end
%             end
%             app.enableImages();
        end

        % Image clicked function: Image6
        function ClickOnPic6(app, event)
            userClick(app,6);
%             if app.game.state == "running"
%                 if (app.game.board(3,2) == 255) && (app.game.player == "user")
%                     app.Image6.ImageSource = 'X.png';
%                     app.game.drawX(6);
%                     [done,who] = app.game.checkForWin();
%                     if done == true
%                         if who =='X'
%                             msg = "You Win！";
%                         elseif who == 'O'
%                             msg = "You Lose!";
%                         else
%                             msg = "This is a draw!";
%                         end
%                         
%                         outPut(app,msg);
%                         outPut(app,"Game finished");
%                         msgbox(msg)
%                         app.game.state = "done";
%                         app.StartButton.Enable = "on";
%                     else
%                         outPut(app,"robot is playing...")
%                         app.game.player = "robot";
%                         position = app.game.computeNextMove();
%                         app.game.drawO(position);
%                         loadPicForRobot(app,position);
%                         [done,who] = app.game.checkForWin();
%                         if done == true
%                             if who =='X'
%                                 msg = "You Win！";
%                             elseif who == 'O'
%                                 msg = "You Lose!";
%                             else
%                                 msg = "This is a draw!";
%                             end
%                             
%                             outPut(app,msg);
%                             outPut(app,"Game finished");
%                             msgbox(msg);
%                             app.game.state = "done";
%                             app.StartButton.Enable = "on";
%                         else
%                             app.game.player = "user";
%                             outPut(app,"user is playing...")
%                         end
%                     end
%                     
%                 end
%             end
%             %Enable all iamges to allow user click
%             app.enableImages();
        end

        % Image clicked function: Image7
        function ClickOnPic7(app, event)
            userClick(app,7);
%             if app.game.state == "running"
%                 if (app.game.board(1,3) == 255) && (app.game.player == "user")
%                     app.Image7.ImageSource = 'X.png';
%                     app.game.drawX(7);
%                     [done,who] = app.game.checkForWin();
%                     if done == true
%                         if who =='X'
%                             msg = "You Win！";
%                         elseif who == 'O'
%                             msg = "You Lose!";
%                         else
%                             msg = "This is a draw!";
%                         end
%                         
%                         outPut(app,msg);
%                         outPut(app,"Game finished");
%                         msgbox(msg)
%                         app.game.state = "done";
%                         app.StartButton.Enable = "on";
%                     else
%                         outPut(app,"robot is playing...")
%                         app.game.player = "robot";
%                         position = app.game.computeNextMove();
%                         app.game.drawO(position);
%                         loadPicForRobot(app,position);
%                         [done,who] = app.game.checkForWin();
%                         if done == true
%                             if who =='X'
%                                 msg = "You Win！";
%                             elseif who == 'O'
%                                 msg = "You Lose!";
%                             else
%                                 msg = "This is a draw!";
%                             end
%                             
%                             outPut(app,msg);
%                             outPut(app,"Game finished");
%                             msgbox(msg);
%                             app.game.state = "done";
%                             app.StartButton.Enable = "on";
%                         else
%                             app.game.player = "user";
%                             outPut(app,"user is playing...")
%                         end
%                     end
%                     
%                 end
%             end
%             %Enable all iamges to allow user click
%             app.enableImages();
        end

        % Image clicked function: Image8
        function ClickOnPic8(app, event)
            userClick(app,8);
%             if app.game.state == "running"
%                 if (app.game.board(2,3) == 255) && (app.game.player == "user")
%                     app.Image8.ImageSource = 'X.png';
%                     app.game.drawX(8);
%                     [done,who] = app.game.checkForWin();
%                     if done == true
%                         if who =='X'
%                             msg = "You Win！";
%                         elseif who == 'O'
%                             msg = "You Lose!";
%                         else
%                             msg = "This is a draw!";
%                         end
%                         
%                         outPut(app,msg);
%                         outPut(app,"Game finished");
%                         msgbox(msg)
%                         app.game.state = "done";
%                         app.StartButton.Enable = "on";
%                     else
%                         outPut(app,"robot is playing...")
%                         app.game.player = "robot";
%                         position = app.game.computeNextMove();
%                         app.game.drawO(position);
%                         loadPicForRobot(app,position);
%                         [done,who] = app.game.checkForWin();
%                         if done == true
%                             if who =='X'
%                                 msg = "You Win！";
%                             elseif who == 'O'
%                                 msg = "You Lose!";
%                             else
%                                 msg = "This is a draw!";
%                             end
%                             
%                             outPut(app,msg);
%                             outPut(app,"Game finished");
%                             msgbox(msg);
%                             app.game.state = "done";
%                             app.StartButton.Enable = "on";
%                         else
%                             app.game.player = "user";
%                             outPut(app,"user is playing...")
%                         end
%                     end
%                     
%                 end
%             end
%             %Enable all iamges to allow user click
%             app.enableImages();
        end

        % Image clicked function: Image9
        function ClickOnPic9(app, event)
            userClick(app,9);
%             if app.game.state == "running"
%                 if (app.game.board(3,3) == 255) && (app.game.player == "user")
%                     app.Image9.ImageSource = 'X.png';
%                     app.game.drawX(9);
%                     [done,who] = app.game.checkForWin();
%                     if done == true
%                         if who =='X'
%                             msg = "You Win！";
%                         elseif who == 'O'
%                             msg = "You Lose!";
%                         else
%                             msg = "This is a draw!";
%                         end
%                         
%                         outPut(app,msg);
%                         outPut(app,"Game finished");
%                         msgbox(msg)
%                         app.game.state = "done";
%                         app.StartButton.Enable = "on";
%                     else
%                         outPut(app,"robot is playing...")
%                         app.game.player = "robot";
%                         position = app.game.computeNextMove();
%                         app.game.drawO(position);
%                         loadPicForRobot(app,position);
%                         [done,who] = app.game.checkForWin();
%                         if done == true
%                             if who =='X'
%                                 msg = "You Win！";
%                             elseif who == 'O'
%                                 msg = "You Lose!";
%                             else
%                                 msg = "This is a draw!";
%                             end
%                             
%                             outPut(app,msg);
%                             outPut(app,"Game finished");
%                             msgbox(msg);
%                             app.game.state = "done";
%                             app.StartButton.Enable = "on";
%                         else
%                             app.game.player = "user";
%                             outPut(app,"user is playing...")
%                         end
%                     end
%                     
%                 end
%             end
%             %Enable all iamges to allow user click
%             app.enableImages();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.G8RobotArmUIFigure = uifigure('Visible', 'off');
            app.G8RobotArmUIFigure.Name = 'G8 Robot Arm';
            app.G8RobotArmUIFigure.CloseRequestFcn = createCallbackFcn(app, @G8RobotArmUIFigureCloseRequest, true);
            app.G8RobotArmUIFigure.KeyReleaseFcn = createCallbackFcn(app, @G8RobotArmUIFigureKeyRelease, true);
            app.OperationMenu = uimenu(app.G8RobotArmUIFigure);
            app.OperationMenu.Text = 'Operation';
            app.ConnectMenu = uimenu(app.OperationMenu);
            app.ConnectMenu.MenuSelectedFcn = createCallbackFcn(app, @Connect, true);
            app.ConnectMenu.Text = 'Connect';
            app.CreateRobotMenu = uimenu(app.OperationMenu);
            app.CreateRobotMenu.MenuSelectedFcn = createCallbackFcn(app, @CreateRobot, true);
            app.CreateRobotMenu.Text = 'CreateRobot';
            app.OptimizeMenu = uimenu(app.OperationMenu);
            app.OptimizeMenu.MenuSelectedFcn = createCallbackFcn(app, @Optimize, true);
            app.OptimizeMenu.Text = 'Optimize';
            app.TestRobotMenu = uimenu(app.OperationMenu);
            app.TestRobotMenu.MenuSelectedFcn = createCallbackFcn(app, @TestRobot, true);
            app.TestRobotMenu.Text = 'TestRobot';
            app.ConfigMenu = uimenu(app.G8RobotArmUIFigure);
            app.ConfigMenu.Text = 'Config';
            app.HWConfigMenu = uimenu(app.ConfigMenu);
            app.HWConfigMenu.MenuSelectedFcn = createCallbackFcn(app, @ConfigHWPara, true);
            app.HWConfigMenu.Text = 'Hardware';
            app.DHParaCfgMenu = uimenu(app.ConfigMenu);
            app.DHParaCfgMenu.MenuSelectedFcn = createCallbackFcn(app, @ConfigDHParameters, true);
            app.DHParaCfgMenu.Text = 'DH Parameters';
            app.StartButton = uibutton(app.G8RobotArmUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartGame, true);
            app.StartButton.Text = 'Start';
            app.GameInfoLabel = uilabel(app.G8RobotArmUIFigure);
            app.GameInfoLabel.Text = 'GameInfo';
            app.GameTextArea = uitextarea(app.G8RobotArmUIFigure);
            app.TicTacToeGameBoardPanel = uipanel(app.G8RobotArmUIFigure);
            app.TicTacToeGameBoardPanel.Title = 'TicTacToe Game Board';
            app.Image1 = uiimage(app.TicTacToeGameBoardPanel);
            app.Image1.ImageClickedFcn = createCallbackFcn(app, @ClickOnPic1, true);
            app.Image1.ImageSource = 'blank.png';
            app.Image4 = uiimage(app.TicTacToeGameBoardPanel);
            app.Image4.ImageClickedFcn = createCallbackFcn(app, @ClickOnPic4, true);
            app.Image4.ImageSource = 'blank.png';
            app.Image7 = uiimage(app.TicTacToeGameBoardPanel);
            app.Image7.ImageClickedFcn = createCallbackFcn(app, @ClickOnPic7, true);
            app.Image7.ImageSource = 'blank.png';
            app.Image8 = uiimage(app.TicTacToeGameBoardPanel);
            app.Image8.ImageClickedFcn = createCallbackFcn(app, @ClickOnPic8, true);
            app.Image8.ImageSource = 'blank.png';
            app.Image2 = uiimage(app.TicTacToeGameBoardPanel);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @ClickOnPic2, true);
            app.Image2.ImageSource = 'blank.png';
            app.Image5 = uiimage(app.TicTacToeGameBoardPanel);
            app.Image5.ImageClickedFcn = createCallbackFcn(app, @ClickOnPic5, true);
            app.Image5.ImageSource = 'blank.png';
            app.Image9 = uiimage(app.TicTacToeGameBoardPanel);
            app.Image9.ImageClickedFcn = createCallbackFcn(app, @ClickOnPic9, true);
            app.Image9.ImageSource = 'blank.png';
            app.Image3 = uiimage(app.TicTacToeGameBoardPanel);
            app.Image3.ImageClickedFcn = createCallbackFcn(app, @ClickOnPic3, true);
            app.Image3.ImageSource = 'blank.png';
            app.Image6 = uiimage(app.TicTacToeGameBoardPanel);
            app.Image6.ImageClickedFcn = createCallbackFcn(app, @ClickOnPic6, true);
            app.Image6.ImageSource = 'blank.png';
            % Show the figure after all components are created
            app.G8RobotArmUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TicTacToeApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.G8RobotArmUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.G8RobotArmUIFigure)
        end
    end
end