classdef TicTacToe < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        G8RobotArmUIFigure        matlab.ui.Figure
        OperationMenu             matlab.ui.container.Menu
        ConnectMenu               matlab.ui.container.Menu
        CreateRobotMenu           matlab.ui.container.Menu
        OptimizeMenu              matlab.ui.container.Menu
        ConfigMenu                matlab.ui.container.Menu
        HWConfigMenu              matlab.ui.container.Menu
        DHParaCfgMenu             matlab.ui.container.Menu
        EndEffectorLocationPanel  matlab.ui.container.Panel
        XEditFieldLabel           matlab.ui.control.Label
        XEditField                matlab.ui.control.NumericEditField
        YEditFieldLabel           matlab.ui.control.Label
        YEditField                matlab.ui.control.NumericEditField
        ZEditFieldLabel           matlab.ui.control.Label
        ZEditField                matlab.ui.control.NumericEditField
        DistanceEditFieldLabel    matlab.ui.control.Label
        DistanceEditField         matlab.ui.control.NumericEditField
        PoseDropDownLabel         matlab.ui.control.Label
        PoseDropDown              matlab.ui.control.DropDown
        Joint1Label               matlab.ui.control.Label
        Joint1Slider              matlab.ui.control.Slider
        Joint2Label               matlab.ui.control.Label
        Joint2Slider              matlab.ui.control.Slider
        Joint3Label               matlab.ui.control.Label
        Joint3Slider              matlab.ui.control.Slider
    end

    
    properties (Access = private)
        arduino_hardware = [];
        arduino_initialized = true;
        JointServo1 = [];
        JointServo2 = [];
        JointServo3 = [];
        G8Robot = [];
        IsRobotCreated = false;
        optimized = false;
        DhPara = [0.1 0.073 0;      %a1 a2 a3
                  0   -90   0;      %alpha1 alpha2 alpha3
                  0   0.02  0.02;   %d1 d2 d3
                  -19 16.8  0;      %offset1 offset2 offset2
                  0   -0.08 0.047]; %basex basey basez
        % some useful poses
        Pose = [0 0 0;          %Pose1
                0 90 0;       %Pose2
                0 -90 0;      %Pose3
                90 0 0;       %Pose4
                90 90 0;    %Pose5
                90 -90 0;   %Pose6
                -90 0 0;      %Pose7
                -90 90 0;    %Pose8
                -90 -90 0];  %Pose9
        ConfigHWApp             % Config Hardware Dialog box app
        ComPort = "COM5"        % Initial Arduino COM port
        JointServoPin = ["D3" "D5" "D6"]
        
        ConfigDhApp             % Config DH para Dialog box app
        
        RobotFig
    end
    
    methods (Access = private)
        
        function updateRobot(app)
            if app.IsRobotCreated == true
                %Re-plot robot for new pose
                if isempty(app.JointServo1) == false
                    angle1 = ( app.Joint1Slider.Limits(2) - app.Joint1Slider.Value)/...
                             (app.Joint1Slider.Limits(2) - app.Joint1Slider.Limits(1));
                    %angle1 = app.Joint1Spinner.Value;
                    writePosition(app.JointServo1, angle1);
                end
                
                if isempty(app.JointServo2) == false
                    angle2 = (app.Joint2Slider.Limits(2) - app.Joint2Slider.Value)/...
                             (app.Joint2Slider.Limits(2) - app.Joint2Slider.Limits(1));
                    %angle2 = app.Joint2Spinner.Value;
                    writePosition(app.JointServo2, angle2);
                end
                
                
                app.G8Robot.plot([app.Joint1Slider.Value*pi/180 ...
                    app.Joint2Slider.Value*pi/180 ...
                    app.Joint3Slider.Value*pi/180],"jvec")
                %Recalculate the Transformation matrix after joint angle
                %changed
                T = app.G8Robot.fkine([app.Joint1Slider.Value*pi/180 ...
                    app.Joint2Slider.Value*pi/180 ...
                    app.Joint3Slider.Value*pi/180]);
                %Update the EE position on GUI
                app.XEditField.Value = T.t(1);
                app.YEditField.Value = T.t(2);
                app.ZEditField.Value = T.t(3);
                %Calculate the distance of EE and update on GUI
                app.DistanceEditField.Value = sqrt(T.t(1)^2 + T.t(2)^2 + T.t(3)^2);
            else
                msgbox("Robot not created yet")
            end
        end
        
    end
    
    methods (Access = public)
        
        
        function updateHWConfig(app, port, servoPin)
            app.ComPort = port;     %Update port
            app.JointServoPin = servoPin;
            %re-enable config arduino port
            app.HWConfigMenu.Enable = 'on';
        end
        
        function updateDhParaCfg(app,DhPara)
            app.DhPara = DhPara;
            %re-enable config arduino port
            app.DHParaCfgMenu.Enable = 'on';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Set initial joints angle according to pose selection
            poseInx = str2num(app.PoseDropDown.Value);
            app.Joint1Slider.Value = app.Pose(poseInx,1);
            app.Joint2Slider.Value = app.Pose(poseInx,2);
            app.Joint3Slider.Value = app.Pose(poseInx,3);
            
        end

        % Menu selected function: ConnectMenu
        function Connect(app, event)
            try
                app.arduino_hardware = arduino(app.ComPort, 'Uno', ...
                    'Libraries', 'Servo')
                app.arduino_initialized = true;
            catch ME
                warn_msg = ["Connect failure","Indentifier:",ME.identifier,"Message:",ME.message];
                msgbox(warn_msg);
                return;
            end
            
            try
                if isempty(app.arduino_hardware) == false
                    app.JointServo1 = servo(app.arduino_hardware, 'D3', ...
                        'MinPulseDuration', 5.75*10^-4, 'MaxPulseDuration', 2.46*10^-3);
                    app.JointServo2 = servo(app.arduino_hardware, 'D5', ...
                        'MinPulseDuration', 5.75*10^-4, 'MaxPulseDuration', 2.46*10^-3);
                end
                
            catch ME
                warn_msg = ["Servo failure","Indentifier:",ME.identifier,"Message:",ME.message];
                msgbox(warn_msg);
                return;
            end
            
            app.arduino_initialized = true;
            msgbox('Arduino initialized');
        end

        % Menu selected function: CreateRobotMenu
        function CreateRobot(app, event)
            clear L
            deg = pi/180;
            %get the joint angles limits from slider.Limits           
            qlim1 = deg2rad(app.Joint1Slider.Limits);
            qlim2 = deg2rad(app.Joint2Slider.Limits);
            qlim3 = deg2rad(app.Joint3Slider.Limits);
            
            % Define links of robot
            L(1) = Revolute('d', app.DhPara(3,1), ...   % link length (Dennavit-Hartenberg notation)
                'a', app.DhPara(1,1), ...               % link offset (Dennavit-Hartenberg notation)
                'alpha', app.DhPara(2,1)*deg, ...       % link twist (Dennavit-Hartenberg notation)
                'qlim', qlim1, ...                      % minimum and maximum joint angle
                'offset',app.DhPara(4,1)*deg ); 
            
            L(2) = Revolute('d', app.DhPara(3,2), ...
                'a', app.DhPara(1,2), ...
                'alpha', app.DhPara(2,2)*deg, ...
                'qlim', qlim2, ...
                'offset',app.DhPara(4,2)*deg );
            
            L(3) = Revolute('d', app.DhPara(3,3), ...
                'a', app.DhPara(1,3), ...
                'alpha', app.DhPara(2,3)*deg,  ...
                'qlim', qlim3, ...
                'offset',app.DhPara(4,3)*deg  );

            %Build the robot
            app.G8Robot = SerialLink(L, 'name', 'G8 Robot Arm', ...
                'tool',transl(0,0,0), ...
                'base',transl(app.DhPara(5,1), app.DhPara(5,2),app.DhPara(5,3)));
            %Set the robot created flag
            app.IsRobotCreated = true;
            %plot the robot and update postion and distance info
            app.updateRobot();
        end

        % Key release function: G8RobotArmUIFigure
        function G8RobotArmUIFigureKeyRelease(app, event)
            %make sure robot is created already
            if app.IsRobotCreated == true
                %Get current robot EE transformation matrix
                q1 = [app.Joint1Slider.Value*pi/180 ...
                    app.Joint2Slider.Value*pi/180 ...
                    app.Joint3Slider.Value*pi/180];
                T = app.G8Robot.fkine(q1);
                %adjust x or y position according to key press
                if event.Key == "leftarrow"
                    T.t(2) = T.t(2) + 0.005;    %move to Y + 0.005m
                elseif event.Key == "rightarrow"
                    T.t(2) = T.t(2) - 0.005;    %move to Y - 0.005m
                elseif event.Key == "uparrow"
                    T.t(1) = T.t(1) + 0.005;    %move to X + 0.005m
                elseif event.Key == "downarrow"
                    T.t(1) = T.t(1) - 0.005;    %move to X - 0.005m
                else 
                end
                %Do inverse kinematics
                q2 = app.G8Robot.ikine(T,'q0',q1,'mask',[1 1 1 0 0 0]);
                if (isempty(q2) == false)
                    %update joints angle after ikine
                    q2deg = rad2deg(q2);
                    if((q2deg(1) <= app.Joint1Slider.Limits(2)) && ...
                       (q2deg(1) >= app.Joint1Slider.Limits(1)) && ...
                       (q2deg(2) <= app.Joint2Slider.Limits(2)) && ...
                       (q2deg(2) >= app.Joint2Slider.Limits(1)))
                   
                        app.Joint1Slider.Value = q2deg(1);
                        app.Joint2Slider.Value = q2deg(2);
                        app.Joint3Slider.Value = q2deg(3);
                        %plot the robot and update postion and distance info
                        app.updateRobot();
                    else
                        msg = sprintf("Joint angle reaches limit:\n" + ...
                                      "Joint1 angle:%0.1f\n" + ...
                                      "Joint2 angle:%0.1f",q2deg(1),q2deg(2));
                        msgbox(msg);
                    end
                else
                    msgbox("can't reach the target");
                end
            else
                msgbox("Robot not created yet")
            end
        end

        % Value changed function: PoseDropDown
        function ChangePose(app, event)
            %make sure robot is created already
            if app.IsRobotCreated == true
                %update joints angle according to pose selection
                poseinx = str2num(app.PoseDropDown.Value);
                app.Joint1Slider.Value = app.Pose(poseinx,1);
                app.Joint2Slider.Value = app.Pose(poseinx,2);
                app.Joint3Slider.Value = app.Pose(poseinx,3);
                
                %plot the robot and update postion and distance info
                app.updateRobot();
            else
                msgbox("Robot not created yet")
            end            
        end

        % Value changed function: Joint1Slider
        function Joint1SliderValueChanged(app, event)
            %plot the robot and update postion and distance info
            app.updateRobot();
        end

        % Value changed function: Joint2Slider
        function Joint2SliderValueChanged(app, event)
            %plot the robot and update postion and distance info
            app.updateRobot();
        end

        % Value changed function: Joint3Slider
        function Joint3SliderValueChanged(app, event)
            %plot the robot and update postion and distance info
            app.updateRobot();
        end

        % Menu selected function: OptimizeMenu
        function Optimize(app, event)
            %before start, set flag as flase
            app.optimized = false;
            
            %set optimization options
            options = optimset('Display','final','TolX',1e-6, ...
                'PlotFcns',@optimplotfval,'MaxIter',500);
            %start optimization
            [x,fval,exitflag,output] = fminsearch(@CalcDistError,app.DhPara, options)
            
            app.optimized = true;
            %optimization finished, update the DH parameters
            app.DhPara = x;
        end

        % Callback function
        function Update_a1(app, event)
            if app.IsRobotCreated == true
                %clear current figure before re-plot
                clf;
                %update robot a1 parameter
                app.G8Robot.links(1).a = app.a1EditField.Value;
                %plot the robot and update postion and distance info
                app.updateRobot();
            end
        end

        % Callback function
        function update_a2(app, event)
            if app.IsRobotCreated == true
                %clear current figure before re-plot
                clf;
                %update robot a1 parameter
                app.G8Robot.links(2).a = app.a2EditField.Value;
                %plot the robot and update postion and distance info
                app.updateRobot();
            end            
        end

        % Callback function
        function update_d1(app, event)
            if app.IsRobotCreated == true
                %clear current figure before re-plot
                clf;
                %update robot a1 parameter
                app.G8Robot.links(1).d = app.d1EditField.Value;
                %plot the robot and update postion and distance info
                app.updateRobot();
            end            
        end

        % Callback function
        function update_d2(app, event)
            if app.IsRobotCreated == true
                %clear current figure before re-plot
                clf;
                %update robot a1 parameter
                app.G8Robot.links(2).d = app.d2EditField.Value;
                %plot the robot and update postion and distance info
                app.updateRobot();
            end            
        end

        % Callback function
        function update_d3(app, event)
            if app.IsRobotCreated == true
                %clear current figure before re-plot
                clf;
                %update robot a1 parameter
                app.G8Robot.links(3).d = app.d3EditField.Value;
                %plot the robot and update postion and distance info
                app.updateRobot();
            end            
        end

        % Callback function
        function update_alpha1(app, event)
            if app.IsRobotCreated == true
                %clear current figure before re-plot
                %clf;
                %update robot a1 parameter
                app.G8Robot.links(1).alpha = deg2rad(app.alpha1EditField.Value);
                %plot the robot and update postion and distance info
                app.updateRobot();
            end 
        end

        % Callback function
        function update_alpha2(app, event)
            if app.IsRobotCreated == true
                %clear current figure before re-plot
                clf;
                %update robot a1 parameter
                app.G8Robot.links(2).alpha = deg2rad(app.alpha2EditField.Value);
                %plot the robot and update postion and distance info
                app.updateRobot();
            end            
        end

        % Callback function
        function update_offset1(app, event)
            if app.IsRobotCreated == true
                %clear current figure before re-plot
                %clf;
                %update robot a1 parameter
                app.G8Robot.offset(1) = app.offset1EditField.Value*pi/180;
                %plot the robot and update postion and distance info
                app.updateRobot();
            end  
        end

        % Callback function
        function update_offset2(app, event)
            if app.IsRobotCreated == true
                %clear current figure before re-plot
                %clf;
                %update robot a1 parameter
                app.G8Robot.offset(2) = app.offset2EditField.Value*pi/180;
                %plot the robot and update postion and distance info
                app.updateRobot();
            end 
        end

        % Menu selected function: DHParaCfgMenu
        function ConfigDHParameters(app, event)
            %Disable the menu to avoid reopen config window 
            app.DHParaCfgMenu.Enable = 'off';
            
            % Open the options dialog and pass inputs
            app.ConfigDhApp = ConfigDhPara(app, app.DhPara);
        end

        % Menu selected function: HWConfigMenu
        function ConfigHW(app, event)
            %Disable the menu to avoid reopen config window 
            app.HWConfigMenu.Enable = 'off';
            
            % Open the options dialog and pass inputs
            app.ConfigHWApp = ConfigHW(app, app.ComPort, app.JointServoPin);
            
        end

        % Close request function: G8RobotArmUIFigure
        function G8RobotArmUIFigureCloseRequest(app, event)
            %close all windows
            close all
            
            % Delete both apps
            delete(app.ConfigHWApp)
            delete(app.ConfigDhApp)
            delete(app)
            
        end

        % Callback function
        function UpdateServo(app, event)

            app.updateRobot();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.G8RobotArmUIFigure = uifigure('Visible', 'off');
            app.G8RobotArmUIFigure.Name = 'G8 Robot Arm';
            app.G8RobotArmUIFigure.CloseRequestFcn = createCallbackFcn(app, @G8RobotArmUIFigureCloseRequest, true);
            app.G8RobotArmUIFigure.KeyReleaseFcn = createCallbackFcn(app, @G8RobotArmUIFigureKeyRelease, true);
            app.OperationMenu = uimenu(app.G8RobotArmUIFigure);
            app.OperationMenu.Text = 'Operation';
            app.ConnectMenu = uimenu(app.OperationMenu);
            app.ConnectMenu.MenuSelectedFcn = createCallbackFcn(app, @Connect, true);
            app.ConnectMenu.Text = 'Connect';
            app.CreateRobotMenu = uimenu(app.OperationMenu);
            app.CreateRobotMenu.MenuSelectedFcn = createCallbackFcn(app, @CreateRobot, true);
            app.CreateRobotMenu.Text = 'CreateRobot';
            app.OptimizeMenu = uimenu(app.OperationMenu);
            app.OptimizeMenu.MenuSelectedFcn = createCallbackFcn(app, @Optimize, true);
            app.OptimizeMenu.Text = 'Optimize';
            app.ConfigMenu = uimenu(app.G8RobotArmUIFigure);
            app.ConfigMenu.Text = 'Config';
            app.HWConfigMenu = uimenu(app.ConfigMenu);
            app.HWConfigMenu.MenuSelectedFcn = createCallbackFcn(app, @ConfigHW, true);
            app.HWConfigMenu.Text = 'Hardware';
            app.DHParaCfgMenu = uimenu(app.ConfigMenu);
            app.DHParaCfgMenu.MenuSelectedFcn = createCallbackFcn(app, @ConfigDHParameters, true);
            app.DHParaCfgMenu.Text = 'DH Parameters';
            app.EndEffectorLocationPanel = uipanel(app.G8RobotArmUIFigure);
            app.EndEffectorLocationPanel.Title = 'End-Effector Location';
            app.XEditFieldLabel = uilabel(app.EndEffectorLocationPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.EndEffectorLocationPanel, 'numeric');
            app.YEditFieldLabel = uilabel(app.EndEffectorLocationPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.EndEffectorLocationPanel, 'numeric');
            app.ZEditFieldLabel = uilabel(app.EndEffectorLocationPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.EndEffectorLocationPanel, 'numeric');
            app.DistanceEditFieldLabel = uilabel(app.G8RobotArmUIFigure);
            app.DistanceEditFieldLabel.Text = 'Distance';
            app.DistanceEditField = uieditfield(app.G8RobotArmUIFigure, 'numeric');
            app.PoseDropDownLabel = uilabel(app.G8RobotArmUIFigure);
            app.PoseDropDownLabel.Text = 'Pose';
            app.PoseDropDown = uidropdown(app.G8RobotArmUIFigure);
            app.PoseDropDown.Items = {'Pose1_000', 'Pose2_090', 'Pose3_0-90', 'Pose4_900', 'Pose5_990', 'Pose6_9-90', 'Pose7_-900', 'Pose8_-990', 'Pose9_-9-90'};
            app.PoseDropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9'};
            app.PoseDropDown.ValueChangedFcn = createCallbackFcn(app, @ChangePose, true);
            app.PoseDropDown.Value = '2';
            app.Joint1Label = uilabel(app.G8RobotArmUIFigure);
            app.Joint1Label.Text = 'Joint1';
            app.Joint1Slider = uislider(app.G8RobotArmUIFigure);
            app.Joint1Slider.Limits = [-112 90];
            app.Joint1Slider.MajorTicks = [-90 -60 -30 0 30 60 90];
            app.Joint1Slider.ValueChangedFcn = createCallbackFcn(app, @Joint1SliderValueChanged, true);
            app.Joint1Slider.MinorTicks = [-100 -90 -80 -70 -60 -50 -40 -30 -20 -10 0 10 20 30 40 50 60 70 80 90 100];
            app.Joint2Label = uilabel(app.G8RobotArmUIFigure);
            app.Joint2Label.Text = 'Joint2';
            app.Joint2Slider = uislider(app.G8RobotArmUIFigure);
            app.Joint2Slider.Limits = [-90 112];
            app.Joint2Slider.MajorTicks = [-90 -60 -30 0 30 60 90];
            app.Joint2Slider.ValueChangedFcn = createCallbackFcn(app, @Joint2SliderValueChanged, true);
            app.Joint2Slider.MinorTicks = [-100 -90 -80 -70 -60 -50 -40 -30 -20 -10 0 10 20 30 40 50 60 70 80 90];
            app.Joint3Label = uilabel(app.G8RobotArmUIFigure);
            app.Joint3Label.Text = 'Joint3';
            app.Joint3Slider = uislider(app.G8RobotArmUIFigure);
            app.Joint3Slider.Limits = [0 165];
            app.Joint3Slider.MajorTicks = [0 30 60 90 120 150];
            app.Joint3Slider.ValueChangedFcn = createCallbackFcn(app, @Joint3SliderValueChanged, true);
            % Show the figure after all components are created
            app.G8RobotArmUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TicTacToe

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.G8RobotArmUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.G8RobotArmUIFigure)
        end
    end
end